classdef CurveFittingDialogDiode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        VLabel_8                     matlab.ui.control.Label
        VLabel_7                     matlab.ui.control.Label
        VLabel_6                     matlab.ui.control.Label
        VLabel_5                     matlab.ui.control.Label
        VLabel_4                     matlab.ui.control.Label
        VLabel_3                     matlab.ui.control.Label
        VLabel_2                     matlab.ui.control.Label
        ALabel_4                     matlab.ui.control.Label
        ALabel_3                     matlab.ui.control.Label
        VLabel                       matlab.ui.control.Label
        ALabel_2                     matlab.ui.control.Label
        ALabel                       matlab.ui.control.Label
        GetresiduesButton            matlab.ui.control.Button
        ErrorfEditField              matlab.ui.control.NumericEditField
        ErrorfEditFieldLabel         matlab.ui.control.Label
        fEditField                   matlab.ui.control.NumericEditField
        fEditFieldLabel              matlab.ui.control.Label
        R22EditField                 matlab.ui.control.NumericEditField
        R22EditFieldLabel            matlab.ui.control.Label
        R2EditField                  matlab.ui.control.NumericEditField
        R2EditFieldLabel             matlab.ui.control.Label
        ErroreEditField              matlab.ui.control.NumericEditField
        ErroreEditFieldLabel         matlab.ui.control.Label
        eEditField                   matlab.ui.control.NumericEditField
        eEditFieldLabel              matlab.ui.control.Label
        ErrordEditField              matlab.ui.control.NumericEditField
        ErrordEditFieldLabel         matlab.ui.control.Label
        dEditField                   matlab.ui.control.NumericEditField
        dEditFieldLabel              matlab.ui.control.Label
        Image2_2                     matlab.ui.control.Image
        ErrorcEditField              matlab.ui.control.NumericEditField
        ErrorcEditFieldLabel         matlab.ui.control.Label
        cEditField                   matlab.ui.control.NumericEditField
        cEditFieldLabel              matlab.ui.control.Label
        ErrorbEditField              matlab.ui.control.NumericEditField
        ErrorbEditFieldLabel         matlab.ui.control.Label
        ErroraEditField              matlab.ui.control.NumericEditField
        ErroraEditFieldLabel         matlab.ui.control.Label
        bEditField                   matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        CalculateButton              matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        ExportFitParametersMenu      matlab.ui.container.Menu
        ContextMenu2                 matlab.ui.container.ContextMenu
        SaveasMenu                   matlab.ui.container.Menu
        ExportDatatoWorkspaceMenu    matlab.ui.container.Menu
        ContextMenu3                 matlab.ui.container.ContextMenu
        SaveasMenu_2                 matlab.ui.container.Menu
        ExportDatatoWorkspaceMenu_2  matlab.ui.container.Menu
    end

    
    properties (Access = public)
        MainDiodeApp
        V_O_fit_line;
        I_D_fit_line;
        V_D_fit_line;
        optimal_coeff_ID;
        optimal_coeff_VO;
        V_D_fit2;
        V_D_fit;
        I_D_fit_data;
        V_O_fit_data;
        model_ID;
        model_VO;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if isempty(app.MainDiodeApp.I_D_mean)
                uialert(app.UIFigure, 'The value of "I_D" has not yet been calculated.', 'Error');
            elseif isempty(app.MainDiodeApp.V_D)
                uialert(app.UIFigure, 'The value of "V_D" has not yet been calculated.', 'Error');
            elseif isempty(app.MainDiodeApp.V_O_mean)
                uialert(app.UIFigure, 'The value of "V_O" has not yet been calculated.', 'Error');
            end

            V_N_mean = mean(app.MainDiodeApp.V_N);
            V_P_mean = mean(app.MainDiodeApp.V_P);
            R = app.MainDiodeApp.REditField.Value;
            Ioff = app.MainDiodeApp.IOffEditField.Value;
            
            % --------------------- Ajuste de V_O vs V_D -------------------------
            % Filtrar datos para V_O (ajusta el umbral según corresponda)
            threshold_VO = 0.99 * app.MainDiodeApp.V_O_mean(end);
            valid_idx_VO = app.MainDiodeApp.V_O_mean >= threshold_VO;
            app.V_D_fit2 = app.MainDiodeApp.V_D(valid_idx_VO);      % V_D correspondiente a estos puntos
            app.V_O_fit_data = app.MainDiodeApp.V_O_mean(valid_idx_VO);
            
            % Definir el modelo: f(V_D) = d - e * exp(f * V_D)
            app.model_VO = @(coeff, x) coeff(1) - coeff(2) * exp(coeff(3) * x);
            
            % Estimación inicial de parámetros [d, e, f]
            initial_guess_VO = [V_N_mean, R * 9.6e-9, 19.5];

            % Opciones del optimizador (se han ajustado las tolerancias para mayor precisión)
            options = optimset('Display', 'off', 'TolX', 1e-12, 'TolFun', 1e-12);
            
            % Ajuste no lineal para V_O vs V_D
            app.optimal_coeff_VO = lsqcurvefit(app.model_VO, initial_guess_VO, app.V_D_fit2, app.V_O_fit_data, [], [], options);
            d = app.optimal_coeff_VO(1);
            e = app.optimal_coeff_VO(2);
            f_param = app.optimal_coeff_VO(3); % Usamos "f_param" para el parámetro f
            
            % Calcular el modelo ajustado y el coeficiente de determinación R²
            V_O_model = app.model_VO(app.optimal_coeff_VO, app.V_D_fit2);
            SStot2 = sum((app.V_O_fit_data - mean(app.V_O_fit_data)).^2);
            SSres2 = sum((app.V_O_fit_data - V_O_model).^2);
            R2_VO = 1 - (SSres2 / SStot2);
            
            % Cálculo de errores mediante la matriz Jacobiana para V_O vs V_D
            J_VO = zeros(length(app.V_D_fit2), 3);
            for i = 1:length(app.V_D_fit2)
                J_VO(i,1) = 1;                                       % derivada respecto a d
                J_VO(i,2) = -exp(f_param * app.V_D_fit2(i));             % derivada respecto a e
                J_VO(i,3) = -e * app.V_D_fit2(i) * exp(f_param * app.V_D_fit2(i));  % derivada respecto a f
            end
            cov_matrix_VO = inv(J_VO' * J_VO) * (SSres2 / (length(app.V_D_fit2) - 3));
            error_d = sqrt(cov_matrix_VO(1,1));
            error_e = sqrt(cov_matrix_VO(2,2));
            error_f = sqrt(cov_matrix_VO(3,3));
            
            app.dEditField.Value = d;
            app.eEditField.Value = e;
            app.fEditField.Value = f_param;
            app.ErrordEditField.Value = error_d;
            app.ErroreEditField.Value = error_e;
            app.ErrorfEditField.Value = error_f;
            app.R22EditField.Value = R2_VO;

            % --------------------- Ajuste de I_D vs V_D -------------------------
            % Filtrar datos para V_O (ajusta el umbral según corresponda)
            % threshold_ID = -threshold_VO/app.MainDiodeApp.REditField.Value - app.MainDiodeApp.IOffEditField.Value;
            threshold_ID = 0.99 * app.MainDiodeApp.I_D_mean(end);
            valid_idx_ID = app.MainDiodeApp.I_D_mean <= threshold_ID;
            app.V_D_fit = app.MainDiodeApp.V_D(valid_idx_ID);      % V_D correspondiente a estos puntos
            app.I_D_fit_data = app.MainDiodeApp.I_D_mean(valid_idx_ID);
            
            % Definir el modelo: f(V_D) = a - b * exp(c * V_D)
            app.model_ID = @(coeff, x) coeff(1) - coeff(2) * exp(coeff(3) * x);
            
            % Estimación inicial de parámetros [a, b, c]
            initial_guess_ID = [-9.6e-9, -9.6e-9, 19.5];
            %initial_guess_ID = [(V_N_mean - V_P_mean)/R - Ioff, -9.6e-9, 19.5];

            % Opciones del optimizador (se han ajustado las tolerancias para mayor precisión)
            options = optimset('Display', 'off', 'TolX', 1e-12, 'TolFun', 1e-12);
            
            % Ajuste no lineal para V_O vs V_D
            app.optimal_coeff_ID = lsqcurvefit(app.model_ID, initial_guess_ID, app.V_D_fit, app.I_D_fit_data, [], [], options);
            a = app.optimal_coeff_ID(1);
            b = app.optimal_coeff_ID(2);
            c = app.optimal_coeff_ID(3); % Usamos "f_param" para el parámetro f
            
            % Calcular el modelo ajustado y el coeficiente de determinación R²
            I_D_model = app.model_ID(app.optimal_coeff_ID, app.V_D_fit);
            SStot = sum((app.I_D_fit_data - mean(app.I_D_fit_data)).^2);
            SSres = sum((app.I_D_fit_data - I_D_model).^2);
            R2_ID = 1 - (SSres / SStot);
            
            % Cálculo de errores mediante la matriz Jacobiana para V_O vs V_D
            J_ID = zeros(length(app.V_D_fit), 3);
            for i = 1:length(app.V_D_fit)
                J_ID(i,1) = 1;                                       % derivada respecto a d
                J_ID(i,2) = -exp(c * app.V_D_fit(i));             % derivada respecto a e
                J_ID(i,3) = -b * app.V_D_fit(i) * exp(c * app.V_D_fit(i));  % derivada respecto a f
            end
            cov_matrix_ID = inv(J_ID' * J_ID) * (SSres / (length(app.V_D_fit) - 3));
            error_a = sqrt(cov_matrix_ID(1,1));
            error_b = sqrt(cov_matrix_ID(2,2));
            error_c = sqrt(cov_matrix_ID(3,3));
            
            app.aEditField.Value = a;
            app.bEditField.Value = b;
            app.cEditField.Value = c;
            app.ErroraEditField.Value = error_a;
            app.ErrorbEditField.Value = error_b;
            app.ErrorcEditField.Value = error_c;
            app.R2EditField.Value = R2_ID;
            
            % Representación gráfica de los ajustes
            
            % Generar puntos para graficar el modelo ajustado en un rango de V_D
            app.V_D_fit_line = linspace(min(app.V_D_fit), max(app.V_D_fit), 100);
            app.I_D_fit_line = app.model_ID(app.optimal_coeff_ID, app.V_D_fit_line);
            
            V_D_fit_line2 = linspace(min(app.V_D_fit2), max(app.V_D_fit2), 100);
            app.V_O_fit_line = app.model_VO(app.optimal_coeff_VO, V_D_fit_line2);
            
            plot(app.MainDiodeApp.UIAxes, app.V_D_fit_line, app.I_D_fit_line, 'DisplayName', 'Ifit')
            plot(app.MainDiodeApp.UIAxes2, V_D_fit_line2, app.V_O_fit_line, 'DisplayName', 'Vfit')
        end

        % Menu selected function: ExportFitParametersMenu
        function ExportFitParametersMenuSelected(app, event)
            fields = {
                'a', app.aEditField.Value;
                'Error a', app.ErroraEditField.Value;
                'b', app.bEditField.Value;
                'Error b', app.ErrorbEditField.Value;
                'c', app.cEditField.Value;
                'Error c', app.ErrorcEditField.Value;
                'R2', app.R2EditField.Value;
                'd', app.dEditField.Value;
                'Error d', app.ErrordEditField.Value;
                'e', app.eEditField.Value;
                'Error e', app.ErroreEditField.Value;
                'f', app.fEditField.Value;
                'Error f', app.ErrorfEditField.Value;
                'R22', app.R22EditField.Value;
            };
        
            % Exportar cada campo al workspace
            for i = 1:size(fields, 1)
                fieldName = fields{i, 1};
                fieldValue = fields{i, 2};
                assignin('base', matlab.lang.makeValidName(fieldName), fieldValue);
            end
        
            uialert(app.UIFigure, 'Fit parameters exported to workspace.', 'Exportation Completed')
        end

        % Button pushed function: GetresiduesButton
        function GetresiduesButtonPushed(app, event)
            % -------------Residuos para V_O vs V_D ------------------------
            
            if isempty(app.optimal_coeff_VO) || isempty(app.V_D_fit2) || isempty(app.V_O_fit_line)
                uialert(app.UIFigure, 'First, fit the curve V_O vs V_D.', 'Error');
                return;
            end
        
            % Calcular la predicción del modelo
            V_O_model = app.model_VO(app.optimal_coeff_VO, app.V_D_fit2);
            % Calcular los residuos (diferencia entre datos medidos y modelo ajustado)
            residuals_VO = app.V_O_fit_data - V_O_model;
            
            % ------------Residuos para el ajuste de I_D vs V_D----------
            % Se asume que ya se realizó el ajuste y se almacenaron:
            % app.optimal_coeff_ID, app.V_D_fit, app.I_D_fit_data
            if isempty(app.optimal_coeff_ID) || isempty(app.V_D_fit) || isempty(app.I_D_fit_line)
                uialert(app.UIFigure, 'First, fit the curve I_D vs V_D.', 'Error');
                return;
            end
        
            I_D_model = app.model_ID(app.optimal_coeff_ID, app.V_D_fit);
            residuals_ID = app.I_D_fit_data - I_D_model;

            % Representamos
            plot(app.UIAxes, app.V_D_fit, residuals_ID)
            plot(app.UIAxes2, app.V_D_fit2, residuals_VO)
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            % Open a save dialog box
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.fig'}, 'Save Plot As');
            
            % If user cancels, do nothing
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % Build the full file name
            fullFileName = fullfile(path, file);
            
            % Get the file extension
            [~, ~, ext] = fileparts(fullFileName);
            
            % Save the current axes as the selected format
            switch ext
                case '.png'
                    exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
                case '.jpg'
                    exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
                case '.pdf'
                    exportgraphics(app.UIAxes, fullFileName);
                case '.fig'
                    % Save as a MATLAB figure
                    fig = figure('Visible', 'off'); % Create an invisible figure
                    axCopy = copyobj(app.UIAxes, fig); % Copy the axes to the new figure
                    set(axCopy, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]); % Resize
                    savefig(fig, fullFileName); % Save the figure
                    close(fig); % Close the temporary figure
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
            end
        end

        % Menu selected function: ExportDatatoWorkspaceMenu
        function ExportDatatoWorkspaceMenuSelected(app, event)
            % Obtener el objeto gráfico activo
            srcObject = app.UIAxes.Children; % Obtiene todos los objetos del eje
        
            % Iterar sobre los objetos del eje (por si hay varios gráficos)
            for i = 1:numel(srcObject)
                if strcmp(srcObject(i).Type, 'line') % Verificar si es una línea
                    % Extraer datos X e Y de la línea
                    xData = srcObject(i).XData;
                    yData = srcObject(i).YData;
                    
                    % Crear una matriz con dos columnas: X y Y
                    data = [xData(:), yData(:)]; % Asegurarse de que sean columnas
                    
                    % Obtener el nombre descriptivo (DisplayName)
                    graphName = srcObject(i).DisplayName;
                    
                    % Validar el nombre para que sea un nombre de variable válido
                    if isempty(graphName) % Si no hay DisplayName, usar un nombre genérico
                        graphName = sprintf('ExportedData_%d', i);
                    else
                        graphName = matlab.lang.makeValidName(graphName); % Asegurarse de que sea válido
                    end
                    
                    % Exportar al Workspace con el nombre descriptivo
                    assignin('base', graphName, data);
                end
            end
            
            % Notificación al usuario
            uialert(app.UIFigure, 'Data Exported to Workspace', 'Exportation completed');
        end

        % Menu selected function: SaveasMenu_2
        function SaveasMenu_2Selected(app, event)
            % Open a save dialog box
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.fig'}, 'Save Plot As');
            
            % If user cancels, do nothing
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % Build the full file name
            fullFileName = fullfile(path, file);
            
            % Get the file extension
            [~, ~, ext] = fileparts(fullFileName);
            
            % Save the current axes as the selected format
            switch ext
                case '.png'
                    exportgraphics(app.UIAxes2, fullFileName, 'Resolution', 300);
                case '.jpg'
                    exportgraphics(app.UIAxes2, fullFileName, 'Resolution', 300);
                case '.pdf'
                    exportgraphics(app.UIAxes2, fullFileName);
                case '.fig'
                    % Save as a MATLAB figure
                    fig = figure('Visible', 'off'); % Create an invisible figure
                    axCopy = copyobj(app.UIAxes2, fig); % Copy the axes to the new figure
                    set(axCopy, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]); % Resize
                    savefig(fig, fullFileName); % Save the figure
                    close(fig); % Close the temporary figure
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
            end
        end

        % Menu selected function: ExportDatatoWorkspaceMenu_2
        function ExportDatatoWorkspaceMenu_2Selected(app, event)
            % Obtener el objeto gráfico activo
            srcObject = app.UIAxes2.Children; % Obtiene todos los objetos del eje
        
            % Iterar sobre los objetos del eje (por si hay varios gráficos)
            for i = 1:numel(srcObject)
                if strcmp(srcObject(i).Type, 'line') % Verificar si es una línea
                    % Extraer datos X e Y de la línea
                    xData = srcObject(i).XData;
                    yData = srcObject(i).YData;
                    
                    % Crear una matriz con dos columnas: X y Y
                    data = [xData(:), yData(:)]; % Asegurarse de que sean columnas
                    
                    % Obtener el nombre descriptivo (DisplayName)
                    graphName = srcObject(i).DisplayName;
                    
                    % Validar el nombre para que sea un nombre de variable válido
                    if isempty(graphName) % Si no hay DisplayName, usar un nombre genérico
                        graphName = sprintf('ExportedData_%d', i);
                    else
                        graphName = matlab.lang.makeValidName(graphName); % Asegurarse de que sea válido
                    end
                    
                    % Exportar al Workspace con el nombre descriptivo
                    assignin('base', graphName, data);
                end
            end
            
            % Notificación al usuario
            uialert(app.UIFigure, 'Data Exported to Workspace', 'Exportation completed');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Residues for I_D vs V_D')
            xlabel(app.UIAxes, 'V_D [V]')
            ylabel(app.UIAxes, 'Residues for I_D [A]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Residues for V_O vs V_D')
            xlabel(app.UIAxes2, 'V_D [V]')
            ylabel(app.UIAxes2, 'Residues for V_O [V]')
            zlabel(app.UIAxes2, 'Z')
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'DiodeCharacteristicsCurrentEquation.svg');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErroraEditFieldLabel = uilabel(app.UIFigure);
            app.ErroraEditFieldLabel.Text = 'Error a';
            app.ErroraEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrorbEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorbEditFieldLabel.Text = 'Error b';
            app.ErrorbEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrorcEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorcEditFieldLabel.Text = 'Error c';
            app.ErrorcEditField = uieditfield(app.UIFigure, 'numeric');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'DiodeCharacteristicsVoltageEquation.svg');
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrordEditFieldLabel = uilabel(app.UIFigure);
            app.ErrordEditFieldLabel.Text = 'Error d';
            app.ErrordEditField = uieditfield(app.UIFigure, 'numeric');
            app.eEditFieldLabel = uilabel(app.UIFigure);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErroreEditFieldLabel = uilabel(app.UIFigure);
            app.ErroreEditFieldLabel.Text = 'Error e';
            app.ErroreEditField = uieditfield(app.UIFigure, 'numeric');
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R^2';
            app.R2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R22EditFieldLabel = uilabel(app.UIFigure);
            app.R22EditFieldLabel.Text = 'R^2 (2)';
            app.R22EditField = uieditfield(app.UIFigure, 'numeric');
            app.fEditFieldLabel = uilabel(app.UIFigure);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrorfEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorfEditFieldLabel.Text = 'Error f';
            app.ErrorfEditField = uieditfield(app.UIFigure, 'numeric');
            app.GetresiduesButton = uibutton(app.UIFigure, 'push');
            app.GetresiduesButton.ButtonPushedFcn = createCallbackFcn(app, @GetresiduesButtonPushed, true);
            app.GetresiduesButton.Text = 'Get residues';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.ALabel_2 = uilabel(app.UIFigure);
            app.ALabel_2.Text = 'A';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = '1/V';
            app.ALabel_3 = uilabel(app.UIFigure);
            app.ALabel_3.Text = 'A';
            app.ALabel_4 = uilabel(app.UIFigure);
            app.ALabel_4.Text = 'A';
            app.VLabel_2 = uilabel(app.UIFigure);
            app.VLabel_2.Text = '1/V';
            app.VLabel_3 = uilabel(app.UIFigure);
            app.VLabel_3.Text = 'V';
            app.VLabel_4 = uilabel(app.UIFigure);
            app.VLabel_4.Text = 'V';
            app.VLabel_5 = uilabel(app.UIFigure);
            app.VLabel_5.Text = '1/V';
            app.VLabel_6 = uilabel(app.UIFigure);
            app.VLabel_6.Text = 'V';
            app.VLabel_7 = uilabel(app.UIFigure);
            app.VLabel_7.Text = 'V';
            app.VLabel_8 = uilabel(app.UIFigure);
            app.VLabel_8.Text = '1/V';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExportFitParametersMenu = uimenu(app.ContextMenu);
            app.ExportFitParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportFitParametersMenuSelected, true);
            app.ExportFitParametersMenu.Text = 'Export Fit Parameters';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.SaveasMenu = uimenu(app.ContextMenu2);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as';
            app.ExportDatatoWorkspaceMenu = uimenu(app.ContextMenu2);
            app.ExportDatatoWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDatatoWorkspaceMenuSelected, true);
            app.ExportDatatoWorkspaceMenu.Text = 'Export Data to Workspace';
            % Assign app.ContextMenu2
            app.UIAxes.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.SaveasMenu_2 = uimenu(app.ContextMenu3);
            app.SaveasMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenu_2Selected, true);
            app.SaveasMenu_2.Text = 'Save as';
            app.ExportDatatoWorkspaceMenu_2 = uimenu(app.ContextMenu3);
            app.ExportDatatoWorkspaceMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ExportDatatoWorkspaceMenu_2Selected, true);
            app.ExportDatatoWorkspaceMenu_2.Text = 'Export Data to Workspace';
            % Assign app.ContextMenu3
            app.UIAxes2.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurveFittingDialogDiode

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end