classdef CurveFittingDialogMOSFET < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AV2Label_2                   matlab.ui.control.Label
        AV2Label                     matlab.ui.control.Label
        VLabel_4                     matlab.ui.control.Label
        VLabel_3                     matlab.ui.control.Label
        VLabel_2                     matlab.ui.control.Label
        VLabel                       matlab.ui.control.Label
        ErrorlambdaEditField         matlab.ui.control.EditField
        ErrorlambdaEditFieldLabel    matlab.ui.control.Label
        ErrorKEditField              matlab.ui.control.EditField
        ErrorKEditFieldLabel         matlab.ui.control.Label
        ErrorVthEditField            matlab.ui.control.EditField
        ErrorVthEditFieldLabel       matlab.ui.control.Label
        lambdaEditField              matlab.ui.control.NumericEditField
        lambdaEditFieldLabel         matlab.ui.control.Label
        KEditField                   matlab.ui.control.NumericEditField
        KEditFieldLabel              matlab.ui.control.Label
        VthEditField                 matlab.ui.control.NumericEditField
        VthEditFieldLabel            matlab.ui.control.Label
        GetresiduesButton            matlab.ui.control.Button
        R2EditField                  matlab.ui.control.NumericEditField
        R2EditFieldLabel             matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        Image4                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        CalculateButton              matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        ExportFitParametersMenu      matlab.ui.container.Menu
        ContextMenu2                 matlab.ui.container.ContextMenu
        SaveasMenu                   matlab.ui.container.Menu
        ExportDatatoWorkspaceMenu    matlab.ui.container.Menu
        ContextMenu3                 matlab.ui.container.ContextMenu
        SaveasMenu_2                 matlab.ui.container.Menu
        ExportDatatoWorkspaceMenu_2  matlab.ui.container.Menu
    end

    
    properties (Access = public)
        MainMOSFETApp % Description
        Vd;
        Vg;
        V_ajuste;
        Id_ajuste;
        Id_medido;
    end

    properties (Access = private)
        valid_V
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if strcmp(app.MainMOSFETApp.XAxisDropDown.Value, 'V_DS')
                app.valid_V = app.MainMOSFETApp.V_O_mean < 10;
                app.Vg = app.MainMOSFETApp.V_G(app.valid_V);
                app.Vd = app.MainMOSFETApp.V_D(app.valid_V);
                app.Id_medido = app.MainMOSFETApp.I_D_mean(app.valid_V);
            elseif strcmp(app.MainMOSFETApp.XAxisDropDown.Value, 'V_GS')
                app.valid_V = app.MainMOSFETApp.V_O_mean < 10;
                app.Vg = app.MainMOSFETApp.V_G(app.valid_V);
                app.Vd = app.MainMOSFETApp.V_D(app.valid_V);
                app.Id_medido = app.MainMOSFETApp.I_D_mean(app.valid_V);
            end
            
            % Prepara xData e yData
            xData = [app.Vg, app.Vd];  % Si tienes ambos
            yData = app.Id_medido;
            
            % Parámetros iniciales
            if strcmp(app.MainMOSFETApp.XAxisDropDown.Value, 'V_DS')
                Vth0 = 1.803281;       % estimación inicial de umbral
                K0 = 0.002242731;         % estimación inicial
                lambda0 = 0.01332326;    % estimación inicial
                params0 = [Vth0, K0, lambda0];
            elseif strcmp(app.MainMOSFETApp.XAxisDropDown.Value, 'V_GS')
                Vth0 = 1.116464;       % estimación inicial de umbral
                K0 = 0.0007117044;         % estimación inicial
                lambda0 = 0.00923674;    % estimación inicial
                params0 = [Vth0, K0, lambda0];
            end
            
            % Límites inferiores y superiores (opcional)
            lb = [0, 0, 0];
            ub = [Inf, Inf, Inf];
            
            % Ajuste con lsqcurvefit usando la función mosfetModel de MainMOSFETApp.
            % Se asume que en MainMOSFETApp tienes un método mosfetModel implementado.
            options = optimset('Display','iter');
            [paramsFit, ~, residuals, ~, ~, ~, J] = lsqcurvefit(@(p,x) app.MainMOSFETApp.mosfetModel(p,x), params0, xData, yData, lb, ub, options);
            
            % Extraer parámetros ajustados
            Vth_fit = paramsFit(1);
            K_fit = paramsFit(2);
            lambda_fit = paramsFit(3);
            
            % Calcular la suma de los residuos y determinar la varianza
            SSres = sum(residuals.^2);
            n = length(yData);         % número de datos
            p = length(paramsFit);       % número de parámetros
            variance = SSres / (n - p);
            
            % Calcular la matriz de covarianza y extraer los errores
            cov_matrix = inv(J' * J) * variance;
            error_Vth = sqrt(cov_matrix(1,1));
            error_K   = sqrt(cov_matrix(2,2));
            error_lambda = sqrt(cov_matrix(3,3));
            
            % Calcular R^2
            SStot = sum((yData - mean(yData)).^2);
            R2 = 1 - (SSres / SStot);
            
            % Guardar o mostrar en la UI (se muestran los parámetros y sus errores)
            app.VthEditField.Value = Vth_fit;
            app.KEditField.Value = K_fit;
            app.lambdaEditField.Value = lambda_fit;
            app.R2EditField.Value = R2;
            
            % Supongamos que tienes EditFields para mostrar los errores:
            app.ErrorVthEditField.Value = num2str(error_Vth, '%11.4g');
            app.ErrorKEditField.Value = num2str(error_K, '%11.4g');
            app.ErrorlambdaEditField.Value = num2str(error_lambda, '%11.4g');
            
            % Graficar datos vs. ajuste
            app.Id_ajuste = app.MainMOSFETApp.mosfetModel(paramsFit, xData);
            % Aquí, se asume que REditField es un factor de escala y que hay Vd en MainMOSFETApp
            app.V_ajuste = app.Id_ajuste * app.MainMOSFETApp.REditField.Value + app.Vd;
            if strcmp(app.MainMOSFETApp.XAxisDropDown.Value, 'V_DS')
                plot(app.MainMOSFETApp.UIAxes, app.Vd, app.Id_ajuste, 'DisplayName', 'Ifit');
                plot(app.MainMOSFETApp.UIAxes2, app.Vd, app.V_ajuste, 'DisplayName', 'Vfit');
            elseif strcmp(app.MainMOSFETApp.XAxisDropDown.Value, 'V_GS')
                plot(app.MainMOSFETApp.UIAxes, app.Vg, app.Id_ajuste, 'DisplayName', 'Ifit');
                plot(app.MainMOSFETApp.UIAxes2, app.Vg, app.V_ajuste, 'DisplayName', 'Vfit');
            end
        end

        % Menu selected function: ExportFitParametersMenu
        function ExportFitParametersMenuSelected(app, event)
            fields = {
                'Vth', app.VthEditField.Value;
                'ErrorVth', app.ErrorVthEditField.Value;
                'K', app.KEditField.Value;
                'ErrorK', app.ErrorKEditField.Value;
                'lambda', app.lambdaEditField.Value;
                'Errorlambda', app.ErrorlambdaEditField.Value;
                'R2', app.R2EditField.Value;
            };
        
            % Exportar cada campo al workspace
            for i = 1:size(fields, 1)
                fieldName = fields{i, 1};
                fieldValue = fields{i, 2};
                assignin('base', matlab.lang.makeValidName(fieldName), fieldValue);
            end
        
            uialert(app.UIFigure, 'Fit parameters exported to workspace.', 'Exportation Completed')
        end

        % Button pushed function: GetresiduesButton
        function GetresiduesButtonPushed(app, event)
            % -------------Residuos para V_O vs V_D ------------------------
            
            if isempty(app.Vd) || isempty(app.Vg) || isempty(app.V_ajuste)
                uialert(app.UIFigure, 'First, fit the curve V_O vs V.', 'Error');
                return;
            end
        
            % Calcular la predicción del modelo
            V_medido = app.MainMOSFETApp.V_O_mean(app.valid_V);
            % Calcular los residuos (diferencia entre datos medidos y modelo ajustado)
            residuals_V = V_medido - app.V_ajuste;
            
            % ------------Residuos para el ajuste de I vs V----------
            % Se asume que ya se realizó el ajuste y se almacenaron:
            if isempty(app.Vd) || isempty(app.Vg) || isempty(app.Id_ajuste)
                uialert(app.UIFigure, 'First, fit the curve I vs V.', 'Error');
                return;
            end
        
            %I_D_model = app.model_ID(app.optimal_coeff_ID, app.V_D_fit);
            residuals_I = app.Id_medido - app.Id_ajuste;

            if strcmp(app.MainMOSFETApp.XAxisDropDown.Value, 'V_DS')
                plot(app.UIAxes, app.Vd, residuals_I)
                plot(app.UIAxes2, app.Vd, residuals_V)
                xlabel(app.UIAxes, 'V_{DS} [V]')
                xlabel(app.UIAxes2, 'V_{DS} [V]')
            elseif strcmp(app.MainMOSFETApp.XAxisDropDown.Value, 'V_GS')
                plot(app.UIAxes, app.Vg, residuals_I)
                plot(app.UIAxes2, app.Vg, residuals_V)
                xlabel(app.UIAxes, 'V_{GS} [V]')
                xlabel(app.UIAxes2, 'V_{GS} [V]')
            end
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            % Open a save dialog box
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.fig'}, 'Save Plot As');
            
            % If user cancels, do nothing
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % Build the full file name
            fullFileName = fullfile(path, file);
            
            % Get the file extension
            [~, ~, ext] = fileparts(fullFileName);
            
            % Save the current axes as the selected format
            switch ext
                case '.png'
                    exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
                case '.jpg'
                    exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
                case '.pdf'
                    exportgraphics(app.UIAxes, fullFileName);
                case '.fig'
                    % Save as a MATLAB figure
                    fig = figure('Visible', 'off'); % Create an invisible figure
                    axCopy = copyobj(app.UIAxes, fig); % Copy the axes to the new figure
                    set(axCopy, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]); % Resize
                    savefig(fig, fullFileName); % Save the figure
                    close(fig); % Close the temporary figure
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
            end
        end

        % Menu selected function: ExportDatatoWorkspaceMenu
        function ExportDatatoWorkspaceMenuSelected(app, event)
            % Obtener el objeto gráfico activo
            srcObject = app.UIAxes.Children; % Obtiene todos los objetos del eje
        
            % Iterar sobre los objetos del eje (por si hay varios gráficos)
            for i = 1:numel(srcObject)
                if strcmp(srcObject(i).Type, 'line') % Verificar si es una línea
                    % Extraer datos X e Y de la línea
                    xData = srcObject(i).XData;
                    yData = srcObject(i).YData;
                    
                    % Crear una matriz con dos columnas: X y Y
                    data = [xData(:), yData(:)]; % Asegurarse de que sean columnas
                    
                    % Obtener el nombre descriptivo (DisplayName)
                    graphName = srcObject(i).DisplayName;
                    
                    % Validar el nombre para que sea un nombre de variable válido
                    if isempty(graphName) % Si no hay DisplayName, usar un nombre genérico
                        graphName = sprintf('ExportedData_%d', i);
                    else
                        graphName = matlab.lang.makeValidName(graphName); % Asegurarse de que sea válido
                    end
                    
                    % Exportar al Workspace con el nombre descriptivo
                    assignin('base', graphName, data);
                end
            end
            
            % Notificación al usuario
            uialert(app.UIFigure, 'Data Exported to Workspace', 'Exportation completed');
        end

        % Menu selected function: SaveasMenu_2
        function SaveasMenu_2Selected(app, event)
            % Open a save dialog box
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.fig'}, 'Save Plot As');
            
            % If user cancels, do nothing
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % Build the full file name
            fullFileName = fullfile(path, file);
            
            % Get the file extension
            [~, ~, ext] = fileparts(fullFileName);
            
            % Save the current axes as the selected format
            switch ext
                case '.png'
                    exportgraphics(app.UIAxes2, fullFileName, 'Resolution', 300);
                case '.jpg'
                    exportgraphics(app.UIAxes2, fullFileName, 'Resolution', 300);
                case '.pdf'
                    exportgraphics(app.UIAxes2, fullFileName);
                case '.fig'
                    % Save as a MATLAB figure
                    fig = figure('Visible', 'off'); % Create an invisible figure
                    axCopy = copyobj(app.UIAxes2, fig); % Copy the axes to the new figure
                    set(axCopy, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]); % Resize
                    savefig(fig, fullFileName); % Save the figure
                    close(fig); % Close the temporary figure
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
            end
        end

        % Menu selected function: ExportDatatoWorkspaceMenu_2
        function ExportDatatoWorkspaceMenu_2Selected(app, event)
            % Obtener el objeto gráfico activo
            srcObject = app.UIAxes2.Children; % Obtiene todos los objetos del eje
        
            % Iterar sobre los objetos del eje (por si hay varios gráficos)
            for i = 1:numel(srcObject)
                if strcmp(srcObject(i).Type, 'line') % Verificar si es una línea
                    % Extraer datos X e Y de la línea
                    xData = srcObject(i).XData;
                    yData = srcObject(i).YData;
                    
                    % Crear una matriz con dos columnas: X y Y
                    data = [xData(:), yData(:)]; % Asegurarse de que sean columnas
                    
                    % Obtener el nombre descriptivo (DisplayName)
                    graphName = srcObject(i).DisplayName;
                    
                    % Validar el nombre para que sea un nombre de variable válido
                    if isempty(graphName) % Si no hay DisplayName, usar un nombre genérico
                        graphName = sprintf('ExportedData_%d', i);
                    else
                        graphName = matlab.lang.makeValidName(graphName); % Asegurarse de que sea válido
                    end
                    
                    % Exportar al Workspace con el nombre descriptivo
                    assignin('base', graphName, data);
                end
            end
            
            % Notificación al usuario
            uialert(app.UIFigure, 'Data Exported to Workspace', 'Exportation completed');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Residues for I vs V')
            ylabel(app.UIAxes, 'Residues for I [A]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Residues for V_O vs V')
            ylabel(app.UIAxes2, 'Residues for V_O [V]')
            zlabel(app.UIAxes2, 'Z')
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'MOSFETCharacteristicsCurrentEquation.svg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'MOSFETCharacteristicsKEquation.svg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'MOSFETCharacteristicsVoltageEquation.svg');
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R^2';
            app.R2EditField = uieditfield(app.UIFigure, 'numeric');
            app.GetresiduesButton = uibutton(app.UIFigure, 'push');
            app.GetresiduesButton.ButtonPushedFcn = createCallbackFcn(app, @GetresiduesButtonPushed, true);
            app.GetresiduesButton.Text = 'Get residues';
            app.VthEditFieldLabel = uilabel(app.UIFigure);
            app.VthEditFieldLabel.Text = 'Vth';
            app.VthEditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.lambdaEditFieldLabel = uilabel(app.UIFigure);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrorVthEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorVthEditFieldLabel.Text = 'Error Vth';
            app.ErrorVthEditField = uieditfield(app.UIFigure, 'text');
            app.ErrorKEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorKEditFieldLabel.Text = 'Error K';
            app.ErrorKEditField = uieditfield(app.UIFigure, 'text');
            app.ErrorlambdaEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorlambdaEditFieldLabel.Text = 'Error lambda';
            app.ErrorlambdaEditField = uieditfield(app.UIFigure, 'text');
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.UIFigure);
            app.VLabel_2.Text = 'V';
            app.VLabel_3 = uilabel(app.UIFigure);
            app.VLabel_3.Text = '1/V';
            app.VLabel_4 = uilabel(app.UIFigure);
            app.VLabel_4.Text = '1/V';
            app.AV2Label = uilabel(app.UIFigure);
            app.AV2Label.Text = 'A/V^2';
            app.AV2Label_2 = uilabel(app.UIFigure);
            app.AV2Label_2.Text = 'A/V^2';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExportFitParametersMenu = uimenu(app.ContextMenu);
            app.ExportFitParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportFitParametersMenuSelected, true);
            app.ExportFitParametersMenu.Text = 'Export Fit Parameters';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.SaveasMenu = uimenu(app.ContextMenu2);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as';
            app.ExportDatatoWorkspaceMenu = uimenu(app.ContextMenu2);
            app.ExportDatatoWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDatatoWorkspaceMenuSelected, true);
            app.ExportDatatoWorkspaceMenu.Text = 'Export Data to Workspace';
            % Assign app.ContextMenu2
            app.UIAxes.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.SaveasMenu_2 = uimenu(app.ContextMenu3);
            app.SaveasMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenu_2Selected, true);
            app.SaveasMenu_2.Text = 'Save as';
            app.ExportDatatoWorkspaceMenu_2 = uimenu(app.ContextMenu3);
            app.ExportDatatoWorkspaceMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ExportDatatoWorkspaceMenu_2Selected, true);
            app.ExportDatatoWorkspaceMenu_2.Text = 'Export Data to Workspace';
            % Assign app.ContextMenu3
            app.UIAxes2.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurveFittingDialogMOSFET

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end