classdef DiodeCharacteristics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        SaveasMenu_3                 matlab.ui.container.Menu
        DiodeIVCharacteristicMenu    matlab.ui.container.Menu
        OutputVoltageMenu            matlab.ui.container.Menu
        ExporttoWorkspaceMenu        matlab.ui.container.Menu
        DiodeIVCharacteristicMenu_2  matlab.ui.container.Menu
        OutputVoltageMenu_2          matlab.ui.container.Menu
        LoadDataMenu_3               matlab.ui.container.Menu
        DiodeIVCharacteristicMenu_3  matlab.ui.container.Menu
        OutputVoltageMenu_3          matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        HelpMenu_2                   matlab.ui.container.Menu
        DocumentationMenu            matlab.ui.container.Menu
        ResetFiguresButton           matlab.ui.control.Button
        MeasurementOptionsPanel      matlab.ui.container.Panel
        FullScaleRangeButton         matlab.ui.control.Button
        CurvefittingButton           matlab.ui.control.Button
        ShowSatLimitsButton          matlab.ui.control.Button
        FastModeCheckBox             matlab.ui.control.CheckBox
        LampLabel                    matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        MeasurementStatusPanel       matlab.ui.container.Panel
        MeasurementStatusGauge       matlab.ui.control.LinearGauge
        MeasurementStatusGaugeLabel  matlab.ui.control.Label
        StopSimulationButton         matlab.ui.control.Button
        RunMeasurementsButton        matlab.ui.control.Button
        NImyDAQPortsPanel            matlab.ui.container.Panel
        VODropDown                   matlab.ui.control.DropDown
        VODropDownLabel              matlab.ui.control.Label
        VNDropDown                   matlab.ui.control.DropDown
        VNDropDownLabel              matlab.ui.control.Label
        VPDropDown                   matlab.ui.control.DropDown
        VPDropDownLabel              matlab.ui.control.Label
        IOffEditField                matlab.ui.control.NumericEditField
        IOffEditFieldLabel           matlab.ui.control.Label
        NAveragingEditField          matlab.ui.control.NumericEditField
        NAveragingEditFieldLabel     matlab.ui.control.Label
        REditField                   matlab.ui.control.NumericEditField
        REditFieldLabel              matlab.ui.control.Label
        VNstepsEditField             matlab.ui.control.NumericEditField
        VNstepsEditFieldLabel        matlab.ui.control.Label
        VNmaxEditField               matlab.ui.control.NumericEditField
        VNmaxEditFieldLabel          matlab.ui.control.Label
        VNminEditField               matlab.ui.control.NumericEditField
        VNminEditFieldLabel          matlab.ui.control.Label
        VPstepsEditField             matlab.ui.control.NumericEditField
        VPstepsEditFieldLabel        matlab.ui.control.Label
        VPmaxEditField               matlab.ui.control.NumericEditField
        VPmaxEditFieldLabel          matlab.ui.control.Label
        VPminEditField               matlab.ui.control.NumericEditField
        VPminEditFieldLabel          matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        NImyDAQSwitch                matlab.ui.control.Switch
        ButtonGroup                  matlab.ui.container.ButtonGroup
        LogButton                    matlab.ui.control.RadioButton
        LinButton                    matlab.ui.control.RadioButton
        HoldonCheckBox               matlab.ui.control.CheckBox
        NImyDAQPanel                 matlab.ui.container.Panel
        DeviceIDEditField            matlab.ui.control.EditField
        DeviceIDEditFieldLabel       matlab.ui.control.Label
        InputSignalPropertiesPanel   matlab.ui.container.Panel
        SweepCheckBox                matlab.ui.control.CheckBox
        VLabel_4                     matlab.ui.control.Label
        VLabel_3                     matlab.ui.control.Label
        VLabel_2                     matlab.ui.control.Label
        VLabel                       matlab.ui.control.Label
        MeasurementPropertiesPanel   matlab.ui.container.Panel
        ALabel                       matlab.ui.control.Label
        OhmLabel                     matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        RenameMenu                   matlab.ui.container.Menu
        SaveasMenu                   matlab.ui.container.Menu
        ExportDatatoWorkspaceMenu    matlab.ui.container.Menu
        LoadDataMenu                 matlab.ui.container.Menu
        HideCurveMenu_2              matlab.ui.container.Menu
        ShowCurveMenu_2              matlab.ui.container.Menu
        ContextMenu2                 matlab.ui.container.ContextMenu
        RenameMenu_2                 matlab.ui.container.Menu
        SaveasMenu_2                 matlab.ui.container.Menu
        ExportDatatoWorkspaceMenu_2  matlab.ui.container.Menu
        LoadDataMenu_2               matlab.ui.container.Menu
        HideCurveMenu                matlab.ui.container.Menu
        ShowCurveMenu                matlab.ui.container.Menu
        ContextMenu3                 matlab.ui.container.ContextMenu
        ExportConfigtoWorkspaceMenu  matlab.ui.container.Menu
        HelpMenu_3                   matlab.ui.container.Menu
        DocumentationMenu_2          matlab.ui.container.Menu
        ContextMenu4                 matlab.ui.container.ContextMenu
        ExportFitParameterstoWorkspaceMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        HelpAppInstance % For the HelpApp
        DocumentationAppInstance % For the DocumentationApp
        StopSimulation = false; % Variable para controlar si se detiene la simulación
        SatLinesVisible = false; % Estado de las líneas de saturación
        SatLinesHandles % Handles para las líneas de saturación
        VLoadedData % Estructura para almacenar las variables cargadas
        ILoadedData % Estructura para almacenar las variables cargadas
        VisibleCurves % Mapa para almacenar la visibilidad de las curvas
        VPsteps
        VNsteps
    end
    
    properties (Access = public)
        V_P; % Description
        V_N;
        V_D;
        I_D_mean;
        V_O_mean;
        CurveFitDialog;
        NoiseDialog;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.REditField.Value = 1000; % Resistance value
            app.VPminEditField.Value = 0;          % Voltaje mínimo para VP
            app.VPmaxEditField.Value = 0.8;        % Voltaje máximo para VP
            app.VPstepsEditField.Value = 25;       % Número de pasos para VP
            app.VNminEditField.Value = 0;          % Voltaje fijo para VN
            app.VNmaxEditField.Value = 0;          % Voltaje fijo para VN
            app.VNstepsEditField.Value = 25;        % Pasos
            app.NAveragingEditField.Value = 1;
            app.Lamp.Color = 'red';
            app.LampLabel.Text = 'Disconnected';
            % Configurar los valores iniciales para los dropdowns
            app.VPDropDown.Items = {'ao0', 'ao1'};
            app.VNDropDown.Items = {'ao0', 'ao1'};
            app.VODropDown.Items = {'ai0', 'ai1'};
        
            % Configurar los valores predeterminados
            app.VPDropDown.Value = 'ao0'; % Predeterminado para VP
            app.VNDropDown.Value = 'ao1'; % Predeterminado para VN
            app.VODropDown.Value = 'ai1'; % Predeterminado para VO

            app.DeviceIDEditField.Value = 'myDAQ1'; % Predeterminado para DeviceID

            app.UIFigure.Resize = 'off';
        end

        % Menu selected function: HelpMenu_2
        function HelpMenu_2Selected(app, event)
            run('HelpDiodeApp.mlapp')
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            run('DocumentationDiodeApp.mlapp')
        end

        % Button pushed function: RunMeasurementsButton
        function RunMeasurementsButtonPushed(app, event)
if strcmp(app.VPDropDown.Value, app.VNDropDown.Value)
    uialert(app.UIFigure, 'The channels VP and VN cannot be the same.', 'Channel Error');
    return;
elseif app.NImyDAQSwitch.Value == "Off"
    uialert(app.UIFigure, 'Turn on the NI myDAQ switch.', 'Error');
    return;
elseif any([app.VPminEditField.Value, app.VPmaxEditField.Value, app.VNminEditField.Value, app.VNmaxEditField.Value]<-10) ||any([app.VPminEditField.Value, app.VPmaxEditField.Value, app.VNminEditField.Value, app.VNmaxEditField.Value]>10)
    uialert(app.UIFigure, 'Channels configured for -10 to +10 Volts.', 'Error');
    return;
elseif ~app.HoldonCheckBox.Value
    % Reestablecer líneas de saturación
    app.SatLinesHandles = []; % Limpiar los handles
    % Cambiar el estado
    app.SatLinesVisible = false;
    app.ShowSatLimitsButton.Text = 'Show Sat Limits'; % Cambiar texto del botón
end
            
% Configuración de parámetros de barrido
VPmin = app.VPminEditField.Value;          % Voltaje mínimo para VP
VPmax = app.VPmaxEditField.Value;          % Voltaje máximo para VP
app.VPsteps = app.VPstepsEditField.Value;      % Número de pasos para VP
VNmin = app.VNminEditField.Value;          % Voltaje fijo para VN
VNmax = app.VNmaxEditField.Value;          % Voltaje fijo para VN
app.VNsteps = app.VNstepsEditField.Value;      % Pasos
VP = app.VPDropDown.Value;                 % Canal VP
VN = app.VNDropDown.Value;                 % Canal VN
VO = app.VODropDown.Value;                 % Canal VO
IOff = app.IOffEditField.Value;            % IOff
Vsteps = max(app.VPsteps, app.VNsteps);
NAveraging = app.NAveragingEditField.Value;

if app.VPsteps < 0 || mod(app.VPsteps, 1) ~= 0 || app.VNsteps < 0 || mod(app.VNsteps, 1) ~= 0
    uialert(app.UIFigure, 'VPsteps and VNsteps must be positive values and integers.', 'Error');
    return;
elseif NAveraging < 0 || mod(NAveraging, 1) ~= 0
    uialert(app.UIFigure, 'NAveraging must be a positive value and an integer.', 'Error')
end

% Configuración del dispositivo DAQ
dq = daq("ni");

% Añadir los canales de salida seleccionados
addoutput(dq, app.DeviceIDEditField.Value, str2double(VP(end)), "Voltage"); % VP
addoutput(dq, app.DeviceIDEditField.Value, str2double(VN(end)), "Voltage"); % VN

% Añadir el canal de entrada seleccionado
addinput(dq, app.DeviceIDEditField.Value, VO, "Voltage"); % VO

% Inicializar barra de progreso
app.MeasurementStatusGauge.Value = 0;  % Reiniciar progreso

% Reiniciar la bandera de detención
app.StopSimulation = false;

if app.FastModeCheckBox.Value

    uialert(app.UIFigure, 'Fast Mode is in development. Results might not be accurate.', 'Warning')

    if Vsteps < 1000
        uialert(app.UIFigure, 'Fast Mode needs a high number of points. Make sure VPsteps and VNsteps are at least 1000.', 'Error')
        return
    elseif app.SweepCheckBox.Value
        uialert(app.UIFigure, 'Sweep is not available in Fast Mode.', 'Error')
        return
    end

    app.V_P = [linspace(VPmin, VPmax, Vsteps)'; linspace(VPmax,VPmin, Vsteps)'];
    app.V_N = [linspace(VNmin, VNmax, Vsteps)'; linspace(VNmax,VNmin, Vsteps)'];
    V_N_I = linspace(VNmin, VNmax, Vsteps);

    % Vector diferencial de voltaje
    app.V_D = app.V_P - app.V_N;

    outputData = [app.V_P, app.V_N];  % Configurar las señales de salida
    data = readwrite(dq, outputData);  % Operación simultánea
    
    % Extraer el voltaje medido V0
    V_O_fast = data.Variables;

    n = length(V_O_fast); % Longitud del vector
    mid = floor(n/2); % Calculamos el punto medio
    
    V_O_fast1 = V_O_fast(1:mid); % Una curva será hasta la mita
    V_D1 = app.V_D(1:mid); % En el eje X también
    
    V_O_fast2 = flip(V_O_fast(mid+1:end)); % Y aquí la otra mitad, invertida para que vaya de 0 a end
    V_D2 = flip(app.V_D(mid+1:end)); % También en X
    
    app.V_D = (V_D1 + V_D2)/2;

    % Preprocesamiento: Eliminar duplicados en las curvas
    [V_O_fast1_unique, idx1] = unique(V_O_fast1, 'stable'); % Eliminar duplicados en y para la curva 1
    V_D1_unique = app.V_D(idx1); % Ajustar las x correspondientes
    
    [V_O_fast2_unique, idx2] = unique(V_O_fast2, 'stable'); % Eliminar duplicados en y para la curva 2
    V_D2_unique = app.V_D(idx2); % Ajustar las x correspondientes
    
    % Definir un rango de valores de Y para interpolar
    y_min = max(min(V_O_fast1_unique), min(V_O_fast2_unique)); % Máximo mínimo compartido
    y_max = min(max(V_O_fast1_unique), max(V_O_fast2_unique)); % Mínimo máximo compartido
    n_points = 1000; % Número de puntos para la interpolación
    y_values = linspace(y_min, y_max, n_points); % Valores de y para las líneas horizontales
    
    % Interpolación horizontal
    x_interp1 = interp1(V_O_fast1_unique, V_D1_unique, y_values, 'linear', 'extrap'); % x de la curva 1
    x_interp2 = interp1(V_O_fast2_unique, V_D2_unique, y_values, 'linear', 'extrap'); % x de la curva 2
    
    % Calcular el promedio horizontal
    app.V_D = (x_interp1 + x_interp2) / 2;
    app.V_D = flip(app.V_D);
    % EL eje Y será:
    app.V_O_mean = y_values;
    app.V_O_mean = flip(app.V_O_mean);

    % Cálculo de la corriente del diodo (I_D)
    app.I_D_mean = (V_N_I - app.V_O_mean) / app.REditField.Value - IOff;
    
    % Crear el primer plot en UIAxes2
    plot(app.UIAxes2, app.V_D, app.V_O_mean);
    xlabel(app.UIAxes2, 'V_D [V]')
    ylabel(app.UIAxes2, 'V_O [V]')
    title(app.UIAxes2, 'Output Voltage')
    legend(app.UIAxes2, 'Location', 'best')
    
    % Graficar resultados en UIAxes (I_D vs V_D)
    plot(app.UIAxes, app.V_D, app.I_D_mean);
    xlabel(app.UIAxes, 'V_D [V]')
    ylabel(app.UIAxes, 'I_D [A]')
    title(app.UIAxes, 'Diode I-V Characteristic')
    legend(app.UIAxes, 'Location', 'best')
else
    if app.SweepCheckBox.Value

        if ~app.HoldonCheckBox.Value
            uialert(app.UIFigure, 'Turn on the Hold on check box in order to represent multiple curves.', 'Error')
            return
        end

        % Vectores de barrido
        app.V_P = linspace(VPmin, VPmax, app.VPsteps)';
        app.V_N = linspace(VNmin, VNmax, app.VNsteps)';
        
        % Crear malla de combinaciones
        [VPm, VNm] = meshgrid(app.V_P, app.V_N);
        
        % Aplanar para DAQ
        VPv = VPm(:);
        VNv = VNm(:);

        % Medida en bucle
        if app.VPsteps >= app.VNsteps
            nPts = numel(VPv);
        else
            nPts = numel(VNv);
        end
        
        V_O_values = zeros(nPts,1);
        Vout = zeros(NAveraging, 1);
        for k = 1:nPts
            if app.StopSimulation
                break;
            end

            for j=1:NAveraging
                write(dq, [VPv(k), VNv(k)]);
                data = read(dq, 1);
                Vout(j) = data.Variables;
            end

            V_O_values(k) = mean(Vout);

            % Actualizar la barra de progreso
            progress = (k / nPts) * 100;  % Calcular porcentaje de avance
            app.MeasurementStatusGauge.Value = progress;  % Actualizar valor de la barra
            drawnow;  % Refrescar la interfaz gráfica
        end
        
        % Calcular V_D y corriente I_D
        VDv   = VPv - VNv;
        IDv   = (VNv - V_O_values) / app.REditField.Value - IOff;
        
        % Reconstruir matrices (filas = distintos V_N, columnas = V_P)
        if app.VPsteps >= app.VNsteps
            app.V_D   = reshape(VDv, size(VPm));
            app.V_O_mean = reshape(V_O_values, size(VPm));
            app.I_D_mean   = reshape(IDv, size(VPm));
        else
            app.V_D   = reshape(VDv, size(VNm));
            app.V_O_mean = reshape(V_O_values, size(VNm));
            app.I_D_mean   = reshape(IDv, size(VNm));
        end
        
        
        % Graficar Vout vs V_D para cada curva de V_N
        if app.VPsteps >= app.VNsteps
            for row = 1:size(app.V_D,1)
                plot(app.UIAxes2, app.V_D(row,:), app.V_O_mean(row,:), 'LineWidth', 1.5);
            end
        else
            for col = 1:size(app.V_D,2)
                plot(app.UIAxes2, app.V_D(:,col), app.V_O_mean(:,col), 'LineWidth',1.5);
            end
        end
        xlabel(app.UIAxes2, 'V_D [V]')
        ylabel(app.UIAxes2, 'V_O [V]')
        title(app.UIAxes2, 'Output Voltage')
        legend(app.UIAxes2, 'Location', 'best')
        
        % Graficar I_D vs V_D para cada curva de V_N
        if app.VPsteps >= app.VNsteps
            for row = 1:size(app.V_D,1)
                plot(app.UIAxes, app.V_D(row,:), app.I_D_mean(row,:), 'LineWidth', 1.5);
            end
        else
            for col = 1:size(app.V_D,2)
                plot(app.UIAxes, app.V_D(:,col), app.I_D_mean(:,col), 'LineWidth',1.5);
            end
        end
        xlabel(app.UIAxes, 'V_D [V]')
        ylabel(app.UIAxes, 'I_D [A]')
        title(app.UIAxes, 'Diode I-V Characteristic')
        legend(app.UIAxes, 'Location', 'best')

    else
        % Crear los vectores ajustados
        app.V_P = linspace(VPmin, VPmax, Vsteps)';
        app.V_N = linspace(VNmin, VNmax, Vsteps)';
    
        % Vector diferencial de voltaje
        app.V_D = app.V_P - app.V_N;
    
        % Inicializar variables para almacenar promedios
        app.V_O_mean = zeros(size(app.V_D));  % Voltaje de salida promedio
    
        V_O_values = zeros(NAveraging, 1);  % Almacenar mediciones para el promedio
    
        for i = 1:length(app.V_D)
            if app.StopSimulation
                    break;
            end
    
            for j = 1:NAveraging
                % Escribir valores de VP y VN
                write(dq, [app.V_P(i), app.V_N(i)]);
    
                % Leer el voltaje de salida (V0)
                data = read(dq, 1);  % Leer 1 muestra
                V_O_values(j) = data.Variables;  % Almacenar la medición individual
            end
    
            % Calcular el promedio para el punto actual
            app.V_O_mean(i) = mean(V_O_values);
    
            % Actualizar la barra de progreso
            progress = (i / length(app.V_D)) * 100;  % Calcular porcentaje de avance
            app.MeasurementStatusGauge.Value = progress;  % Actualizar valor de la barra
            drawnow;  % Refrescar la interfaz gráfica
        end
        % Cálculo de la corriente del diodo (I_D)
        app.I_D_mean = (app.V_N - app.V_O_mean) / app.REditField.Value - IOff;
        
        % Crear el primer plot en UIAxes2
        plot(app.UIAxes2, app.V_D, app.V_O_mean);
        xlabel(app.UIAxes2, 'V_D [V]')
        ylabel(app.UIAxes2, 'V_O [V]')
        title(app.UIAxes2, 'Output Voltage')
        legend(app.UIAxes2, 'Location', 'best')
        
        % Graficar resultados en UIAxes (I_D vs V_D)
        plot(app.UIAxes, app.V_D, app.I_D_mean);
        xlabel(app.UIAxes, 'V_D [V]')
        ylabel(app.UIAxes, 'I_D [A]')
        title(app.UIAxes, 'Diode I-V Characteristic')
        legend(app.UIAxes, 'Location', 'best')
    end
end

% Eliminar canales
removechannel(dq, 1:length(dq.Channels));
        end

        % Value changed function: HoldonCheckBox
        function HoldonCheckBoxValueChanged(app, event)
            if app.HoldonCheckBox.Value
                hold(app.UIAxes, 'on');  % Enable hold on
                hold(app.UIAxes2, 'on');
            else
                hold(app.UIAxes, 'off'); % Disable hold on
                hold(app.UIAxes2, 'off');
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            % Callback function para el Radio Button Group
            switch selectedButton.Text
                case 'Lin'
                    % Cambiar el eje x a escala lineal
                    set(app.UIAxes, 'YScale', 'linear');
                case 'Log'
                    % Cambiar el eje x a escala logarítmica
                    set(app.UIAxes, 'YScale', 'log');
            end
        end

        % Value changed function: NImyDAQSwitch
        function NImyDAQSwitchValueChanged(app, event)
            % Obtener el estado del switch (On/Off)
            switchState = app.NImyDAQSwitch.Value;
            
            if strcmp(switchState, 'On') % Si el switch está en "On"
                % Limpiamos el registro
                clear devices;
                % Buscar dispositivos DAQ conectados
                devices = daqlist;
                
                if isempty(devices) % Si no se detectan dispositivos
                    app.Lamp.Color = 'red';    % Cambiar el color de la lámpara a rojo
                    app.LampLabel.Text = 'Disconnected'; % Mostrar "Disconnected"
                    uialert(app.UIFigure, 'No devices have been found. Make sure the NI myDAQ is connected properly. If the error persists, restart MATLAB and try again.', 'Error')
                else
                    app.Lamp.Color = 'green';  % Cambiar el color de la lámpara a verde
                    app.LampLabel.Text = 'Connected';   % Mostrar "Connected"
                end
                
            else % Si el switch está en "Off"
                app.Lamp.Color = 'red';    % Cambiar el color de la lámpara a rojo
                app.LampLabel.Text = 'Disconnected'; % Mostrar "Disconnected"
            end
        end

        % Button pushed function: StopSimulationButton
        function StopSimulationButtonPushed(app, event)
            app.StopSimulation = true; % Indica que se debe detener la simulación
        end

        % Button pushed function: ResetFiguresButton
        function ResetFiguresButtonPushed(app, event)
            % --- Para app.UIAxes ---
            % (Opcional) Guardar el estado de "hold"
            holdState1 = ishold(app.UIAxes);
            
            % Eliminar todos los objetos gráficos del axes (sin resetear sus propiedades)
            delete(app.UIAxes.Children);
            
            % Reestablecer el grid y el minor grid
            grid(app.UIAxes, 'on');
            app.UIAxes.GridLineWidth = 0.5;
            app.UIAxes.GridAlpha = 0.15;
            app.UIAxes.MinorGridAlpha = 0.25;
            app.UIAxes.MinorGridLineWidth = 0.5;
            app.UIAxes.XMinorGrid = 'on';
            app.UIAxes.YMinorGrid = 'on';
            
            % Reestablecer las etiquetas y título
            xlabel(app.UIAxes, 'V_D [V]');
            ylabel(app.UIAxes, 'I_D [A]');
            title(app.UIAxes, 'Diode I-V Characteristic');
            
            % Si previamente estaba en hold, reactivar hold
            if holdState1
                hold(app.UIAxes, 'on');
            end

            % Reestablecer líneas de saturación
            app.SatLinesHandles = []; % Limpiar los handles
    
            % Cambiar el estado
            app.SatLinesVisible = false;
            app.ShowSatLimitsButton.Text = 'Show Sat Limits'; % Cambiar texto del botón
            
            % --- Para app.UIAxes2 ---
            holdState2 = ishold(app.UIAxes2);
            
            delete(app.UIAxes2.Children);
            
            grid(app.UIAxes2, 'on');
            app.UIAxes2.GridLineWidth = 0.5;
            app.UIAxes2.GridAlpha = 0.15;
            app.UIAxes2.MinorGridAlpha = 0.25;
            app.UIAxes2.MinorGridLineWidth = 0.5;
            app.UIAxes2.XMinorGrid = 'on';
            app.UIAxes2.YMinorGrid = 'on';
            
            xlabel(app.UIAxes2, 'V_D [V]');
            ylabel(app.UIAxes2, 'V_O [V]');
            title(app.UIAxes2, 'Output Voltage [V]');
            
            if holdState2
                hold(app.UIAxes2, 'on');
            end
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            % Open a save dialog box
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.fig'}, 'Save Plot As');
            
            % If user cancels, do nothing
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % Build the full file name
            fullFileName = fullfile(path, file);
            
            % Get the file extension
            [~, ~, ext] = fileparts(fullFileName);
            
            % Save the current axes as the selected format
            switch ext
                case '.png'
                    exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
                case '.jpg'
                    exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
                case '.pdf'
                    exportgraphics(app.UIAxes, fullFileName);
                case '.fig'
                    % Save as a MATLAB figure
                    fig = figure('Visible', 'off'); % Create an invisible figure
                    axCopy = copyobj(app.UIAxes, fig); % Copy the axes to the new figure
                    set(axCopy, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]); % Resize
                    savefig(fig, fullFileName); % Save the figure
                    close(fig); % Close the temporary figure
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
            end
        end

        % Menu selected function: SaveasMenu_2
        function SaveasMenu_2Selected(app, event)
            % Open a save dialog box
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.fig'}, 'Save Plot As');
            
            % If user cancels, do nothing
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % Build the full file name
            fullFileName = fullfile(path, file);
            
            % Get the file extension
            [~, ~, ext] = fileparts(fullFileName);
            
            % Save the current axes as the selected format
            switch ext
                case '.png'
                    exportgraphics(app.UIAxes2, fullFileName, 'Resolution', 300);
                case '.jpg'
                    exportgraphics(app.UIAxes2, fullFileName, 'Resolution', 300);
                case '.pdf'
                    exportgraphics(app.UIAxes2, fullFileName);
                case '.fig'
                    % Save as a MATLAB figure
                    fig = figure('Visible', 'off'); % Create an invisible figure
                    axCopy = copyobj(app.UIAxes2, fig); % Copy the axes to the new figure
                    set(axCopy, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]); % Resize
                    savefig(fig, fullFileName); % Save the figure
                    close(fig); % Close the temporary figure
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
            end
        end

        % Menu selected function: HelpMenu_3
        function HelpMenu_3Selected(app, event)
            run('HelpDiodeApp.mlapp')
        end

        % Menu selected function: DocumentationMenu_2
        function DocumentationMenu_2Selected(app, event)
             run('DocumentationDiodeApp.mlapp')
        end

        % Button pushed function: ShowSatLimitsButton
        function ShowSatLimitsButtonPushed(app, event)
            if ~app.HoldonCheckBox.Value
                uialert(app.UIFigure, ...
                    'Make sure the Hold On checkbox is activated to represent multiple curves.', ...
                    'Warning');
                return; % Salir del callback si la condición no se cumple
            elseif isempty(app.V_D) || ~isprop(app, 'V_D')
                uialert(app.UIFigure, ...
                    'Represent a curve in order to show the saturation limits.', ...
                    'Error');
                return;
            end

            if ~app.SatLinesVisible
                if app.SweepCheckBox.Value
                    if app.VPsteps >= app.VNsteps
                        for row=1:size(app.V_D,1)
                            if app.REditField.Value < 5000
                                isatp = 2e-3;
                                isatn = -isatp;
                                vsatp = app.V_N(row, :) + 2e-3 * app.REditField.Value;
                                vsatn = app.V_N(row, :) - 2e-3 * app.REditField.Value;
                            else
                                vsatp = 10;
                                vsatn = -vsatp;
                                isatp = (app.V_N(row,:) - 10)/app.REditField.Value;
                                isatn = -isatp;
                            end
                            V_O_sat_pos = linspace(vsatp, vsatp, length(app.V_D));
                            V_O_sat_neg = linspace(vsatn, vsatn, length(app.V_D));
                            I_D_sat_pos = linspace(isatp, isatp, length(app.V_D));
                            I_D_sat_neg = linspace(isatn, isatn, length(app.V_D));
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes2, app.V_D(row,:), V_O_sat_pos, 'k--', 'DisplayName', 'Vsatp');
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes2, app.V_D(row,:), V_O_sat_neg, 'k--', 'DisplayName', 'Vsatn');
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes, app.V_D(row, :), I_D_sat_pos, 'k--', 'DisplayName', 'Isatp');
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes, app.V_D(row, :), I_D_sat_neg, 'k--', 'DisplayName', 'Isatn');
                        end
                    else
                        for col=1:size(app.V_D, 2)
                            if app.REditField.Value < 5000
                                isatp = 2e-3 - app.V_N(:, col)/app.REditField.Value;
                                isatn = 2e-3 + app.V_N(:, col)/app.REditField.Value;
                                vsatp = app.V_N(:, col) + 2e-3 * app.REditField.Value;
                                vsatn = app.V_N(:, col) - 2e-3 * app.REditField.Value;
                            else
                                vsatp = 10;
                                vsatn = -10;
                                isatp = 10/app.REditField.Value;
                                isatn = isatp;
                            end
                            V_O_sat_pos = linspace(vsatp, vsatp, length(app.V_D));
                            V_O_sat_neg = linspace(vsatn, vsatn, length(app.V_D));
                            I_D_sat_pos = linspace(isatp, isatp, length(app.V_D));
                            I_D_sat_neg = linspace(-isatn, -isatn, length(app.V_D));
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes2, app.V_D(:, col), V_O_sat_pos, 'k--', 'DisplayName', 'Vsatp');
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes2, app.V_D(:, col), V_O_sat_neg, 'k--', 'DisplayName', 'Vsatn');
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes, app.V_D(:, col), I_D_sat_pos, 'k--', 'DisplayName', 'Isatp');
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes, app.V_D(:, col), I_D_sat_neg, 'k--', 'DisplayName', 'Isatn');
                        end
                    end
                else
                    if app.REditField.Value < 5000
                        isat = 2e-3;
                        vsat = isat * app.REditField.Value;
                    else
                        vsat = 10;
                        isat = vsat/app.REditField.Value;
                    end
                    V_O_sat_pos = linspace(vsat, vsat, length(app.V_D));
                    V_O_sat_neg = linspace(-vsat, -vsat, length(app.V_D));
                    I_D_sat_pos = linspace(isat, isat, length(app.V_D));
                    I_D_sat_neg = linspace(-isat, -isat, length(app.V_D));
                    app.SatLinesHandles(1) = plot(app.UIAxes2, app.V_D, V_O_sat_pos, 'k--', 'DisplayName', 'Vsatp');
                    app.SatLinesHandles(2) = plot(app.UIAxes2, app.V_D, V_O_sat_neg, 'k--', 'DisplayName', 'Vsatn');
                    app.SatLinesHandles(3) = plot(app.UIAxes, app.V_D, I_D_sat_pos, 'k--', 'DisplayName', 'Isatp');
                    app.SatLinesHandles(4) = plot(app.UIAxes, app.V_D, I_D_sat_neg, 'k--', 'DisplayName', 'Isatn');
                end

                % Actualizar leyenda
                legend(app.UIAxes, 'show')
                legend(app.UIAxes2, 'show')

                % Cambiar el estado
                app.SatLinesVisible = true;
                app.ShowSatLimitsButton.Text = 'Hide Sat Limits'; % Cambiar texto del botón
            else
                % Ocultar las líneas de saturación
                delete(app.SatLinesHandles); % Eliminar las líneas de la gráfica
                app.SatLinesHandles = []; % Limpiar los handles
        
                % Cambiar el estado
                app.SatLinesVisible = false;
                app.ShowSatLimitsButton.Text = 'Show Sat Limits'; % Cambiar texto del botón
            end
        end

        % Menu selected function: ExportDatatoWorkspaceMenu
        function ExportDatatoWorkspaceMenuSelected(app, event)
            % Obtener el objeto gráfico activo
            srcObject = app.UIAxes.Children; % Obtiene todos los objetos del eje
        
            % Iterar sobre los objetos del eje (por si hay varios gráficos)
            for i = 1:numel(srcObject)
                if strcmp(srcObject(i).Type, 'line') % Verificar si es una línea
                    % Extraer datos X e Y de la línea
                    xData = srcObject(i).XData;
                    yData = srcObject(i).YData;
                    
                    % Crear una matriz con dos columnas: X y Y
                    data = [xData(:), yData(:)]; % Asegurarse de que sean columnas
                    
                    % Obtener el nombre descriptivo (DisplayName)
                    graphName = srcObject(i).DisplayName;
                    
                    % Validar el nombre para que sea un nombre de variable válido
                    if isempty(graphName) % Si no hay DisplayName, usar un nombre genérico
                        graphName = sprintf('ExportedData_%d', i);
                    else
                        graphName = matlab.lang.makeValidName(graphName); % Asegurarse de que sea válido
                    end
                    
                    % Exportar al Workspace con el nombre descriptivo
                    assignin('base', graphName, data);
                end
            end
            
            % Notificación al usuario
            uialert(app.UIFigure, 'Data Exported to Workspace', 'Exportation completed');
        end

        % Menu selected function: ExportDatatoWorkspaceMenu_2
        function ExportDatatoWorkspaceMenu_2Selected(app, event)
            % Obtener el objeto gráfico activo
            srcObject = app.UIAxes2.Children; % Obtiene todos los objetos del eje
        
            % Iterar sobre los objetos del eje (por si hay varios gráficos)
            for i = 1:numel(srcObject)
                if strcmp(srcObject(i).Type, 'line') % Verificar si es una línea
                    % Extraer datos X e Y de la línea
                    xData = srcObject(i).XData;
                    yData = srcObject(i).YData;
                    
                    % Crear una matriz con dos columnas: X y Y
                    data = [xData(:), yData(:)]; % Asegurarse de que sean columnas
                    
                    % Obtener el nombre descriptivo (DisplayName)
                    graphName = srcObject(i).DisplayName;
                    
                    % Validar el nombre para que sea un nombre de variable válido
                    if isempty(graphName) % Si no hay DisplayName, usar un nombre genérico
                        graphName = sprintf('ExportedData_%d', i);
                    else
                        graphName = matlab.lang.makeValidName(graphName); % Asegurarse de que sea válido
                    end
                    
                    % Exportar al Workspace con el nombre descriptivo
                    assignin('base', graphName, data);
                end
            end
            
            % Notificación al usuario
            uialert(app.UIFigure, 'Data Exported to Workspace', 'Exportation completed');
        end

        % Menu selected function: DiodeIVCharacteristicMenu
        function DiodeIVCharacteristicMenuSelected(app, event)
            % Open a save dialog box
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.fig'}, 'Save Plot As');
            
            % If user cancels, do nothing
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % Build the full file name
            fullFileName = fullfile(path, file);
            
            % Get the file extension
            [~, ~, ext] = fileparts(fullFileName);
            
            % Save the current axes as the selected format
            switch ext
                case '.png'
                    exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
                case '.jpg'
                    exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
                case '.pdf'
                    exportgraphics(app.UIAxes, fullFileName);
                case '.fig'
                    % Save as a MATLAB figure
                    fig = figure('Visible', 'off'); % Create an invisible figure
                    axCopy = copyobj(app.UIAxes, fig); % Copy the axes to the new figure
                    set(axCopy, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]); % Resize
                    savefig(fig, fullFileName); % Save the figure
                    close(fig); % Close the temporary figure
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
            end
        end

        % Menu selected function: OutputVoltageMenu
        function OutputVoltageMenuSelected(app, event)
            % Open a save dialog box
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.fig'}, 'Save Plot As');
            
            % If user cancels, do nothing
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % Build the full file name
            fullFileName = fullfile(path, file);
            
            % Get the file extension
            [~, ~, ext] = fileparts(fullFileName);
            
            % Save the current axes as the selected format
            switch ext
                case '.png'
                    exportgraphics(app.UIAxes2, fullFileName, 'Resolution', 300);
                case '.jpg'
                    exportgraphics(app.UIAxes2, fullFileName, 'Resolution', 300);
                case '.pdf'
                    exportgraphics(app.UIAxes2, fullFileName);
                case '.fig'
                    % Save as a MATLAB figure
                    fig = figure('Visible', 'off'); % Create an invisible figure
                    axCopy = copyobj(app.UIAxes2, fig); % Copy the axes to the new figure
                    set(axCopy, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]); % Resize
                    savefig(fig, fullFileName); % Save the figure
                    close(fig); % Close the temporary figure
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
            end
        end

        % Menu selected function: DiodeIVCharacteristicMenu_2
        function DiodeIVCharacteristicMenu_2Selected(app, event)
            % Obtener el objeto gráfico activo
            srcObject = app.UIAxes.Children; % Obtiene todos los objetos del eje
        
            % Iterar sobre los objetos del eje (por si hay varios gráficos)
            for i = 1:numel(srcObject)
                if strcmp(srcObject(i).Type, 'line') % Verificar si es una línea
                    % Extraer datos X e Y de la línea
                    xData = srcObject(i).XData;
                    yData = srcObject(i).YData;
                    
                    % Crear una matriz con dos columnas: X y Y
                    data = [xData(:), yData(:)]; % Asegurarse de que sean columnas
                    
                    % Obtener el nombre descriptivo (DisplayName)
                    graphName = srcObject(i).DisplayName;
                    
                    % Validar el nombre para que sea un nombre de variable válido
                    if isempty(graphName) % Si no hay DisplayName, usar un nombre genérico
                        graphName = sprintf('ExportedData_%d', i);
                    else
                        graphName = matlab.lang.makeValidName(graphName); % Asegurarse de que sea válido
                    end
                    
                    % Exportar al Workspace con el nombre descriptivo
                    assignin('base', graphName, data);
                end
            end
            
            % Notificación al usuario
            uialert(app.UIFigure, 'Data Exported to Workspace', 'Exportation completed');
        end

        % Menu selected function: OutputVoltageMenu_2
        function OutputVoltageMenu_2Selected(app, event)
            % Obtener el objeto gráfico activo
            srcObject = app.UIAxes2.Children; % Obtiene todos los objetos del eje
        
            % Iterar sobre los objetos del eje (por si hay varios gráficos)
            for i = 1:numel(srcObject)
                if strcmp(srcObject(i).Type, 'line') % Verificar si es una línea
                    % Extraer datos X e Y de la línea
                    xData = srcObject(i).XData;
                    yData = srcObject(i).YData;
                    
                    % Crear una matriz con dos columnas: X y Y
                    data = [xData(:), yData(:)]; % Asegurarse de que sean columnas
                    
                    % Obtener el nombre descriptivo (DisplayName)
                    graphName = srcObject(i).DisplayName;
                    
                    % Validar el nombre para que sea un nombre de variable válido
                    if isempty(graphName) % Si no hay DisplayName, usar un nombre genérico
                        graphName = sprintf('ExportedData_%d', i);
                    else
                        graphName = matlab.lang.makeValidName(graphName); % Asegurarse de que sea válido
                    end
                    
                    % Exportar al Workspace con el nombre descriptivo
                    assignin('base', graphName, data);
                end
            end
            
            % Notificación al usuario
            uialert(app.UIFigure, 'Data Exported to Workspace', 'Exportation completed');
        end

        % Button pushed function: CurvefittingButton
        function CurvefittingButtonPushed(app, event)
            if ~app.HoldonCheckBox.Value
                uialert(app.UIFigure, ...
                    'Make sure the Hold On checkbox is activated.', ...
                    'Warning');
                return; % Salir del callback si la condición no se cumple
            elseif isempty(app.V_D) || ~isprop(app, 'V_D')
                uialert(app.UIFigure, ...
                    'Represent a curve in order to be able to fit a curve.', ...
                    'Error');
                return;
            elseif app.SweepCheckBox.Value
                uialert(app.UIFigure, ...
                    'Curve fitting is not available for sweep data.', ...
                    'Error');
                return
            end

            app.CurveFitDialog = CurveFittingDialogDiode();
            app.CurveFitDialog.MainDiodeApp = app;  % Asigna la referencia a la MainApp
            app.CurveFitDialog.UIFigure.Visible = 'on';
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            % Abre el diálogo para seleccionar un archivo .mat
            [file, path] = uigetfile('*.mat', 'Select a MAT-file');
            if isequal(file, 0) % Si el usuario cancela
                return;
            end
            fullPath = fullfile(path, file);
            
            % Carga las variables del archivo .mat
            vars = whos('-file', fullPath); % Obtiene información sobre las variables en el archivo
            if isempty(vars)
                uialert(app.UIFigure, 'The selected MAT-file is empty.', 'No Data');
                return;
            end
            
            % Muestra un cuadro de diálogo para seleccionar variables
            varNames = {vars.name};
            [indx, tf] = listdlg('ListString', varNames, ...
                             'SelectionMode', 'multiple', ...
                             'PromptString', 'Select variables to load', ...
                             'Name', 'Load Variables');
            if tf == 0 % Si el usuario cancela
                return;
            end
            
            % Cargar las variables seleccionadas al workspace
            selectedVars = varNames(indx);
            loadedData = load(fullPath, selectedVars{:});
            
            % Guardar las variables cargadas en una propiedad del app (opcional)
            app.ILoadedData = loadedData; % Crear una propiedad `LoadedData` en el app

            % Extraer datos de la estructura cargada
            data = app.ILoadedData; % Supongamos que contiene las variables seleccionadas
            fieldNames = fieldnames(data); % Obtener los nombres de las variables cargadas
            
            matrixData = data.(fieldNames{1}); % Supongamos que es la primera variable
            x = matrixData(:, 1); % Columna 1: Eje X
            y = matrixData(:, 2); % Columna 2: Eje Y

            plot(app.UIAxes, x, y, 'DisplayName', 'ILoaded')
            legend(app.UIAxes, 'show')
        end

        % Menu selected function: LoadDataMenu_2
        function LoadDataMenu_2Selected(app, event)
            % Abre el diálogo para seleccionar un archivo .mat
            [file, path] = uigetfile('*.mat', 'Select a MAT-file');
            if isequal(file, 0) % Si el usuario cancela
                return;
            end
            fullPath = fullfile(path, file);
            
            % Carga las variables del archivo .mat
            vars = whos('-file', fullPath); % Obtiene información sobre las variables en el archivo
            if isempty(vars)
                uialert(app.UIFigure, 'The selected MAT-file is empty.', 'No Data');
                return;
            end
            
            % Muestra un cuadro de diálogo para seleccionar variables
            varNames = {vars.name};
            [indx, tf] = listdlg('ListString', varNames, ...
                             'SelectionMode', 'multiple', ...
                             'PromptString', 'Select variables to load', ...
                             'Name', 'Load Variables');
            if tf == 0 % Si el usuario cancela
                return;
            end
            
            % Cargar las variables seleccionadas al workspace
            selectedVars = varNames(indx);
            loadedData = load(fullPath, selectedVars{:});
            
            % Guardar las variables cargadas en una propiedad del app (opcional)
            app.VLoadedData = loadedData; % Crear una propiedad `LoadedData` en el app

            % Extraer datos de la estructura cargada
            data = app.VLoadedData; % Supongamos que contiene las variables seleccionadas
            fieldNames = fieldnames(data); % Obtener los nombres de las variables cargadas
            
            matrixData = data.(fieldNames{1}); % Supongamos que es la primera variable
            x = matrixData(:, 1); % Columna 1: Eje X
            y = matrixData(:, 2); % Columna 2: Eje Y

            plot(app.UIAxes2, x, y, 'DisplayName', 'VLoaded')
            legend(app.UIAxes2, 'show')
        end

        % Menu selected function: DiodeIVCharacteristicMenu_3
        function DiodeIVCharacteristicMenu_3Selected(app, event)
            % Abre el diálogo para seleccionar un archivo .mat
            [file, path] = uigetfile('*.mat', 'Select a MAT-file');
            if isequal(file, 0) % Si el usuario cancela
                return;
            end
            fullPath = fullfile(path, file);
            
            % Carga las variables del archivo .mat
            vars = whos('-file', fullPath); % Obtiene información sobre las variables en el archivo
            if isempty(vars)
                uialert(app.UIFigure, 'The selected MAT-file is empty.', 'No Data');
                return;
            end
            
            % Muestra un cuadro de diálogo para seleccionar variables
            varNames = {vars.name};
            [indx, tf] = listdlg('ListString', varNames, ...
                             'SelectionMode', 'multiple', ...
                             'PromptString', 'Select variables to load', ...
                             'Name', 'Load Variables');
            if tf == 0 % Si el usuario cancela
                return;
            end
            
            % Cargar las variables seleccionadas al workspace
            selectedVars = varNames(indx);
            loadedData = load(fullPath, selectedVars{:});
            
            % Guardar las variables cargadas en una propiedad del app (opcional)
            app.ILoadedData = loadedData; % Crear una propiedad `LoadedData` en el app

            % Extraer datos de la estructura cargada
            data = app.ILoadedData; % Supongamos que contiene las variables seleccionadas
            fieldNames = fieldnames(data); % Obtener los nombres de las variables cargadas
            
            matrixData = data.(fieldNames{1}); % Supongamos que es la primera variable
            x = matrixData(:, 1); % Columna 1: Eje X
            y = matrixData(:, 2); % Columna 2: Eje Y

            plot(app.UIAxes, x, y, 'DisplayName', 'ILoaded')
            legend(app.UIAxes, 'show')
        end

        % Menu selected function: OutputVoltageMenu_3
        function OutputVoltageMenu_3Selected(app, event)
            % Abre el diálogo para seleccionar un archivo .mat
            [file, path] = uigetfile('*.mat', 'Select a MAT-file');
            if isequal(file, 0) % Si el usuario cancela
                return;
            end
            fullPath = fullfile(path, file);
            
            % Carga las variables del archivo .mat
            vars = whos('-file', fullPath); % Obtiene información sobre las variables en el archivo
            if isempty(vars)
                uialert(app.UIFigure, 'The selected MAT-file is empty.', 'No Data');
                return;
            end
            
            % Muestra un cuadro de diálogo para seleccionar variables
            varNames = {vars.name};
            [indx, tf] = listdlg('ListString', varNames, ...
                             'SelectionMode', 'multiple', ...
                             'PromptString', 'Select variables to load', ...
                             'Name', 'Load Variables');
            if tf == 0 % Si el usuario cancela
                return;
            end
            
            % Cargar las variables seleccionadas al workspace
            selectedVars = varNames(indx);
            loadedData = load(fullPath, selectedVars{:});
            
            % Guardar las variables cargadas en una propiedad del app (opcional)
            app.VLoadedData = loadedData; % Crear una propiedad `LoadedData` en el app

            % Extraer datos de la estructura cargada
            data = app.VLoadedData; % Supongamos que contiene las variables seleccionadas
            fieldNames = fieldnames(data); % Obtener los nombres de las variables cargadas
            
            matrixData = data.(fieldNames{1}); % Supongamos que es la primera variable
            x = matrixData(:, 1); % Columna 1: Eje X
            y = matrixData(:, 2); % Columna 2: Eje Y

            plot(app.UIAxes2, x, y, 'DisplayName', 'VLoaded')
            legend(app.UIAxes2, 'show')
        end

        % Menu selected function: RenameMenu
        function RenameMenuSelected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to rename.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'single', ...
                                 'PromptString', 'Select a curve to rename:', ...
                                 'Name', 'Rename Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end
            
            % Solicitar el nuevo nombre
            newName = inputdlg('Enter a new name for the curve:', 'Rename Curve', [1 50]);
            if isempty(newName) || isempty(newName{1}) % Si se cancela o no se ingresa un nombre
                return;
            end
            
            % Asignar el nuevo nombre a la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.DisplayName = newName{1};
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes, 'show');
        end

        % Menu selected function: RenameMenu_2
        function RenameMenu_2Selected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes2, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to rename.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'single', ...
                                 'PromptString', 'Select a curve to rename:', ...
                                 'Name', 'Rename Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end
            
            % Solicitar el nuevo nombre
            newName = inputdlg('Enter a new name for the curve:', 'Rename Curve', [1 50]);
            if isempty(newName) || isempty(newName{1}) % Si se cancela o no se ingresa un nombre
                return;
            end
            
            % Asignar el nuevo nombre a la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.DisplayName = newName{1};
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes2, 'show');
        end

        % Menu selected function: HideCurveMenu_2
        function HideCurveMenu_2Selected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to hide.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'multiple', ...
                                 'PromptString', 'Select a curve to hide:', ...
                                 'Name', 'Hide Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end

            % Ocultar la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.Visible = 'off';
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes, 'show');
        end

        % Menu selected function: HideCurveMenu
        function HideCurveMenuSelected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes2, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to hide.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'multiple', ...
                                 'PromptString', 'Select a curve to hide:', ...
                                 'Name', 'Hide Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end

            % Ocultar la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.Visible = 'off';
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes2, 'show');
        end

        % Menu selected function: ShowCurveMenu_2
        function ShowCurveMenu_2Selected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to show.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'multiple', ...
                                 'PromptString', 'Select a curve to show:', ...
                                 'Name', 'Show Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end

            % Ocultar la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.Visible = 'on';
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes, 'show');
        end

        % Menu selected function: ShowCurveMenu
        function ShowCurveMenuSelected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes2, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to show.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'multiple', ...
                                 'PromptString', 'Select a curve to show:', ...
                                 'Name', 'Hide Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end

            % Ocultar la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.Visible = 'on';
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes2, 'show');
        end

        % Menu selected function: ExportConfigtoWorkspaceMenu
        function ExportConfigtoWorkspaceMenuSelected(app, event)
            fields = {
                app.VPminEditFieldLabel.Text, app.VPminEditField.Value;
                app.VPmaxEditFieldLabel.Text, app.VPmaxEditField.Value;
                app.VPstepsEditFieldLabel.Text, app.VPstepsEditField.Value;
                app.VNminEditFieldLabel.Text, app.VNminEditField.Value;
                app.VNmaxEditFieldLabel.Text, app.VNmaxEditField.Value;
                app.VNstepsEditFieldLabel.Text, app.VNstepsEditField.Value;
                app.REditFieldLabel.Text, app.REditField.Value;
                app.NAveragingEditFieldLabel.Text, app.NAveragingEditField.Value;
                app.IOffEditFieldLabel.Text, app.IOffEditField.Value;
            };
        
            % Exportar cada campo al workspace
            for i = 1:size(fields, 1)
                fieldName = fields{i, 1};
                fieldValue = fields{i, 2};
                assignin('base', matlab.lang.makeValidName(fieldName), fieldValue);
            end
        
            uialert(app.UIFigure, 'Configuration exported to workspace.', 'Exportation Completed')
        end

        % Button pushed function: FullScaleRangeButton
        function FullScaleRangeButtonPushed(app, event)
            if app.SweepCheckBox.Value
                % Si el sweep está activado, buscar el máximo y mínimo considerando todas las curvas
                vomax = max(app.V_O_mean(:)); % Considerando todos los elementos
                vomin = min(app.V_O_mean(:)); % Considerando todos los elementos
            else
                % Si no hay sweep, calcularlo normalmente
                vomax = max(app.V_O_mean);
                vomin = min(app.V_O_mean);
            end
            
            fsr = vomax - vomin; % Calcular el rango
            fsrpercent = (fsr / 20) * 100; % Calcular el porcentaje
            
            % Crear el mensaje para mostrar
            mensaje = sprintf('The FSR is: %.2f V, or %.2f%%', fsr, fsrpercent);
            
            % Mostrar el mensaje en la interfaz
            uialert(app.UIFigure, mensaje, 'Full Scale Range (FSR)');
        end

        % Value changed function: FastModeCheckBox
        function FastModeCheckBoxValueChanged(app, event)
            app.VPstepsEditField.Value = 1000;
            app.VNstepsEditField.Value = 1000;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveasMenu_3 = uimenu(app.FileMenu);
            app.SaveasMenu_3.Text = 'Save as';
            app.DiodeIVCharacteristicMenu = uimenu(app.SaveasMenu_3);
            app.DiodeIVCharacteristicMenu.MenuSelectedFcn = createCallbackFcn(app, @DiodeIVCharacteristicMenuSelected, true);
            app.DiodeIVCharacteristicMenu.Text = 'Diode I-V Characteristic';
            app.OutputVoltageMenu = uimenu(app.SaveasMenu_3);
            app.OutputVoltageMenu.MenuSelectedFcn = createCallbackFcn(app, @OutputVoltageMenuSelected, true);
            app.OutputVoltageMenu.Text = 'Output Voltage';
            app.ExporttoWorkspaceMenu = uimenu(app.FileMenu);
            app.ExporttoWorkspaceMenu.Text = 'Export to Workspace';
            app.DiodeIVCharacteristicMenu_2 = uimenu(app.ExporttoWorkspaceMenu);
            app.DiodeIVCharacteristicMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DiodeIVCharacteristicMenu_2Selected, true);
            app.DiodeIVCharacteristicMenu_2.Text = 'Diode I-V Characteristic';
            app.OutputVoltageMenu_2 = uimenu(app.ExporttoWorkspaceMenu);
            app.OutputVoltageMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OutputVoltageMenu_2Selected, true);
            app.OutputVoltageMenu_2.Text = 'Output Voltage';
            app.LoadDataMenu_3 = uimenu(app.FileMenu);
            app.LoadDataMenu_3.Text = 'Load Data';
            app.DiodeIVCharacteristicMenu_3 = uimenu(app.LoadDataMenu_3);
            app.DiodeIVCharacteristicMenu_3.MenuSelectedFcn = createCallbackFcn(app, @DiodeIVCharacteristicMenu_3Selected, true);
            app.DiodeIVCharacteristicMenu_3.Text = 'Diode I-V Characteristic';
            app.OutputVoltageMenu_3 = uimenu(app.LoadDataMenu_3);
            app.OutputVoltageMenu_3.MenuSelectedFcn = createCallbackFcn(app, @OutputVoltageMenu_3Selected, true);
            app.OutputVoltageMenu_3.Text = 'Output Voltage';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.MenuSelectedFcn = createCallbackFcn(app, @HelpMenu_2Selected, true);
            app.HelpMenu_2.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Diode I-V Characteristic')
            xlabel(app.UIAxes, 'V_D [V]')
            ylabel(app.UIAxes, 'I_D [A]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Output Voltage')
            xlabel(app.UIAxes2, 'V_D [V]')
            ylabel(app.UIAxes2, 'V_O [V]')
            zlabel(app.UIAxes2, 'Z')
            app.MeasurementPropertiesPanel = uipanel(app.UIFigure);
            app.MeasurementPropertiesPanel.Title = 'Measurement Properties';
            app.OhmLabel = uilabel(app.MeasurementPropertiesPanel);
            app.OhmLabel.Text = 'Ohm';
            app.ALabel = uilabel(app.MeasurementPropertiesPanel);
            app.ALabel.Text = 'A';
            app.InputSignalPropertiesPanel = uipanel(app.UIFigure);
            app.InputSignalPropertiesPanel.Title = 'Input Signal Properties';
            app.VLabel = uilabel(app.InputSignalPropertiesPanel);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.InputSignalPropertiesPanel);
            app.VLabel_2.Text = 'V';
            app.VLabel_3 = uilabel(app.InputSignalPropertiesPanel);
            app.VLabel_3.Text = 'V';
            app.VLabel_4 = uilabel(app.InputSignalPropertiesPanel);
            app.VLabel_4.Text = 'V';
            app.SweepCheckBox = uicheckbox(app.InputSignalPropertiesPanel);
            app.SweepCheckBox.Text = 'Sweep';
            app.NImyDAQPanel = uipanel(app.UIFigure);
            app.NImyDAQPanel.Title = 'NI myDAQ';
            app.DeviceIDEditFieldLabel = uilabel(app.NImyDAQPanel);
            app.DeviceIDEditFieldLabel.Text = 'Device ID';
            app.DeviceIDEditField = uieditfield(app.NImyDAQPanel, 'text');
            app.HoldonCheckBox = uicheckbox(app.UIFigure);
            app.HoldonCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldonCheckBoxValueChanged, true);
            app.HoldonCheckBox.Text = 'Hold on';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.LinButton = uiradiobutton(app.ButtonGroup);
            app.LinButton.Text = 'Lin';
            app.LinButton.Value = true;
            app.LogButton = uiradiobutton(app.ButtonGroup);
            app.LogButton.Text = 'Log';
            app.NImyDAQSwitch = uiswitch(app.UIFigure, 'slider');
            app.NImyDAQSwitch.ValueChangedFcn = createCallbackFcn(app, @NImyDAQSwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.VPminEditFieldLabel = uilabel(app.UIFigure);
            app.VPminEditFieldLabel.Text = 'VPmin';
            app.VPminEditField = uieditfield(app.UIFigure, 'numeric');
            app.VPmaxEditFieldLabel = uilabel(app.UIFigure);
            app.VPmaxEditFieldLabel.Text = 'VPmax';
            app.VPmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.VPstepsEditFieldLabel = uilabel(app.UIFigure);
            app.VPstepsEditFieldLabel.Text = 'VPsteps';
            app.VPstepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VNminEditFieldLabel = uilabel(app.UIFigure);
            app.VNminEditFieldLabel.Text = 'VNmin';
            app.VNminEditField = uieditfield(app.UIFigure, 'numeric');
            app.VNmaxEditFieldLabel = uilabel(app.UIFigure);
            app.VNmaxEditFieldLabel.Text = 'VNmax';
            app.VNmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.VNstepsEditFieldLabel = uilabel(app.UIFigure);
            app.VNstepsEditFieldLabel.Text = 'VNsteps';
            app.VNstepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.NAveragingEditFieldLabel = uilabel(app.UIFigure);
            app.NAveragingEditFieldLabel.Text = 'NAveraging';
            app.NAveragingEditField = uieditfield(app.UIFigure, 'numeric');
            app.IOffEditFieldLabel = uilabel(app.UIFigure);
            app.IOffEditFieldLabel.Text = 'IOff';
            app.IOffEditField = uieditfield(app.UIFigure, 'numeric');
            app.NImyDAQPortsPanel = uipanel(app.UIFigure);
            app.NImyDAQPortsPanel.Title = 'NI myDAQ Ports';
            app.VPDropDownLabel = uilabel(app.NImyDAQPortsPanel);
            app.VPDropDownLabel.Text = 'VP';
            app.VPDropDown = uidropdown(app.NImyDAQPortsPanel);
            app.VPDropDown.Items = {'''ao0''', '''ao1'''};
            app.VPDropDown.Value = '''ao0''';
            app.VNDropDownLabel = uilabel(app.NImyDAQPortsPanel);
            app.VNDropDownLabel.Text = 'VN';
            app.VNDropDown = uidropdown(app.NImyDAQPortsPanel);
            app.VNDropDown.Items = {'''ao0''', '''ao1'''};
            app.VNDropDown.Value = '''ao1''';
            app.VODropDownLabel = uilabel(app.NImyDAQPortsPanel);
            app.VODropDownLabel.Text = 'VO';
            app.VODropDown = uidropdown(app.NImyDAQPortsPanel);
            app.VODropDown.Items = {'''ai0''', '''ai1'''};
            app.VODropDown.Value = '''ai1''';
            app.MeasurementStatusPanel = uipanel(app.UIFigure);
            app.MeasurementStatusPanel.Title = 'Measurement Status';
            app.RunMeasurementsButton = uibutton(app.MeasurementStatusPanel, 'push');
            app.RunMeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @RunMeasurementsButtonPushed, true);
            app.RunMeasurementsButton.Text = 'Run Measurements';
            app.StopSimulationButton = uibutton(app.MeasurementStatusPanel, 'push');
            app.StopSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimulationButtonPushed, true);
            app.StopSimulationButton.Text = 'Stop Simulation';
            app.MeasurementStatusGaugeLabel = uilabel(app.MeasurementStatusPanel);
            app.MeasurementStatusGaugeLabel.Text = 'Measurement Status (%)';
            app.MeasurementStatusGauge = uigauge(app.MeasurementStatusPanel, 'linear');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'US-marca-principal.png');
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.MeasurementOptionsPanel = uipanel(app.UIFigure);
            app.MeasurementOptionsPanel.Title = 'Measurement Options';
            app.FastModeCheckBox = uicheckbox(app.MeasurementOptionsPanel);
            app.FastModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @FastModeCheckBoxValueChanged, true);
            app.FastModeCheckBox.Text = 'Fast Mode';
            app.ShowSatLimitsButton = uibutton(app.MeasurementOptionsPanel, 'push');
            app.ShowSatLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSatLimitsButtonPushed, true);
            app.ShowSatLimitsButton.Text = 'Show Sat Limits';
            app.CurvefittingButton = uibutton(app.MeasurementOptionsPanel, 'push');
            app.CurvefittingButton.ButtonPushedFcn = createCallbackFcn(app, @CurvefittingButtonPushed, true);
            app.CurvefittingButton.Text = 'Curve fitting';
            app.FullScaleRangeButton = uibutton(app.MeasurementOptionsPanel, 'push');
            app.FullScaleRangeButton.ButtonPushedFcn = createCallbackFcn(app, @FullScaleRangeButtonPushed, true);
            app.FullScaleRangeButton.Text = 'Full Scale Range';
            app.ResetFiguresButton = uibutton(app.UIFigure, 'push');
            app.ResetFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFiguresButtonPushed, true);
            app.ResetFiguresButton.Text = 'Reset Figures';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenu.Text = 'Rename';
            app.SaveasMenu = uimenu(app.ContextMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as';
            app.ExportDatatoWorkspaceMenu = uimenu(app.ContextMenu);
            app.ExportDatatoWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDatatoWorkspaceMenuSelected, true);
            app.ExportDatatoWorkspaceMenu.Text = 'Export Data to Workspace';
            app.LoadDataMenu = uimenu(app.ContextMenu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Text = 'Load Data';
            app.HideCurveMenu_2 = uimenu(app.ContextMenu);
            app.HideCurveMenu_2.MenuSelectedFcn = createCallbackFcn(app, @HideCurveMenu_2Selected, true);
            app.HideCurveMenu_2.Text = 'Hide Curve';
            app.ShowCurveMenu_2 = uimenu(app.ContextMenu);
            app.ShowCurveMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ShowCurveMenu_2Selected, true);
            app.ShowCurveMenu_2.Text = 'Show Curve';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.RenameMenu_2 = uimenu(app.ContextMenu2);
            app.RenameMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RenameMenu_2Selected, true);
            app.RenameMenu_2.Text = 'Rename';
            app.SaveasMenu_2 = uimenu(app.ContextMenu2);
            app.SaveasMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenu_2Selected, true);
            app.SaveasMenu_2.Text = 'Save as';
            app.ExportDatatoWorkspaceMenu_2 = uimenu(app.ContextMenu2);
            app.ExportDatatoWorkspaceMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ExportDatatoWorkspaceMenu_2Selected, true);
            app.ExportDatatoWorkspaceMenu_2.Text = 'Export Data to Workspace';
            app.LoadDataMenu_2 = uimenu(app.ContextMenu2);
            app.LoadDataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenu_2Selected, true);
            app.LoadDataMenu_2.Text = 'Load Data';
            app.HideCurveMenu = uimenu(app.ContextMenu2);
            app.HideCurveMenu.MenuSelectedFcn = createCallbackFcn(app, @HideCurveMenuSelected, true);
            app.HideCurveMenu.Text = 'Hide Curve';
            app.ShowCurveMenu = uimenu(app.ContextMenu2);
            app.ShowCurveMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowCurveMenuSelected, true);
            app.ShowCurveMenu.Text = 'Show Curve';
            % Assign app.ContextMenu2
            app.UIAxes2.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.ExportConfigtoWorkspaceMenu = uimenu(app.ContextMenu3);
            app.ExportConfigtoWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportConfigtoWorkspaceMenuSelected, true);
            app.ExportConfigtoWorkspaceMenu.Text = 'Export Config to Workspace';
            app.HelpMenu_3 = uimenu(app.ContextMenu3);
            app.HelpMenu_3.MenuSelectedFcn = createCallbackFcn(app, @HelpMenu_3Selected, true);
            app.HelpMenu_3.Text = 'Help';
            app.DocumentationMenu_2 = uimenu(app.ContextMenu3);
            app.DocumentationMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenu_2Selected, true);
            app.DocumentationMenu_2.Text = 'Documentation';
            % Assign app.ContextMenu3
            app.UIFigure.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.ExportFitParameterstoWorkspaceMenu = uimenu(app.ContextMenu4);
            app.ExportFitParameterstoWorkspaceMenu.Text = 'Export Fit Parameters to Workspace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiodeCharacteristics

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end