classdef HelpDiodeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        SchematicTab                 matlab.ui.container.Tab
        Image3                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        SCHEMATICOFTHEDIODECIRCUITIMPLEMENTEDINNIMYDAQLabel  matlab.ui.control.Label
        OpAmpPinOutTab               matlab.ui.container.Tab
        Image4                       matlab.ui.control.Image
        PINOUTOFTHE741OPAMPIMPLEMENTEDINTHECIRCUITLabel  matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        FunctionalitiesoftheAppTab   matlab.ui.container.Tab
        TextArea                     matlab.ui.control.TextArea
        Label                        matlab.ui.control.Label
        UsersGuideTab                matlab.ui.container.Tab
        UsersGuidePanel              matlab.ui.container.Panel
        IOffEditField                matlab.ui.control.NumericEditField
        IOffEditFieldLabel           matlab.ui.control.Label
        NAveragingEditField          matlab.ui.control.NumericEditField
        NAveragingEditFieldLabel     matlab.ui.control.Label
        REditField                   matlab.ui.control.NumericEditField
        REditFieldLabel              matlab.ui.control.Label
        MeasurementPropertiesPanel   matlab.ui.container.Panel
        TextArea_8                   matlab.ui.control.TextArea
        CurvefittingPanel_2          matlab.ui.container.Panel
        GetresiduesButton            matlab.ui.control.Button
        ErrorcEditField_2            matlab.ui.control.NumericEditField
        ErrorcEditField_2Label       matlab.ui.control.Label
        cEditField_2                 matlab.ui.control.NumericEditField
        cEditField_2Label            matlab.ui.control.Label
        ErrorfEditField              matlab.ui.control.NumericEditField
        ErrorfEditFieldLabel         matlab.ui.control.Label
        fEditField                   matlab.ui.control.NumericEditField
        fEditFieldLabel              matlab.ui.control.Label
        Image2_5                     matlab.ui.control.Image
        Image2_4                     matlab.ui.control.Image
        R22EditField                 matlab.ui.control.NumericEditField
        R22EditFieldLabel            matlab.ui.control.Label
        R2EditField_3                matlab.ui.control.NumericEditField
        R2EditField_3Label           matlab.ui.control.Label
        ErroreEditField              matlab.ui.control.NumericEditField
        ErroreEditFieldLabel         matlab.ui.control.Label
        eEditField                   matlab.ui.control.NumericEditField
        eEditFieldLabel              matlab.ui.control.Label
        ErrordEditField_2            matlab.ui.control.NumericEditField
        ErrordEditField_2Label       matlab.ui.control.Label
        dEditField_2                 matlab.ui.control.NumericEditField
        dEditField_2Label            matlab.ui.control.Label
        ErrorbEditField_2            matlab.ui.control.NumericEditField
        ErrorbEditField_2Label       matlab.ui.control.Label
        ErroraEditField_2            matlab.ui.control.NumericEditField
        ErroraEditField_2Label       matlab.ui.control.Label
        bEditField_2                 matlab.ui.control.NumericEditField
        bEditField_2Label            matlab.ui.control.Label
        aEditField_2                 matlab.ui.control.NumericEditField
        aEditField_2Label            matlab.ui.control.Label
        CalculateButton_2            matlab.ui.control.Button
        XButton_2                    matlab.ui.control.Button
        TextArea_10                  matlab.ui.control.TextArea
        TextArea_9                   matlab.ui.control.TextArea
        ResetFiguresButton           matlab.ui.control.Button
        ButtonGroup                  matlab.ui.container.ButtonGroup
        LogButton                    matlab.ui.control.RadioButton
        LinButton                    matlab.ui.control.RadioButton
        HoldonCheckBox_2             matlab.ui.control.CheckBox
        MeasurementOptionsPanel      matlab.ui.container.Panel
        FullScaleRangeButton         matlab.ui.control.Button
        CurvefittingButton           matlab.ui.control.Button
        ShowSatLimitsButton          matlab.ui.control.Button
        FastModeCheckBox             matlab.ui.control.CheckBox
        TextArea_7                   matlab.ui.control.TextArea
        TextArea_6                   matlab.ui.control.TextArea
        MeasurementStatusPanel       matlab.ui.container.Panel
        MeasurementStatusGauge       matlab.ui.control.LinearGauge
        MeasurementStatusGaugeLabel  matlab.ui.control.Label
        StopSimulationButton         matlab.ui.control.Button
        RunMeasurementsButton        matlab.ui.control.Button
        TextArea_5                   matlab.ui.control.TextArea
        TextArea_4                   matlab.ui.control.TextArea
        TextArea_3                   matlab.ui.control.TextArea
        TextArea_2                   matlab.ui.control.TextArea
        USERSGUIDEFORDIODECHARACTERISTICSINMATLABLabel  matlab.ui.control.Label
        NImyDAQPortsPanel            matlab.ui.container.Panel
        VODropDown                   matlab.ui.control.DropDown
        VODropDownLabel              matlab.ui.control.Label
        VNDropDown                   matlab.ui.control.DropDown
        VNDropDownLabel              matlab.ui.control.Label
        VPDropDown                   matlab.ui.control.DropDown
        VPDropDownLabel              matlab.ui.control.Label
        VNstepsEditField             matlab.ui.control.NumericEditField
        VNstepsEditFieldLabel        matlab.ui.control.Label
        VNmaxEditField               matlab.ui.control.NumericEditField
        VNmaxEditFieldLabel          matlab.ui.control.Label
        VNminEditField               matlab.ui.control.NumericEditField
        VNminEditFieldLabel          matlab.ui.control.Label
        VPstepsEditField             matlab.ui.control.NumericEditField
        VPstepsEditFieldLabel        matlab.ui.control.Label
        VPmaxEditField               matlab.ui.control.NumericEditField
        VPmaxEditFieldLabel          matlab.ui.control.Label
        VPminEditField               matlab.ui.control.NumericEditField
        VPminEditFieldLabel          matlab.ui.control.Label
        InputSignalPropertiesPanel   matlab.ui.container.Panel
        SweepCheckBox                matlab.ui.control.CheckBox
        LampLabel                    matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        NImyDAQSwitch                matlab.ui.control.Switch
        NImyDAQPanel                 matlab.ui.container.Panel
        DeviceIDEditField            matlab.ui.control.EditField
        DeviceIDEditFieldLabel       matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VPminEditField.Value = 0;
            app.VPmaxEditField.Value = 0.8;
            app.VPstepsEditField.Value = 1000;
            app.VNminEditField.Value = 0;
            app.VNmaxEditField.Value = 0;
            app.VNstepsEditField.Value = 1000;
            app.REditField.Value = 1000;
            app.NAveragingEditField.Value = 1;
            app.IOffEditField.Value = 0;
            app.DeviceIDEditField.Value = 'myDAQ1';
            Idata = load('IDiodeuserguide.mat');
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, Idata.data1(:,1), Idata.data1(:,2), 'b')
            plot(app.UIAxes, Idata.Ifit(:,1), Idata.Ifit(:,2))
            plot(app.UIAxes, Idata.Isatn(:,1), Idata.Isatn(:,2), 'k--')
            plot(app.UIAxes, Idata.Isatp(:,1), Idata.Isatp(:,2), 'k--')
            hold(app.UIAxes, 'off')
            legend(app.UIAxes, 'show', 'Location', 'best')
            Vdata = load('VDiodeuserguide.mat');
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, Vdata.data1(:,1), Vdata.data1(:,2), 'b')
            plot(app.UIAxes2, Vdata.Vfit(:,1), Vdata.Vfit(:,2))
            plot(app.UIAxes2, Vdata.Vsatn(:,1), Vdata.Vsatn(:,2), 'k--')
            plot(app.UIAxes2, Vdata.Vsatp(:,1), Vdata.Vsatp(:,2), 'k--')
            hold(app.UIAxes2, 'off')
            legend(app.UIAxes2, 'show', 'Location', 'best')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SchematicTab = uitab(app.TabGroup);
            app.SchematicTab.Title = 'Schematic';
            app.SCHEMATICOFTHEDIODECIRCUITIMPLEMENTEDINNIMYDAQLabel = uilabel(app.SchematicTab);
            app.SCHEMATICOFTHEDIODECIRCUITIMPLEMENTEDINNIMYDAQLabel.Text = 'SCHEMATIC OF THE DIODE CIRCUIT IMPLEMENTED IN NI MYDAQ';
            app.Image = uiimage(app.SchematicTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'DiodeCharacteristicsSchematic.svg');
            app.Image3 = uiimage(app.SchematicTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'US-marca-principal.png');
            app.OpAmpPinOutTab = uitab(app.TabGroup);
            app.OpAmpPinOutTab.Title = '741 OpAmp PinOut';
            app.Image2 = uiimage(app.OpAmpPinOutTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'DiodeCharacteristics741PinOut.svg');
            app.PINOUTOFTHE741OPAMPIMPLEMENTEDINTHECIRCUITLabel = uilabel(app.OpAmpPinOutTab);
            app.PINOUTOFTHE741OPAMPIMPLEMENTEDINTHECIRCUITLabel.Text = 'PINOUT OF THE 741 OPAMP IMPLEMENTED IN THE CIRCUIT';
            app.Image4 = uiimage(app.OpAmpPinOutTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'US-marca-principal.png');
            app.FunctionalitiesoftheAppTab = uitab(app.TabGroup);
            app.FunctionalitiesoftheAppTab.Title = 'Functionalities of the App';
            app.Label = uilabel(app.FunctionalitiesoftheAppTab);
            app.Label.Text = 'HELP FOR THE FUNCTIONALITIES OF THE DIODE CHARACTERISTICS APPLICATION';
            app.TextArea = uitextarea(app.FunctionalitiesoftheAppTab);
            app.TextArea.Value = {'Panels:'; ''; 'NI myDAQ: In this panel the user can make sure that the myDAQ is connected properly and MATLAB is detecting the hardware. In the case that the device is not connected or not connected properly, an error message will be displayed. The user can also introduce the ID name of the device, so it can be detected properly.'; ''; 'NI myDAQ Ports: In this panel the user can control the channels employed in myDAQ as inputs and outputs to define what ports are used as VP, VN and VO.'; ''; 'Input Signal Properties: In this panel the user can define the characteristics of the signal that will be sent to the circuit connected to the myDAQ, such as:'};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
            app.UsersGuideTab = uitab(app.TabGroup);
            app.UsersGuideTab.Title = 'User''s Guide';
            app.UsersGuidePanel = uipanel(app.UsersGuideTab);
            app.UsersGuidePanel.Title = 'User''s Guide';
            app.UIAxes = uiaxes(app.UsersGuidePanel);
            title(app.UIAxes, 'Diode I-V Characteristic')
            xlabel(app.UIAxes, 'V_D [V]')
            ylabel(app.UIAxes, 'I_D [A]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UsersGuidePanel);
            title(app.UIAxes2, 'Output Voltage')
            xlabel(app.UIAxes2, 'V_D [V]')
            ylabel(app.UIAxes2, 'V_O [V]')
            zlabel(app.UIAxes2, 'Z')
            app.NImyDAQPanel = uipanel(app.UsersGuidePanel);
            app.NImyDAQPanel.Title = 'NI myDAQ';
            app.DeviceIDEditFieldLabel = uilabel(app.NImyDAQPanel);
            app.DeviceIDEditFieldLabel.Text = 'Device ID';
            app.DeviceIDEditField = uieditfield(app.NImyDAQPanel, 'text');
            app.NImyDAQSwitch = uiswitch(app.UsersGuidePanel, 'slider');
            app.Lamp = uilamp(app.UsersGuidePanel);
            app.LampLabel = uilabel(app.UsersGuidePanel);
            app.LampLabel.Text = 'Lamp';
            app.InputSignalPropertiesPanel = uipanel(app.UsersGuidePanel);
            app.InputSignalPropertiesPanel.Title = 'Input Signal Properties';
            app.SweepCheckBox = uicheckbox(app.InputSignalPropertiesPanel);
            app.SweepCheckBox.Text = 'Sweep';
            app.VPminEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.VPminEditFieldLabel.Text = 'VPmin';
            app.VPminEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.VPmaxEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.VPmaxEditFieldLabel.Text = 'VPmax';
            app.VPmaxEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.VPstepsEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.VPstepsEditFieldLabel.Text = 'VPsteps';
            app.VPstepsEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.VNminEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.VNminEditFieldLabel.Text = 'VNmin';
            app.VNminEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.VNmaxEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.VNmaxEditFieldLabel.Text = 'VNmax';
            app.VNmaxEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.VNstepsEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.VNstepsEditFieldLabel.Text = 'VNsteps';
            app.VNstepsEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.NImyDAQPortsPanel = uipanel(app.UsersGuidePanel);
            app.NImyDAQPortsPanel.Title = 'NI myDAQ Ports';
            app.VPDropDownLabel = uilabel(app.NImyDAQPortsPanel);
            app.VPDropDownLabel.Text = 'VP';
            app.VPDropDown = uidropdown(app.NImyDAQPortsPanel);
            app.VPDropDown.Items = {'''ao0''', '''ao1'''};
            app.VPDropDown.Value = '''ao0''';
            app.VNDropDownLabel = uilabel(app.NImyDAQPortsPanel);
            app.VNDropDownLabel.Text = 'VN';
            app.VNDropDown = uidropdown(app.NImyDAQPortsPanel);
            app.VNDropDown.Items = {'''ao0''', '''ao1'''};
            app.VNDropDown.Value = '''ao1''';
            app.VODropDownLabel = uilabel(app.NImyDAQPortsPanel);
            app.VODropDownLabel.Text = 'VO';
            app.VODropDown = uidropdown(app.NImyDAQPortsPanel);
            app.VODropDown.Items = {'''ai0''', '''ai1'''};
            app.VODropDown.Value = '''ai1''';
            app.USERSGUIDEFORDIODECHARACTERISTICSINMATLABLabel = uilabel(app.UsersGuidePanel);
            app.USERSGUIDEFORDIODECHARACTERISTICSINMATLABLabel.Text = 'USER''S GUIDE FOR DIODE CHARACTERISTICS IN MATLAB';
            app.TextArea_2 = uitextarea(app.UsersGuidePanel);
            app.TextArea_2.Value = {'NI myDAQ: In this panel the user can make sure that the myDAQ is connected properly and MATLAB is detecting the hardware. In the case that the device is not connected or not connected properly, an error message will be displayed. The user can also introduce the ID name of the device, so it can be detected properly.'; ''; 'By default, the Device ID displayed is ''myDAQ1'''};
            app.TextArea_3 = uitextarea(app.UsersGuidePanel);
            app.TextArea_3.Value = {'Input Signal Properties: In this panel the user can define the characteristics of the signal that will be sent to the circuit connected to the myDAQ, such as:'; '- VPmin: minimum value of the VP signal (see Schematic in the help toolbar). Default: 0 [V].'; '- VPmax: maximum value of the VP signal. Default: 0.8 [V].'; '- VPsteps: number of divisions in which the vector is going to define the signal sent to the circuit. Default: 25 steps.'; 'Analogously:'; '- VNmin: minimum value of the VN signal. Default: 0 [V].'; '- VNmax: maximum value of the VN signal. Default: 0 [V]'; '- VNsteps: number of divisions in which the vector is going to define the signal sent to the circuit. Default: 25 steps.'; '- Sweep: this checkbox allows the user to plot multiple graphs by creating a sweep.'; 'NOTE: with Sweep disabled, the program takes the highest value between VPsteps and VNsteps and make the vectors VP and VN with that value, so the vectors are made with compatible sizes for the operations.'};
            app.TextArea_4 = uitextarea(app.UsersGuidePanel);
            app.TextArea_4.Value = {'Measurement Properties: In this panel the user can modify some parameters relevant to the simulation and circuit:'; '- R: in this field the user can modify the value of the resistor employed in the circuit. Default: 1000 [Ohms].'; '- NAveraging: in this field the user can define the number of simulations that can be done before averaging and representing the averaged result. Default: 1.'; '- IOff: It substracts current from I_D - to all points of I_D -, to remove the current from weak inversion. Default: 0 [A].'};
            app.TextArea_5 = uitextarea(app.UsersGuidePanel);
            app.TextArea_5.Value = {'NI myDAQ Ports: In this panel the user can control the channels employed in myDAQ as inputs and outputs to define what ports are used as VP, VN and VO.'; '- VP default: ao0.'; '- VN default: ao1.'; '- VO default: ai1.'};
            app.MeasurementStatusPanel = uipanel(app.UsersGuidePanel);
            app.MeasurementStatusPanel.Title = 'Measurement Status';
            app.RunMeasurementsButton = uibutton(app.MeasurementStatusPanel, 'push');
            app.RunMeasurementsButton.Text = 'Run Measurements';
            app.StopSimulationButton = uibutton(app.MeasurementStatusPanel, 'push');
            app.StopSimulationButton.Text = 'Stop Simulation';
            app.MeasurementStatusGaugeLabel = uilabel(app.MeasurementStatusPanel);
            app.MeasurementStatusGaugeLabel.Text = 'Measurement Status (%)';
            app.MeasurementStatusGauge = uigauge(app.MeasurementStatusPanel, 'linear');
            app.TextArea_6 = uitextarea(app.UsersGuidePanel);
            app.TextArea_6.Value = {'Measurement Status: In this panel the user can control the simulation proceeding. Functionalities available in this panel:'; '- Run measurement: with this button the user can start the simulation.'; '- Stop simulation: with this buttton the user can stop the simulation at any moment.'; '- Measurement Status (%): in this gauge, the user can see the percentage that represents how much is left to complete the simulation (only available for the Slow Mode (the Fast Mode is not selected)).'};
            app.TextArea_7 = uitextarea(app.UsersGuidePanel);
            app.TextArea_7.Value = {'Measurement Options: In this panel the user can modify some aspects relevant to the simulation that is going to be done.'; '- Fast mode: when selected, this option can do the same simulation as if it was not selected, but employing a different method that results in faster processing calculations. The reason why this option exists is because the user is provided with two different ways to play the same simulation, having with this more control over the simulation realised.'; '- Show Sat Limits: when pressed, this button shows in both graphs the saturation limits of current and voltage of the circuit. The user can press this button again, when it says ''Hide Sat Limits'', to hide the saturation limits.'; '- Curve fitting: this button gives access to the user to a panel in which the user can model the last curve represented as an exponential function, and the parameters and the errors are given. It also displays the correlation factor of the adjustment, so the user can have an idea of how good the curve fitting is. In the graphs, a function with the parameters calculated is represented, with names Ifit and Vfit, so the user can have a visual idea of the calculation realised by the curve fitting button. The residues of the fitting calculation is also available.'; '- Full Scale Range: it displays the FSR of the measurement.'};
            app.MeasurementOptionsPanel = uipanel(app.UsersGuidePanel);
            app.MeasurementOptionsPanel.Title = 'Measurement Options';
            app.FastModeCheckBox = uicheckbox(app.MeasurementOptionsPanel);
            app.FastModeCheckBox.Text = 'Fast Mode';
            app.ShowSatLimitsButton = uibutton(app.MeasurementOptionsPanel, 'push');
            app.ShowSatLimitsButton.Text = 'Show Sat Limits';
            app.CurvefittingButton = uibutton(app.MeasurementOptionsPanel, 'push');
            app.CurvefittingButton.Text = 'Curve fitting';
            app.FullScaleRangeButton = uibutton(app.MeasurementOptionsPanel, 'push');
            app.FullScaleRangeButton.Text = 'Full Scale Range';
            app.HoldonCheckBox_2 = uicheckbox(app.UsersGuidePanel);
            app.HoldonCheckBox_2.Text = 'Hold on';
            app.ButtonGroup = uibuttongroup(app.UsersGuidePanel);
            app.LinButton = uiradiobutton(app.ButtonGroup);
            app.LinButton.Text = 'Lin';
            app.LinButton.Value = true;
            app.LogButton = uiradiobutton(app.ButtonGroup);
            app.LogButton.Text = 'Log';
            app.ResetFiguresButton = uibutton(app.UsersGuidePanel, 'push');
            app.ResetFiguresButton.Text = 'Reset Figures';
            app.TextArea_9 = uitextarea(app.UsersGuidePanel);
            app.TextArea_9.Value = {'In this section of the GUI, the user can modify some options relative to the graphs. The ''Lin'' and ''Log'' radio buttons let you switch the y-axis of the I_D vs V_D graph between linear axis and logarithmic axis. The ''Hold on'' check box let you represent multiple graphs in both figures. The ''Reset Figures'' button cleans all the graphs and data from the figures.'};
            app.TextArea_10 = uitextarea(app.UsersGuidePanel);
            app.TextArea_10.Value = {'In the figures above, the user can see some of the curves that can be obtained with the Diode Characteristics application.'; ''; 'If you right-click the graphs, some options appear:'; ''; '- Rename: the user can rename any curve represented, so the graph can be better studied and understood by the user.'; '- Save as: the user can use this option to same the image obtained in the graph, in various formats.'; '- Export Data to Workspace: the user can export the data of the curves to the Workspace of MATLAB, so further analysis can be performed. The data is exported with the name of the curve, in a table with two columns, the first one are the x-values, and the second are the y-values.'; '- Load data: with this option, the user can load data into the graph, so comparisons and further analysis can be made.'; '- Hide Graph: with this option, the user can hide the graph selected.'; '- Show Graph: if the user hid a graph with the previous options, the user can show again that graph with this one.'};
            app.CurvefittingPanel_2 = uipanel(app.UsersGuidePanel);
            app.CurvefittingPanel_2.Title = 'Curve fitting';
            app.XButton_2 = uibutton(app.CurvefittingPanel_2, 'push');
            app.XButton_2.Text = 'X';
            app.CalculateButton_2 = uibutton(app.CurvefittingPanel_2, 'push');
            app.CalculateButton_2.Text = 'Calculate';
            app.aEditField_2Label = uilabel(app.CurvefittingPanel_2);
            app.aEditField_2Label.Text = 'a';
            app.aEditField_2 = uieditfield(app.CurvefittingPanel_2, 'numeric');
            app.bEditField_2Label = uilabel(app.CurvefittingPanel_2);
            app.bEditField_2Label.Text = 'b';
            app.bEditField_2 = uieditfield(app.CurvefittingPanel_2, 'numeric');
            app.ErroraEditField_2Label = uilabel(app.CurvefittingPanel_2);
            app.ErroraEditField_2Label.Text = 'Error a';
            app.ErroraEditField_2 = uieditfield(app.CurvefittingPanel_2, 'numeric');
            app.ErrorbEditField_2Label = uilabel(app.CurvefittingPanel_2);
            app.ErrorbEditField_2Label.Text = 'Error b';
            app.ErrorbEditField_2 = uieditfield(app.CurvefittingPanel_2, 'numeric');
            app.dEditField_2Label = uilabel(app.CurvefittingPanel_2);
            app.dEditField_2Label.Text = 'd';
            app.dEditField_2 = uieditfield(app.CurvefittingPanel_2, 'numeric');
            app.ErrordEditField_2Label = uilabel(app.CurvefittingPanel_2);
            app.ErrordEditField_2Label.Text = 'Error d';
            app.ErrordEditField_2 = uieditfield(app.CurvefittingPanel_2, 'numeric');
            app.eEditFieldLabel = uilabel(app.CurvefittingPanel_2);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.CurvefittingPanel_2, 'numeric');
            app.ErroreEditFieldLabel = uilabel(app.CurvefittingPanel_2);
            app.ErroreEditFieldLabel.Text = 'Error e';
            app.ErroreEditField = uieditfield(app.CurvefittingPanel_2, 'numeric');
            app.R2EditField_3Label = uilabel(app.CurvefittingPanel_2);
            app.R2EditField_3Label.Text = 'R^2';
            app.R2EditField_3 = uieditfield(app.CurvefittingPanel_2, 'numeric');
            app.R22EditFieldLabel = uilabel(app.CurvefittingPanel_2);
            app.R22EditFieldLabel.Text = 'R^2 (2)';
            app.R22EditField = uieditfield(app.CurvefittingPanel_2, 'numeric');
            app.Image2_4 = uiimage(app.CurvefittingPanel_2);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'DiodeCharacteristicsCurrentEquation.svg');
            app.Image2_5 = uiimage(app.CurvefittingPanel_2);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'DiodeCharacteristicsVoltageEquation.svg');
            app.fEditFieldLabel = uilabel(app.CurvefittingPanel_2);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.CurvefittingPanel_2, 'numeric');
            app.ErrorfEditFieldLabel = uilabel(app.CurvefittingPanel_2);
            app.ErrorfEditFieldLabel.Text = 'Error f';
            app.ErrorfEditField = uieditfield(app.CurvefittingPanel_2, 'numeric');
            app.cEditField_2Label = uilabel(app.CurvefittingPanel_2);
            app.cEditField_2Label.Text = 'c';
            app.cEditField_2 = uieditfield(app.CurvefittingPanel_2, 'numeric');
            app.ErrorcEditField_2Label = uilabel(app.CurvefittingPanel_2);
            app.ErrorcEditField_2Label.Text = 'Error c';
            app.ErrorcEditField_2 = uieditfield(app.CurvefittingPanel_2, 'numeric');
            app.GetresiduesButton = uibutton(app.CurvefittingPanel_2, 'push');
            app.GetresiduesButton.Text = 'Get residues';
            app.TextArea_8 = uitextarea(app.UsersGuidePanel);
            app.TextArea_8.Value = {'Curve fitting: this button gives access to the user to a panel in which the user can model the last curve represented as an exponential function, and the parameters and the errors are given. It also displays the correlation factor of the adjustment, so the user can have an idea of how good the curve fitting is. In the graphs, a function with the parameters calculated is represented, with names Ifit and Vfit, so the user can have a visual idea of the calculation realised by the curve fitting button. There is alsa a "Get residues" button, so the user can visualize the residues. The user can also save the residues plots and export the fitting parameters.'};
            app.MeasurementPropertiesPanel = uipanel(app.UsersGuidePanel);
            app.MeasurementPropertiesPanel.Title = 'Measurement Properties';
            app.REditFieldLabel = uilabel(app.UsersGuidePanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.NAveragingEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.NAveragingEditFieldLabel.Text = 'NAveraging';
            app.NAveragingEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.IOffEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.IOffEditFieldLabel.Text = 'IOff';
            app.IOffEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HelpDiodeApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end