classdef HelpMOSFETApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SchematicTab                    matlab.ui.container.Tab
        SCHEMATICOFTHEMOSFETCIRCUITIMPLEMENTEDINNIMYDAQLabel  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        MC4007UBPinOutTab               matlab.ui.container.Tab
        PINOUTOFTHEMC4007UBCHIPIMPLEMENTEDINTHECIRCUITLabel  matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        OpAmpPinOutTab                  matlab.ui.container.Tab
        Image4_2                        matlab.ui.control.Image
        PINOUTOFTHE741OPAMPIMPLEMENTEDINTHECIRCUITLabel  matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        ExtraParametersTab              matlab.ui.container.Tab
        Image7                          matlab.ui.control.Image
        SIMPLIFIEDEQUATIONSFORI_DINLEVEL3MODELLabel  matlab.ui.control.Label
        Image6                          matlab.ui.control.Image
        OHMICANDSATURATIONPARAMETERSLabel  matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        FunctionalitiesoftheAppTab      matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        UsersGuideTab                   matlab.ui.container.Tab
        UsersGuidePanel                 matlab.ui.container.Panel
        TextArea_12                     matlab.ui.control.TextArea
        ExtraParametersPanel_2          matlab.ui.container.Panel
        UOEditField_2                   matlab.ui.control.NumericEditField
        UOEditField_2Label              matlab.ui.control.Label
        THETAEditField_2                matlab.ui.control.NumericEditField
        THETAEditField_2Label           matlab.ui.control.Label
        VTOEditField_2                  matlab.ui.control.NumericEditField
        VTOEditField_2Label             matlab.ui.control.Label
        THETA2EditField                 matlab.ui.control.NumericEditField
        THETA2EditFieldLabel            matlab.ui.control.Label
        VSBEditField                    matlab.ui.control.NumericEditField
        VSBEditFieldLabel               matlab.ui.control.Label
        KAPPAEditField                  matlab.ui.control.NumericEditField
        KAPPAEditFieldLabel             matlab.ui.control.Label
        GAMMAEditField                  matlab.ui.control.NumericEditField
        GAMMAEditFieldLabel             matlab.ui.control.Label
        PHIEditField                    matlab.ui.control.NumericEditField
        PHIEditFieldLabel               matlab.ui.control.Label
        VLabel_10                       matlab.ui.control.Label
        V12Label                        matlab.ui.control.Label
        VLabel_9                        matlab.ui.control.Label
        VLabel_8                        matlab.ui.control.Label
        CalculateButton_3               matlab.ui.control.Button
        VLabel_7                        matlab.ui.control.Label
        cm2VsLabel_3                    matlab.ui.control.Label
        VLabel_6                        matlab.ui.control.Label
        VLabel_5                        matlab.ui.control.Label
        IstheresubstrateeffectLabel     matlab.ui.control.Label
        STRONGINVERSIONPARAMETERSLabel  matlab.ui.control.Label
        ExtraParametersPanel            matlab.ui.container.Panel
        ErrorVTOEditField               matlab.ui.control.EditField
        ErrorVTOEditFieldLabel          matlab.ui.control.Label
        ErrorTHETAEditField             matlab.ui.control.EditField
        ErrorTHETAEditFieldLabel        matlab.ui.control.Label
        ErrorUOEditField                matlab.ui.control.EditField
        ErrorUOEditFieldLabel           matlab.ui.control.Label
        R2EditField_2                   matlab.ui.control.NumericEditField
        R2EditField_2Label              matlab.ui.control.Label
        UOEditField                     matlab.ui.control.NumericEditField
        UOEditFieldLabel                matlab.ui.control.Label
        THETAEditField                  matlab.ui.control.NumericEditField
        THETAEditFieldLabel             matlab.ui.control.Label
        VTOEditField                    matlab.ui.control.NumericEditField
        VTOEditFieldLabel               matlab.ui.control.Label
        Image6_2                        matlab.ui.control.Image
        cm2VsLabel_2                    matlab.ui.control.Label
        cm2VsLabel                      matlab.ui.control.Label
        VLabel_4                        matlab.ui.control.Label
        VLabel_3                        matlab.ui.control.Label
        VLabel_2                        matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        OHMICANDSATURATIONPARAMETERSLabel_2  matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        TextArea_11                     matlab.ui.control.TextArea
        TextArea_10                     matlab.ui.control.TextArea
        TextArea_9                      matlab.ui.control.TextArea
        XAxisDropDown                   matlab.ui.control.DropDown
        XAxisDropDownLabel              matlab.ui.control.Label
        ResetFiguresButton              matlab.ui.control.Button
        HoldonCheckBox                  matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        LogButton                       matlab.ui.control.RadioButton
        LinButton                       matlab.ui.control.RadioButton
        TextArea_8                      matlab.ui.control.TextArea
        CurvefittingPanel               matlab.ui.container.Panel
        ErrorlambdaEditField            matlab.ui.control.EditField
        ErrorlambdaEditFieldLabel       matlab.ui.control.Label
        ErrorKEditField                 matlab.ui.control.EditField
        ErrorKEditFieldLabel            matlab.ui.control.Label
        ErrorVthEditField               matlab.ui.control.EditField
        ErrorVthEditFieldLabel          matlab.ui.control.Label
        lambdaEditField                 matlab.ui.control.NumericEditField
        lambdaEditFieldLabel            matlab.ui.control.Label
        VthEditField                    matlab.ui.control.NumericEditField
        VthEditFieldLabel               matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        KEditFieldLabel                 matlab.ui.control.Label
        GetresiduesButton               matlab.ui.control.Button
        R2EditField                     matlab.ui.control.NumericEditField
        R2EditFieldLabel                matlab.ui.control.Label
        Image4_3                        matlab.ui.control.Image
        Image3_2                        matlab.ui.control.Image
        Image2_3                        matlab.ui.control.Image
        XButton                         matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        TextArea_7                      matlab.ui.control.TextArea
        MeasurementOptionsPanel         matlab.ui.container.Panel
        FullScaleRangeButton            matlab.ui.control.Button
        CurvefittingButton              matlab.ui.control.Button
        ShowSatLimitsButton             matlab.ui.control.Button
        FastModeCheckBox                matlab.ui.control.CheckBox
        TextArea_6                      matlab.ui.control.TextArea
        MeasurementStatusPanel          matlab.ui.container.Panel
        MeasurementStatusGauge          matlab.ui.control.LinearGauge
        MeasurementStatusGaugeLabel     matlab.ui.control.Label
        StopSimulationButton            matlab.ui.control.Button
        RunMeasurementsButton           matlab.ui.control.Button
        TextArea_5                      matlab.ui.control.TextArea
        NImyDAQPortsPanel               matlab.ui.container.Panel
        VODropDown                      matlab.ui.control.DropDown
        VODropDownLabel                 matlab.ui.control.Label
        VGDropDown                      matlab.ui.control.DropDown
        VGDropDownLabel                 matlab.ui.control.Label
        VDDropDown                      matlab.ui.control.DropDown
        VDDropDownLabel                 matlab.ui.control.Label
        TextArea_4                      matlab.ui.control.TextArea
        IOffEditField                   matlab.ui.control.NumericEditField
        IOffEditFieldLabel              matlab.ui.control.Label
        NAveragingEditField             matlab.ui.control.NumericEditField
        NAveragingEditFieldLabel        matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        REditFieldLabel                 matlab.ui.control.Label
        MeasurementPropertiesPanel      matlab.ui.container.Panel
        TextArea_3                      matlab.ui.control.TextArea
        VGstepsEditField                matlab.ui.control.NumericEditField
        VGstepsEditFieldLabel           matlab.ui.control.Label
        VGmaxEditField                  matlab.ui.control.NumericEditField
        VGmaxEditFieldLabel             matlab.ui.control.Label
        VGminEditField                  matlab.ui.control.NumericEditField
        VGminEditFieldLabel             matlab.ui.control.Label
        VDstepsEditField                matlab.ui.control.NumericEditField
        VDstepsEditFieldLabel           matlab.ui.control.Label
        VDmaxEditField                  matlab.ui.control.NumericEditField
        VDmaxEditFieldLabel             matlab.ui.control.Label
        VDminEditField                  matlab.ui.control.NumericEditField
        VDminEditFieldLabel             matlab.ui.control.Label
        InputSignalPropertiesPanel      matlab.ui.container.Panel
        SweepCheckBox                   matlab.ui.control.CheckBox
        TextArea_2                      matlab.ui.control.TextArea
        LampLabel                       matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        NImyDAQSwitch                   matlab.ui.control.Switch
        NImyDAQPanel                    matlab.ui.container.Panel
        DeviceIDEditField               matlab.ui.control.EditField
        DeviceIDEditFieldLabel          matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VDminEditField.Value = 0;
            app.VDmaxEditField.Value = 5;
            app.VDstepsEditField.Value = 25;
            app.VGminEditField.Value = 2.7;
            app.VGmaxEditField.Value = 2.7;
            app.VGstepsEditField.Value = 25;
            app.REditField.Value = 1000;
            app.NAveragingEditField.Value = 1;
            app.IOffEditField.Value = 0;
            app.DeviceIDEditField.Value = 'myDAQ1';
            Idata = load('IMOSFETuserguide.mat');
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, Idata.data1(:,1), Idata.data1(:,2), 'b')
            plot(app.UIAxes, Idata.data2(:,1), Idata.data2(:,2))
            plot(app.UIAxes, Idata.data3(:,1), Idata.data3(:,2))
            plot(app.UIAxes, Idata.Ifit(:,1), Idata.Ifit(:,2))
            hold(app.UIAxes, 'off')
            legend(app.UIAxes, 'show', 'Location', 'best')
            Vdata = load('VMOSFETuserguide.mat');
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, Vdata.data1(:,1), Vdata.data1(:,2), 'b')
            plot(app.UIAxes2, Vdata.data2(:,1), Vdata.data2(:,2))
            plot(app.UIAxes2, Vdata.data3(:,1), Vdata.data3(:,2))
            plot(app.UIAxes2, Vdata.Vfit(:,1), Vdata.Vfit(:,2))
            hold(app.UIAxes2, 'off')
            legend(app.UIAxes2, 'show', 'Location', 'best')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SchematicTab = uitab(app.TabGroup);
            app.SchematicTab.Title = 'Schematic';
            app.Image = uiimage(app.SchematicTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'US-marca-principal.png');
            app.Image3 = uiimage(app.SchematicTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'MOSFETCharacteristicsSchematic.svg');
            app.SCHEMATICOFTHEMOSFETCIRCUITIMPLEMENTEDINNIMYDAQLabel = uilabel(app.SchematicTab);
            app.SCHEMATICOFTHEMOSFETCIRCUITIMPLEMENTEDINNIMYDAQLabel.Text = 'SCHEMATIC OF THE MOSFET CIRCUIT IMPLEMENTED IN NI MYDAQ';
            app.MC4007UBPinOutTab = uitab(app.TabGroup);
            app.MC4007UBPinOutTab.Title = 'MC 4007UB PinOut';
            app.Image2 = uiimage(app.MC4007UBPinOutTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'US-marca-principal.png');
            app.Image4 = uiimage(app.MC4007UBPinOutTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'MOSFETCharacteristicsChip4007.svg');
            app.PINOUTOFTHEMC4007UBCHIPIMPLEMENTEDINTHECIRCUITLabel = uilabel(app.MC4007UBPinOutTab);
            app.PINOUTOFTHEMC4007UBCHIPIMPLEMENTEDINTHECIRCUITLabel.Text = 'PINOUT OF THE MC 4007UB CHIP IMPLEMENTED IN THE CIRCUIT';
            app.OpAmpPinOutTab = uitab(app.TabGroup);
            app.OpAmpPinOutTab.Title = '741 OpAmp PinOut';
            app.Image2_2 = uiimage(app.OpAmpPinOutTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'MOSFETCharacteristics741PinOut.svg');
            app.PINOUTOFTHE741OPAMPIMPLEMENTEDINTHECIRCUITLabel = uilabel(app.OpAmpPinOutTab);
            app.PINOUTOFTHE741OPAMPIMPLEMENTEDINTHECIRCUITLabel.Text = 'PINOUT OF THE 741 OPAMP IMPLEMENTED IN THE CIRCUIT';
            app.Image4_2 = uiimage(app.OpAmpPinOutTab);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'US-marca-principal.png');
            app.ExtraParametersTab = uitab(app.TabGroup);
            app.ExtraParametersTab.Title = 'Extra Parameters';
            app.Image5 = uiimage(app.ExtraParametersTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'US-marca-principal.png');
            app.OHMICANDSATURATIONPARAMETERSLabel = uilabel(app.ExtraParametersTab);
            app.OHMICANDSATURATIONPARAMETERSLabel.Text = 'OHMIC AND SATURATION PARAMETERS';
            app.Image6 = uiimage(app.ExtraParametersTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'MOSFETCharacteristicsUOTHETAVTO.svg');
            app.SIMPLIFIEDEQUATIONSFORI_DINLEVEL3MODELLabel = uilabel(app.ExtraParametersTab);
            app.SIMPLIFIEDEQUATIONSFORI_DINLEVEL3MODELLabel.Text = 'SIMPLIFIED EQUATIONS FOR I_D IN LEVEL 3 MODEL';
            app.Image7 = uiimage(app.ExtraParametersTab);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'MOSFETCharacteristicsStrInv.svg');
            app.FunctionalitiesoftheAppTab = uitab(app.TabGroup);
            app.FunctionalitiesoftheAppTab.Title = 'Functionalities of the App';
            app.Label = uilabel(app.FunctionalitiesoftheAppTab);
            app.Label.Text = 'HELP FOR THE FUNCTIONALITIES OF THE MOSFET CHARACTERISTICS APPLICATION';
            app.TextArea = uitextarea(app.FunctionalitiesoftheAppTab);
            app.TextArea.Value = {'Panels:'; ''; 'NI myDAQ: In this panel the user can make sure that the myDAQ is connected properly and MATLAB is detecting the hardware. In the case that the device is not connected or not connected properly, an error message will be displayed. The user can also introduce the ID name of the device, so it can be detected properly.'; ''; 'NI myDAQ Ports: In this panel the user can control the channels employed in myDAQ as inputs and outputs to define what ports are used as VD, VG and VO.'; ''; 'Input Signal Properties: In this panel the user can define the characteristics of the signal that will be sent to the circuit connected to the myDAQ, such as:'};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
            app.UsersGuideTab = uitab(app.TabGroup);
            app.UsersGuideTab.Title = 'User''s Guide';
            app.UsersGuidePanel = uipanel(app.UsersGuideTab);
            app.UsersGuidePanel.Title = 'User''s Guide';
            app.UIAxes = uiaxes(app.UsersGuidePanel);
            title(app.UIAxes, 'MOSFET I-V Characteristics')
            ylabel(app.UIAxes, 'I_D [A]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UsersGuidePanel);
            title(app.UIAxes2, 'Output Voltage')
            ylabel(app.UIAxes2, 'V_O [V]')
            zlabel(app.UIAxes2, 'Z')
            app.NImyDAQPanel = uipanel(app.UsersGuidePanel);
            app.NImyDAQPanel.Title = 'NI myDAQ';
            app.DeviceIDEditFieldLabel = uilabel(app.NImyDAQPanel);
            app.DeviceIDEditFieldLabel.Text = 'Device ID';
            app.DeviceIDEditField = uieditfield(app.NImyDAQPanel, 'text');
            app.NImyDAQSwitch = uiswitch(app.UsersGuidePanel, 'slider');
            app.Lamp = uilamp(app.UsersGuidePanel);
            app.LampLabel = uilabel(app.UsersGuidePanel);
            app.LampLabel.Text = 'Lamp';
            app.TextArea_2 = uitextarea(app.UsersGuidePanel);
            app.TextArea_2.Value = {'NI myDAQ: In this panel the user can make sure that the myDAQ is connected properly and MATLAB is detecting the hardware. In the case that the device is not connected or not connected properly, an error message will be displayed. The user can also introduce the ID name of the device, so it can be detected properly.'; ''; 'By default, the Device ID displayed is ''myDAQ1'''};
            app.InputSignalPropertiesPanel = uipanel(app.UsersGuidePanel);
            app.InputSignalPropertiesPanel.Title = 'Input Signal Properties';
            app.SweepCheckBox = uicheckbox(app.InputSignalPropertiesPanel);
            app.SweepCheckBox.Text = 'Sweep';
            app.VDminEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.VDminEditFieldLabel.Text = 'VDmin';
            app.VDminEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.VDmaxEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.VDmaxEditFieldLabel.Text = 'VDmax';
            app.VDmaxEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.VDstepsEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.VDstepsEditFieldLabel.Text = 'VDsteps';
            app.VDstepsEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.VGminEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.VGminEditFieldLabel.Text = 'VGmin';
            app.VGminEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.VGmaxEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.VGmaxEditFieldLabel.Text = 'VGmax';
            app.VGmaxEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.VGstepsEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.VGstepsEditFieldLabel.Text = 'VGsteps';
            app.VGstepsEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.TextArea_3 = uitextarea(app.UsersGuidePanel);
            app.TextArea_3.Value = {'Input Signal Properties: In this panel the user can define the characteristics of the signal that will be sent to the circuit connected to the myDAQ, such as:'; '- VDmin: minimum value of the VD signal (see Schematic in the help toolbar). Default: 0 [V].'; '- VDmax: maximum value of the VD signal. Default: 5 [V].'; '- VDsteps: number of divisions in which the vector is going to define the signal sent to the circuit. Default: 25 steps.'; 'Analogously:'; '- VGmin: minimum value of the VG signal. Default: 2.7 [V].'; '- VGmax: maximum value of the VG signal. Default: 2.7 [V]'; '- VGsteps: number of divisions in which the vector is going to define the signal sent to the circuit. Default: 25 steps.'; '- Sweep: this checkbox allows the user to plot multiple graphs by creating a sweep.'; 'NOTE: with Sweep disabled, the app takes the highes value between VDsteps and VGsteps so the vectors are compatible sizes for the operations.'};
            app.MeasurementPropertiesPanel = uipanel(app.UsersGuidePanel);
            app.MeasurementPropertiesPanel.Title = 'Measurement Properties';
            app.REditFieldLabel = uilabel(app.UsersGuidePanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.NAveragingEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.NAveragingEditFieldLabel.Text = 'NAveraging';
            app.NAveragingEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.IOffEditFieldLabel = uilabel(app.UsersGuidePanel);
            app.IOffEditFieldLabel.Text = 'IOff';
            app.IOffEditField = uieditfield(app.UsersGuidePanel, 'numeric');
            app.TextArea_4 = uitextarea(app.UsersGuidePanel);
            app.TextArea_4.Value = {'Measurement Properties: In this panel the user can modify some parameters relevant to the simulation and circuit:'; '- R: in this field the user can modify the value of the resistor employed in the circuit. Default: 1000 [Ohms].'; '- NAveraging: in this field the user can define the number of simulations that can be done before averaging and representing the averaged result. Default: 1.'; '- IOff: It substracts current from I_D - to all points of I_D -, to remove the current from weak inversion. Default: 0 [A].'};
            app.NImyDAQPortsPanel = uipanel(app.UsersGuidePanel);
            app.NImyDAQPortsPanel.Title = 'NI myDAQ Ports';
            app.VDDropDownLabel = uilabel(app.NImyDAQPortsPanel);
            app.VDDropDownLabel.Text = 'VD';
            app.VDDropDown = uidropdown(app.NImyDAQPortsPanel);
            app.VDDropDown.Items = {'''ao0''', '''ao1'''};
            app.VDDropDown.Value = '''ao0''';
            app.VGDropDownLabel = uilabel(app.NImyDAQPortsPanel);
            app.VGDropDownLabel.Text = 'VG';
            app.VGDropDown = uidropdown(app.NImyDAQPortsPanel);
            app.VGDropDown.Items = {'''ao0''', '''ao1'''};
            app.VGDropDown.Value = '''ao1''';
            app.VODropDownLabel = uilabel(app.NImyDAQPortsPanel);
            app.VODropDownLabel.Text = 'VO';
            app.VODropDown = uidropdown(app.NImyDAQPortsPanel);
            app.VODropDown.Items = {'''ai0''', '''ai1'''};
            app.VODropDown.Value = '''ai1''';
            app.TextArea_5 = uitextarea(app.UsersGuidePanel);
            app.TextArea_5.Value = {'NI myDAQ Ports: In this panel the user can control the channels employed in myDAQ as inputs and outputs to define what ports are used as VP, VN and VO.'; '- VP default: ao0.'; '- VN default: ao1.'; '- VO default: ai1.'};
            app.MeasurementStatusPanel = uipanel(app.UsersGuidePanel);
            app.MeasurementStatusPanel.Title = 'Measurement Status';
            app.RunMeasurementsButton = uibutton(app.MeasurementStatusPanel, 'push');
            app.RunMeasurementsButton.Text = 'Run Measurements';
            app.StopSimulationButton = uibutton(app.MeasurementStatusPanel, 'push');
            app.StopSimulationButton.Text = 'Stop Simulation';
            app.MeasurementStatusGaugeLabel = uilabel(app.MeasurementStatusPanel);
            app.MeasurementStatusGaugeLabel.Text = 'Measurement Status (%)';
            app.MeasurementStatusGauge = uigauge(app.MeasurementStatusPanel, 'linear');
            app.TextArea_6 = uitextarea(app.UsersGuidePanel);
            app.TextArea_6.Value = {'Measurement Status: In this panel the user can control the simulation proceeding. Functionalities available in this panel:'; '- Run measurement: with this button the user can start the simulation.'; '- Stop simulation: with this buttton the user can stop the simulation at any moment.'; '- Measurement Status (%): in this gauge, the user can see the percentage that represents how much is left to complete the simulation (only available for the Slow Mode (the Fast Mode is not selected)).'};
            app.MeasurementOptionsPanel = uipanel(app.UsersGuidePanel);
            app.MeasurementOptionsPanel.Title = 'Measurement Options';
            app.FastModeCheckBox = uicheckbox(app.MeasurementOptionsPanel);
            app.FastModeCheckBox.Text = 'Fast Mode';
            app.ShowSatLimitsButton = uibutton(app.MeasurementOptionsPanel, 'push');
            app.ShowSatLimitsButton.Text = 'Show Sat Limits';
            app.CurvefittingButton = uibutton(app.MeasurementOptionsPanel, 'push');
            app.CurvefittingButton.Text = 'Curve fitting';
            app.FullScaleRangeButton = uibutton(app.MeasurementOptionsPanel, 'push');
            app.FullScaleRangeButton.Text = 'Full Scale Range';
            app.TextArea_7 = uitextarea(app.UsersGuidePanel);
            app.TextArea_7.Value = {'Measurement Options: In this panel the user can modify some aspects relevant to the simulation that is going to be done.'; '- Fast mode: when selected, this option can do the same simulation as if it was not selected, but employing a different method that results in faster processing calculations. The reason why this option exists is because the user is provided with two different ways to play the same simulation, having with this more control over the simulation realised.'; '- Show Sat Limits: when pressed, this button shows in both graphs the saturation limits of current and voltage of the circuit. The user can press this button again, when it says ''Hide Sat Limits'', to hide the saturation limits.'; '- Curve fitting: this button gives access to the user to a panel in which the user can model the last curve represented as an exponential function, and the parameters and the errors are given. It also displays the correlation factor of the adjustment, so the user can have an idea of how good the curve fitting is. In the graphs, a function with the parameters calculated is represented, with names Ifit and Vfit, so the user can have a visual idea of the calculation realised by the curve fitting button. It also shows the residues of the fitting calculation.'; '- Full Scale Range: it shows the FSR of the measurement.'};
            app.CurvefittingPanel = uipanel(app.UsersGuidePanel);
            app.CurvefittingPanel.Title = 'Curve fitting';
            app.CalculateButton = uibutton(app.CurvefittingPanel, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.XButton = uibutton(app.CurvefittingPanel, 'push');
            app.XButton.Text = 'X';
            app.Image2_3 = uiimage(app.CurvefittingPanel);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'MOSFETCharacteristicsVoltageEquation.svg');
            app.Image3_2 = uiimage(app.CurvefittingPanel);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'MOSFETCharacteristicsCurrentEquation.svg');
            app.Image4_3 = uiimage(app.CurvefittingPanel);
            app.Image4_3.ImageSource = fullfile(pathToMLAPP, 'MOSFETCharacteristicsKEquation.svg');
            app.R2EditFieldLabel = uilabel(app.CurvefittingPanel);
            app.R2EditFieldLabel.Text = 'R^2';
            app.R2EditField = uieditfield(app.CurvefittingPanel, 'numeric');
            app.GetresiduesButton = uibutton(app.CurvefittingPanel, 'push');
            app.GetresiduesButton.Text = 'Get residues';
            app.KEditFieldLabel = uilabel(app.CurvefittingPanel);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.CurvefittingPanel, 'numeric');
            app.VthEditFieldLabel = uilabel(app.CurvefittingPanel);
            app.VthEditFieldLabel.Text = 'Vth';
            app.VthEditField = uieditfield(app.CurvefittingPanel, 'numeric');
            app.lambdaEditFieldLabel = uilabel(app.CurvefittingPanel);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.CurvefittingPanel, 'numeric');
            app.ErrorVthEditFieldLabel = uilabel(app.CurvefittingPanel);
            app.ErrorVthEditFieldLabel.Text = 'Error Vth';
            app.ErrorVthEditField = uieditfield(app.CurvefittingPanel, 'text');
            app.ErrorKEditFieldLabel = uilabel(app.CurvefittingPanel);
            app.ErrorKEditFieldLabel.Text = 'Error K';
            app.ErrorKEditField = uieditfield(app.CurvefittingPanel, 'text');
            app.ErrorlambdaEditFieldLabel = uilabel(app.CurvefittingPanel);
            app.ErrorlambdaEditFieldLabel.Text = 'Error lambda';
            app.ErrorlambdaEditField = uieditfield(app.CurvefittingPanel, 'text');
            app.TextArea_8 = uitextarea(app.UsersGuidePanel);
            app.TextArea_8.Value = {'Curve fitting: this button gives access to the user to a panel in which the user can model the last curve represented, and the parameters and the confident intervals are given. It also displays the correlation factor of the adjustment, so the user can have an idea of how good the curve fitting is. In the graphs, a function with the parameters calculated is represented, with names Ifit and Vfit, so the user can have a visual idea of the calculation realised by the curve fitting button. There is also a "Get residues" button, and the residues are plotted in the same panel as the Curve fitting panel. There is a menu available so the user can save the residues plots and export the fit parameters.'};
            app.ButtonGroup = uibuttongroup(app.UsersGuidePanel);
            app.LinButton = uiradiobutton(app.ButtonGroup);
            app.LinButton.Text = 'Lin';
            app.LinButton.Value = true;
            app.LogButton = uiradiobutton(app.ButtonGroup);
            app.LogButton.Text = 'Log';
            app.HoldonCheckBox = uicheckbox(app.UsersGuidePanel);
            app.HoldonCheckBox.Text = 'Hold on';
            app.ResetFiguresButton = uibutton(app.UsersGuidePanel, 'push');
            app.ResetFiguresButton.Text = 'Reset Figures';
            app.XAxisDropDownLabel = uilabel(app.UsersGuidePanel);
            app.XAxisDropDownLabel.Text = 'X Axis';
            app.XAxisDropDown = uidropdown(app.UsersGuidePanel);
            app.XAxisDropDown.Items = {'V_DS', 'V_GS'};
            app.XAxisDropDown.Value = 'V_DS';
            app.TextArea_9 = uitextarea(app.UsersGuidePanel);
            app.TextArea_9.Value = {'In this section of the GUI, the user can modify some options relative to the graphs. The ''Lin'' and ''Log'' radio buttons let you switch the y-axis of the I_D vs V_D graph between linear axis and logarithmic axis. The ''Hold on'' check box let you represent multiple graphs in both figures. The ''Reset Figures'' button cleans all the graphs and data from the figures. There is an X Axis Drop Down with the options V_DS and V_GS, so the user can choose which magnitude will be represented in the X Axis.'};
            app.TextArea_10 = uitextarea(app.UsersGuidePanel);
            app.TextArea_10.Value = {'In the figures above, the user can see some of the curves that can be obtained with the MOSFET Characteristics application.'; ''; 'If you right-click the graphs, some options appear:'; ''; '- Rename: the user can rename any curve represented, so the graph can be better studied and understood by the user.'; '- Save as: the user can use this option to same the image obtained in the graph, in various formats.'; '- Export Data to Workspace: the user can export the data of the curves to the Workspace of MATLAB, so further analysis can be performed. The data is exported with the name of the curve, in a table with two columns, the first one are the x-values, and the second are the y-values.'; '- Load data: with this option, the user can load data into the graph, so comparisons and further analysis can be made.'; '- Hide Graph: with this option, the user can hide the graph selected.'; '- Show Graph: if the user hid a graph with the previous options, the user can show again that graph with this one.'};
            app.TextArea_11 = uitextarea(app.UsersGuidePanel);
            app.TextArea_11.Value = {'In this panel the user can obtain extra parameters for ohmic and saturation regions, as well as strong inversion regions using level 3 model of the transistor.'; ''; 'The parameters that can be obtained for the ohmic and saturation regions are UO, THETA and VTO, defined in another panel of the Help App. The errors of the fit and the correlation coefficients of the adjustment are also displayed.'};
            app.ExtraParametersPanel = uipanel(app.UsersGuidePanel);
            app.ExtraParametersPanel.Title = 'Extra Parameters';
            app.CalculateButton_2 = uibutton(app.ExtraParametersPanel, 'push');
            app.CalculateButton_2.Text = 'Calculate';
            app.OHMICANDSATURATIONPARAMETERSLabel_2 = uilabel(app.ExtraParametersPanel);
            app.OHMICANDSATURATIONPARAMETERSLabel_2.Text = 'OHMIC AND SATURATION PARAMETERS';
            app.VLabel = uilabel(app.ExtraParametersPanel);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.ExtraParametersPanel);
            app.VLabel_2.Text = 'V';
            app.VLabel_3 = uilabel(app.ExtraParametersPanel);
            app.VLabel_3.Text = '1/V';
            app.VLabel_4 = uilabel(app.ExtraParametersPanel);
            app.VLabel_4.Text = '1/V';
            app.cm2VsLabel = uilabel(app.ExtraParametersPanel);
            app.cm2VsLabel.Text = 'cm^2/V·s';
            app.cm2VsLabel_2 = uilabel(app.ExtraParametersPanel);
            app.cm2VsLabel_2.Text = 'cm^2/V·s';
            app.Image6_2 = uiimage(app.ExtraParametersPanel);
            app.Image6_2.ImageSource = fullfile(pathToMLAPP, 'MOSFETCharacteristicsUOTHETAVTO.svg');
            app.VTOEditFieldLabel = uilabel(app.ExtraParametersPanel);
            app.VTOEditFieldLabel.Text = 'VTO';
            app.VTOEditField = uieditfield(app.ExtraParametersPanel, 'numeric');
            app.THETAEditFieldLabel = uilabel(app.ExtraParametersPanel);
            app.THETAEditFieldLabel.Text = 'THETA';
            app.THETAEditField = uieditfield(app.ExtraParametersPanel, 'numeric');
            app.UOEditFieldLabel = uilabel(app.ExtraParametersPanel);
            app.UOEditFieldLabel.Text = 'UO';
            app.UOEditField = uieditfield(app.ExtraParametersPanel, 'numeric');
            app.R2EditField_2Label = uilabel(app.ExtraParametersPanel);
            app.R2EditField_2Label.Text = 'R^2';
            app.R2EditField_2 = uieditfield(app.ExtraParametersPanel, 'numeric');
            app.ErrorUOEditFieldLabel = uilabel(app.ExtraParametersPanel);
            app.ErrorUOEditFieldLabel.Text = 'Error UO';
            app.ErrorUOEditField = uieditfield(app.ExtraParametersPanel, 'text');
            app.ErrorTHETAEditFieldLabel = uilabel(app.ExtraParametersPanel);
            app.ErrorTHETAEditFieldLabel.Text = 'Error THETA';
            app.ErrorTHETAEditField = uieditfield(app.ExtraParametersPanel, 'text');
            app.ErrorVTOEditFieldLabel = uilabel(app.ExtraParametersPanel);
            app.ErrorVTOEditFieldLabel.Text = 'Error VTO';
            app.ErrorVTOEditField = uieditfield(app.ExtraParametersPanel, 'text');
            app.ExtraParametersPanel_2 = uipanel(app.UsersGuidePanel);
            app.ExtraParametersPanel_2.Title = 'Extra Parameters';
            app.STRONGINVERSIONPARAMETERSLabel = uilabel(app.ExtraParametersPanel_2);
            app.STRONGINVERSIONPARAMETERSLabel.Text = 'STRONG INVERSION PARAMETERS';
            app.IstheresubstrateeffectLabel = uilabel(app.ExtraParametersPanel_2);
            app.IstheresubstrateeffectLabel.Text = 'Is there substrate effect?';
            app.VLabel_5 = uilabel(app.ExtraParametersPanel_2);
            app.VLabel_5.Text = 'V';
            app.VLabel_6 = uilabel(app.ExtraParametersPanel_2);
            app.VLabel_6.Text = '1/V';
            app.cm2VsLabel_3 = uilabel(app.ExtraParametersPanel_2);
            app.cm2VsLabel_3.Text = 'cm^2/V·s';
            app.VLabel_7 = uilabel(app.ExtraParametersPanel_2);
            app.VLabel_7.Text = 'V';
            app.CalculateButton_3 = uibutton(app.ExtraParametersPanel_2, 'push');
            app.CalculateButton_3.Text = 'Calculate';
            app.VLabel_8 = uilabel(app.ExtraParametersPanel_2);
            app.VLabel_8.Text = 'V';
            app.VLabel_9 = uilabel(app.ExtraParametersPanel_2);
            app.VLabel_9.Text = '(1/V)';
            app.V12Label = uilabel(app.ExtraParametersPanel_2);
            app.V12Label.Text = 'V^(1/2)';
            app.VLabel_10 = uilabel(app.ExtraParametersPanel_2);
            app.VLabel_10.Text = '(1/V)';
            app.PHIEditFieldLabel = uilabel(app.ExtraParametersPanel_2);
            app.PHIEditFieldLabel.Text = 'PHI';
            app.PHIEditField = uieditfield(app.ExtraParametersPanel_2, 'numeric');
            app.GAMMAEditFieldLabel = uilabel(app.ExtraParametersPanel_2);
            app.GAMMAEditFieldLabel.Text = 'GAMMA';
            app.GAMMAEditField = uieditfield(app.ExtraParametersPanel_2, 'numeric');
            app.KAPPAEditFieldLabel = uilabel(app.ExtraParametersPanel_2);
            app.KAPPAEditFieldLabel.Text = 'KAPPA';
            app.KAPPAEditField = uieditfield(app.ExtraParametersPanel_2, 'numeric');
            app.VSBEditFieldLabel = uilabel(app.ExtraParametersPanel_2);
            app.VSBEditFieldLabel.Text = 'VSB';
            app.VSBEditField = uieditfield(app.ExtraParametersPanel_2, 'numeric');
            app.THETA2EditFieldLabel = uilabel(app.ExtraParametersPanel_2);
            app.THETA2EditFieldLabel.Text = 'THETA2';
            app.THETA2EditField = uieditfield(app.ExtraParametersPanel_2, 'numeric');
            app.VTOEditField_2Label = uilabel(app.ExtraParametersPanel_2);
            app.VTOEditField_2Label.Text = 'VTO';
            app.VTOEditField_2 = uieditfield(app.ExtraParametersPanel_2, 'numeric');
            app.THETAEditField_2Label = uilabel(app.ExtraParametersPanel_2);
            app.THETAEditField_2Label.Text = 'THETA';
            app.THETAEditField_2 = uieditfield(app.ExtraParametersPanel_2, 'numeric');
            app.UOEditField_2Label = uilabel(app.ExtraParametersPanel_2);
            app.UOEditField_2Label.Text = 'UO';
            app.UOEditField_2 = uieditfield(app.ExtraParametersPanel_2, 'numeric');
            app.TextArea_12 = uitextarea(app.UsersGuidePanel);
            app.TextArea_12.Value = {'For the strong inversion parameters, we don''t have errors or correlation coefficients. The coefficients are GAMMA, KAPPA, PHI and THETA2 (also shown in this Help App). The substrate effect can be specified to obtain the corresponding coefficients.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HelpMOSFETApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end