classdef MOSFETCharacteristics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        SaveasMenu_3                  matlab.ui.container.Menu
        MOSFETIVCharacteristicMenu    matlab.ui.container.Menu
        OutputVoltageMenu             matlab.ui.container.Menu
        ExporttoWorkspaceMenu         matlab.ui.container.Menu
        MOSFETIVCharacteristicMenu_2  matlab.ui.container.Menu
        OutputVoltageMenu_2           matlab.ui.container.Menu
        LoadDataMenu_3                matlab.ui.container.Menu
        MOSFETIVCharacteristicMenu_3  matlab.ui.container.Menu
        OutputVoltageMenu_3           matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        HelpMenu_2                    matlab.ui.container.Menu
        DocumentationMenu             matlab.ui.container.Menu
        XAxisDropDown                 matlab.ui.control.DropDown
        XAxisDropDownLabel            matlab.ui.control.Label
        ResetFiguresButton            matlab.ui.control.Button
        HoldonCheckBox                matlab.ui.control.CheckBox
        ButtonGroup                   matlab.ui.container.ButtonGroup
        LogButton                     matlab.ui.control.RadioButton
        LinButton                     matlab.ui.control.RadioButton
        IOffEditField                 matlab.ui.control.NumericEditField
        IOffEditFieldLabel            matlab.ui.control.Label
        NAveragingEditField           matlab.ui.control.NumericEditField
        NAveragingEditFieldLabel      matlab.ui.control.Label
        REditField                    matlab.ui.control.NumericEditField
        REditFieldLabel               matlab.ui.control.Label
        MeasurementPropertiesPanel    matlab.ui.container.Panel
        ALabel                        matlab.ui.control.Label
        OhmLabel                      matlab.ui.control.Label
        MeasurementOptionsPanel       matlab.ui.container.Panel
        ExtraParametersButton         matlab.ui.control.Button
        FullScaleRangeButton          matlab.ui.control.Button
        CurvefittingButton            matlab.ui.control.Button
        ShowSatLimitsButton           matlab.ui.control.Button
        FastModeCheckBox              matlab.ui.control.CheckBox
        Image                         matlab.ui.control.Image
        LampLabel                     matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        NImyDAQSwitch                 matlab.ui.control.Switch
        NImyDAQPanel                  matlab.ui.container.Panel
        DeviceIDEditField             matlab.ui.control.EditField
        DeviceIDEditFieldLabel        matlab.ui.control.Label
        VGstepsEditField              matlab.ui.control.NumericEditField
        VGstepsEditFieldLabel         matlab.ui.control.Label
        VGmaxEditField                matlab.ui.control.NumericEditField
        VGmaxEditFieldLabel           matlab.ui.control.Label
        VGminEditField                matlab.ui.control.NumericEditField
        VGminEditFieldLabel           matlab.ui.control.Label
        VDstepsEditField              matlab.ui.control.NumericEditField
        VDstepsEditFieldLabel         matlab.ui.control.Label
        VDmaxEditField                matlab.ui.control.NumericEditField
        VDmaxEditFieldLabel           matlab.ui.control.Label
        VDminEditField                matlab.ui.control.NumericEditField
        VDminEditFieldLabel           matlab.ui.control.Label
        InputSignalPropertiesPanel    matlab.ui.container.Panel
        SweepCheckBox                 matlab.ui.control.CheckBox
        VLabel_4                      matlab.ui.control.Label
        VLabel_3                      matlab.ui.control.Label
        VLabel_2                      matlab.ui.control.Label
        VLabel                        matlab.ui.control.Label
        MeasurementStatusPanel        matlab.ui.container.Panel
        MeasurementStatusGauge        matlab.ui.control.LinearGauge
        MeasurementStatusGaugeLabel   matlab.ui.control.Label
        StopSimulationButton          matlab.ui.control.Button
        RunMeasurementsButton         matlab.ui.control.Button
        NImyDAQPortsPanel             matlab.ui.container.Panel
        VODropDown                    matlab.ui.control.DropDown
        VODropDownLabel               matlab.ui.control.Label
        VGDropDown                    matlab.ui.control.DropDown
        VGDropDownLabel               matlab.ui.control.Label
        VDDropDown                    matlab.ui.control.DropDown
        VDDropDownLabel               matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        ExportConfigMenu              matlab.ui.container.Menu
        HelpMenu_3                    matlab.ui.container.Menu
        DocumentationMenu_2           matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        RenameMenu                    matlab.ui.container.Menu
        SaveasMenu                    matlab.ui.container.Menu
        ExportDatatoWorkspaceMenu     matlab.ui.container.Menu
        LoadDataMenu                  matlab.ui.container.Menu
        HideCurveMenu                 matlab.ui.container.Menu
        ShowCurveMenu                 matlab.ui.container.Menu
        ContextMenu3                  matlab.ui.container.ContextMenu
        RenameMenu_2                  matlab.ui.container.Menu
        SaveasMenu_2                  matlab.ui.container.Menu
        ExportDatatoWorkspaceMenu_2   matlab.ui.container.Menu
        LoadDataMenu_2                matlab.ui.container.Menu
        HideCurveMenu_2               matlab.ui.container.Menu
        ShowCurveMenu_2               matlab.ui.container.Menu
        ContextMenu4                  matlab.ui.container.ContextMenu
        ExportFitParametersMenu       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        HelpAppInstance % For the HelpApp
        DocumentationAppInstance % For the DocumentationApp
        StopSimulation = false; % Variable para controlar si se detiene la simulación
        SatLinesVisible = false; % Estado de las líneas de saturación
        SatLinesHandles % Handles para las líneas de saturación
        VLoadedData % Estructura para almacenar las variables cargadas
        ILoadedData % Estructura para almacenar las variables cargadas
        VisibleCurves % Mapa para almacenar la visibilidad de las curvas
    end

    properties (Access = public)
        V_G           % Datos de V_G (Nx1)
        V_D           % Datos de V_D (Nx1 o un valor fijo)
        I_D_mean      % Datos medidos de corriente (Nx1)
        V_O_mean
        CurveFitDialog  % Instancia de la ventana de diálogo
        StrInvDialog
        StrInv2Dialog
    end

    methods (Access = public)
        function Id_est = mosfetModel(app, params, xData)
            % params: [Vth, K, lambda] (ejemplo)
            % xData: matriz Nx2, donde xData(:,1) = Vg, xData(:,2) = Vd
            % Devuelve Id_est del mismo tamaño que xData(:,1)

            Vth    = params(1);
            K      = params(2);  % equivale a mu*Cox*(W/L)
            lambda = params(3);  % factor de canal corto (opcional)

            app.V_G = xData(:,1);
            app.V_D = xData(:,2);

            Id_est = zeros(size(app.V_G));

            for i = 1:length(app.V_G)
                VGS = app.V_G(i); 
                VDS = app.V_D(i);

                if (VGS < Vth)
                    % Corte
                    Id_est(i) = 0;  
                else
                    if VDS < (VGS - Vth)
                        % Triodo
                        Id_est(i) = K * ((VGS - Vth)*VDS - 0.5*(VDS^2));
                    else
                        % Saturación
                        Id_est(i) = 0.5 * K * (VGS - Vth)^2 * (1 + lambda*VDS);
                    end
                end
            end
        end
    end

    methods (Access = public)
        function [f] = fopt2(app, p, uo, theta, vto, d, vsb)
            % Aquí la hemos llamado “fopt2” pero el nombre es arbitrario.
            % Lo único importante es que este en un fichero “.m” con el mismo nombre que la función. 
            % 
            % Obtenemos los parámetros individuales del vector de parámetros
            GAMMA=p(1);
            PHI=p(2);
            KAPPA=p(3);
            THETA2=p(4);
            % 
            % y los datos experimentales
            vds=d(:,1);
            idx=d(:,2);
            % 
            % Y suponemos conocidos los parámetros obtenidos previamente que se pasan a
            % esta función vía el vector 'p1'
            vgs = mean(app.V_G);
            eox = 3.453e-13;
            esi = 1.03592e-12;
            tox = 100e-9;
            w_l = 100;
            q = 1.602e-19;
            nsub = 2.74e16;
            Leff=5e-6;
            % Calculamos la intensidad según el modelo analítico de nivel
            fb= GAMMA/(4*sqrt(PHI+vsb)); % Parámetro ~ al efecto substrato
            vt=vto+GAMMA*(sqrt(vsb+PHI)-sqrt(PHI)); % VT con efecto substrato
            ueff=uo/(1+theta*(vgs-vt)); % Movilidad efectiva
            beta=ueff*eox/tox*w_l; % Beta = u Cox' W/L
            vdsat=(vgs-vt)/(1+fb); % VDS'
            vde=min(vds,vdsat);
            id=beta*(vgs-vt-(1+fb)/2*vde).*vde; % Cálculo de ID
            sat=find(vds>vdsat);
            % Encontramos el índice de los valores de VDS>VDS'
            deltaL=sqrt(2*esi/(q*nsub))*sqrt(KAPPA*(vds(sat)-vdsat));
            % Cálculo de la modulación de la longitud del canal
            id(sat)=id(sat)./(1-deltaL/Leff);
            % Efecto de la modulación en la intensidad en saturación
            f = (id - idx);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.REditField.Value = 1000; % Resistance value
            app.VDminEditField.Value = 0;          % Voltaje mínimo para VP
            app.VDmaxEditField.Value = 5;        % Voltaje máximo para VP
            app.VDstepsEditField.Value = 25;       % Número de pasos para VP
            app.VGminEditField.Value = 2.7;          % Voltaje fijo para VN
            app.VGmaxEditField.Value = 2.7;          % Voltaje fijo para VN
            app.VGstepsEditField.Value = 25;        % Pasos
            app.NAveragingEditField.Value = 1;
            app.Lamp.Color = 'red';
            app.LampLabel.Text = 'Disconnected';
            % Configurar los valores iniciales para los dropdowns
            app.VDDropDown.Items = {'ao0', 'ao1'};
            app.VGDropDown.Items = {'ao0', 'ao1'};
            app.VODropDown.Items = {'ai0', 'ai1'};
        
            % Configurar los valores predeterminados
            app.VDDropDown.Value = 'ao0'; % Predeterminado para VP
            app.VGDropDown.Value = 'ao1'; % Predeterminado para VN
            app.VODropDown.Value = 'ai1'; % Predeterminado para VO

            app.DeviceIDEditField.Value = 'myDAQ1'; % Predeterminado para DeviceID

            app.UIFigure.Resize = 'off';
        end

        % Button pushed function: RunMeasurementsButton
        function RunMeasurementsButtonPushed(app, event)
            if string(app.VGDropDown.Value) == string(app.VDDropDown.Value)
                uialert(app.UIFigure, 'The channels VD and VG cannot be the same.', 'Channel Error');
                return;
            elseif app.NImyDAQSwitch.Value == "Off"
                uialert(app.UIFigure, 'Turn on the NI myDAQ switch.', 'Warning');
                return;
            elseif any([app.VDminEditField.Value, app.VDmaxEditField.Value, app.VGminEditField.Value, app.VGmaxEditField.Value]<-10) ||any([app.VDminEditField.Value, app.VDmaxEditField.Value, app.VGminEditField.Value, app.VGmaxEditField.Value]>10)
                uialert(app.UIFigure, 'Channels configured for -10 to +10 Volts.', 'Error');
                return;
            elseif ~app.HoldonCheckBox.Value
                % Reestablecer líneas de saturación
                app.SatLinesHandles = []; % Limpiar los handles
                % Cambiar el estado
                app.SatLinesVisible = false;
                app.ShowSatLimitsButton.Text = 'Show Sat Limits'; % Cambiar texto del botón
            end

            % Configuración de parámetros de barrido
            VDmin = app.VDminEditField.Value;          % Voltaje mínimo para VP
            VDmax = app.VDmaxEditField.Value;          % Voltaje máximo para VP
            VDsteps = app.VDstepsEditField.Value;      % Número de pasos para VP
            VGmin = app.VGminEditField.Value;          % Voltaje fijo para VN
            VGmax = app.VGmaxEditField.Value;          % Voltaje fijo para VN
            VGsteps = app.VGstepsEditField.Value;      % Pasos
            VG = app.VGDropDown.Value;                 % Canal VP
            VD = app.VDDropDown.Value;                 % Canal VN
            VO = app.VODropDown.Value;                 % Canal VO
            IOff = app.IOffEditField.Value;            % IOff
            NAveraging = app.NAveragingEditField.Value;

            if VDsteps < 0 || mod(VDsteps, 1) ~= 0 || VGsteps < 0 || mod(VGsteps, 1) ~= 0
                uialert(app.UIFigure, 'VDsteps and VGsteps must be positive values and integers.', 'Error');
                return;
            elseif NAveraging < 0 || mod(NAveraging, 1) ~= 0
                uialert(app.UIFigure, 'NAveraging must be a positive value and an integer.', 'Error')
            end
            
            % Configuración del dispositivo DAQ
            dq = daq("ni");
            
            % Añadir los canales de salida seleccionados
            addoutput(dq, app.DeviceIDEditField.Value, str2double(VD(end)), "Voltage"); % VD
            addoutput(dq, app.DeviceIDEditField.Value, str2double(VG(end)), "Voltage"); % VG
            
            % Añadir el canal de entrada seleccionado
            addinput(dq, app.DeviceIDEditField.Value, VO, "Voltage"); % VO
            
            % Inicializar barra de progreso
            app.MeasurementStatusGauge.Value = 0;  % Reiniciar progreso
            
            % Reiniciar la bandera de detención
            app.StopSimulation = false;

            if app.FastModeCheckBox.Value
                uialert(app.UIFigure, 'Fast Mode is not available.', 'Error')
                return
            else
                if app.SweepCheckBox.Value

                    if ~app.HoldonCheckBox.Value
                        uialert(app.UIFigure, 'Turn on the Hold on check box in order to represent multiple curves.', 'Error')
                        return
                    end

                    % Crear vectores de barrido
                    app.V_D = linspace(VDmin, VDmax, VDsteps);
                    app.V_G = linspace(VGmin, VGmax, VGsteps);
                    
                    % Malla 2D de combinaciones (filas=VG, columnas=VD)
                    [VDm, VGm] = meshgrid(app.V_D, app.V_G);
                    
                    % Aplanar para el DAQ
                    VDv = VDm(:);
                    VGv = VGm(:);

                    if strcmp(app.XAxisDropDown.Value, 'V_DS')
                        nPts = numel(VDv);
                    else
                        nPts = numel(VGv);
                    end

                    Vout_v = zeros(NAveraging,1);
                    Vout_v_mean = zeros(nPts,1);
                    for k = 1:nPts
                        if app.StopSimulation
                            break;
                        end
                        for j = 1:NAveraging
                            write(dq, [VDv(k), VGv(k)]);
                            data = read(dq, 1);
                            Vout_v(j) = data.Variables;
                        end
                        Vout_v_mean(k) = mean(Vout_v);

                        progress = (k/nPts)*100;
                        app.MeasurementStatusGauge.Value = progress;  % Actualizar valor de la barra
                        drawnow;  % Refrescar la interfaz gráfica
                    end

                    Id_v = (Vout_v_mean - VDv)/app.REditField.Value - IOff;
                    
                    if strcmp(app.XAxisDropDown.Value, 'V_DS')
                        app.V_O_mean = reshape(Vout_v_mean, size(VDm));
                        app.V_D = reshape(VDv, size(VDm));
                        app.V_G = reshape(VGv, size(VDm));
                        app.I_D_mean = reshape(Id_v, size(VDm));
                        for row = 1:size(VDm,1)
                            plot(app.UIAxes2, app.V_D(row,:), app.V_O_mean(row,:), 'LineWidth',1.5);
                            plot(app.UIAxes, app.V_D(row,:), app.I_D_mean(row,:), 'LineWidth',1.5);
                        end
                        xlabel(app.UIAxes, 'V_{DS} [V]');
                        xlabel(app.UIAxes2, 'V_{DS} [V]');
                    else
                        app.V_O_mean = reshape(Vout_v_mean, size(VGm));
                        app.V_D = reshape(VDv, size(VGm));
                        app.V_G = reshape(VGv, size(VGm));
                        app.I_D_mean = reshape(Id_v, size(VGm));
                        for col = 1:size(VGm,2)
                            plot(app.UIAxes2, app.V_G(:,col), app.V_O_mean(:,col), 'LineWidth',1.5);
                            plot(app.UIAxes, app.V_G(:,col), app.I_D_mean(:,col), 'LineWidth',1.5);
                        end
                        xlabel(app.UIAxes, 'V_{GS} [V]');
                        xlabel(app.UIAxes2, 'V_{GS} [V]');
                    end
                    ylabel(app.UIAxes2, 'V_O [V]')
                    title(app.UIAxes2, 'Output Voltage')
                    legend(app.UIAxes2, 'Location', 'best')
                    ylabel(app.UIAxes, 'I_D [A]')
                    title(app.UIAxes, 'MOSFET I-V Characteristic')
                    legend(app.UIAxes, 'Location', 'best')

                else
                    % Calcular la cantidad total de pasos
                    Vsteps = max(VDsteps, VGsteps);
                    
                    % Crear los vectores ajustados
                    app.V_D = linspace(VDmin, VDmax, Vsteps)';
                    app.V_G = linspace(VGmin, VGmax, Vsteps)';
                
                    % Vector diferencial de voltaje
                    V_Diff = app.V_D - app.V_G;
                
                    % Inicializar variables para almacenar promedios
                    app.V_O_mean = zeros(size(V_Diff));  % Voltaje de salida promedio
                
                    V_O_values = zeros(NAveraging, 1);  % Almacenar mediciones para el promedio
                
                    for i = 1:length(V_Diff)
                        if app.StopSimulation
                                break;
                        end
                
                        for j = 1:NAveraging
                            % Escribir valores de VD y VG
                            write(dq, [app.V_D(i), app.V_G(i)]);
                
                            % Leer el voltaje de salida (V0)
                            data = read(dq, 1);  % Leer 1 muestra
                            V_O_values(j) = data.Variables;  % Almacenar la medición individual
                        end
                
                        % Calcular el promedio para el punto actual
                        app.V_O_mean(i) = mean(V_O_values);
                
                        % Actualizar la barra de progreso
                        progress = (i / length(V_Diff)) * 100;  % Calcular porcentaje de avance
                        app.MeasurementStatusGauge.Value = progress;  % Actualizar valor de la barra
                        drawnow;  % Refrescar la interfaz gráfica
                    end

                    % Cálculo de la corriente del diodo (I_D)
                    if strcmp(app.XAxisDropDown.Value, 'V_DS')
                        app.I_D_mean = (app.V_O_mean - app.V_D) / app.REditField.Value - IOff; % Si quito readwrite, dejar este
                        plot(app.UIAxes2, app.V_D, app.V_O_mean);
                        xlabel(app.UIAxes2, 'V_{DS} [V]');
                        plot(app.UIAxes, app.V_D, app.I_D_mean);
                        xlabel(app.UIAxes, 'V_{DS} [V]');
                    elseif strcmp(app.XAxisDropDown.Value, 'V_GS')
                        app.I_D_mean = (app.V_O_mean - VDmin) / app.REditField.Value - IOff;
                        plot(app.UIAxes2, app.V_G, app.V_O_mean);
                        xlabel(app.UIAxes2, 'V_{GS} [V]');
                        plot(app.UIAxes, app.V_G, app.I_D_mean);
                        xlabel(app.UIAxes, 'V_{GS} [V]');
                    end
                    ylabel(app.UIAxes2, 'V_O [V]')
                    title(app.UIAxes2, 'Output Voltage')
                    legend(app.UIAxes2, 'Location', 'best')
                    ylabel(app.UIAxes, 'I_D [A]')
                    title(app.UIAxes, 'MOSFET I-V Characteristic')
                    legend(app.UIAxes, 'Location', 'best')

                end
            end


            % Eliminar canales
            removechannel(dq, 1:length(dq.Channels));
        end

        % Value changed function: HoldonCheckBox
        function HoldonCheckBoxValueChanged(app, event)
            if app.HoldonCheckBox.Value
                hold(app.UIAxes, 'on');  % Enable hold on
                hold(app.UIAxes2, 'on');
            else
                hold(app.UIAxes, 'off'); % Disable hold on
                hold(app.UIAxes2, 'off');
            end
        end

        % Value changed function: NImyDAQSwitch
        function NImyDAQSwitchValueChanged(app, event)
            % Obtener el estado del switch (On/Off)
            switchState = app.NImyDAQSwitch.Value;
            
            if strcmp(switchState, 'On') % Si el switch está en "On"
                % Limpiamos el registro
                clear devices;
                % Buscar dispositivos DAQ conectados
                devices = daqlist;
                
                if isempty(devices) % Si no se detectan dispositivos
                    app.Lamp.Color = 'red';    % Cambiar el color de la lámpara a rojo
                    app.LampLabel.Text = 'Disconnected'; % Mostrar "Disconnected"
                    uialert(app.UIFigure, 'No devices have been found. Make sure the NI myDAQ is connected properly. If the error persists, restart MATLAB and try again.', 'Error')
                else
                    app.Lamp.Color = 'green';  % Cambiar el color de la lámpara a verde
                    app.LampLabel.Text = 'Connected';   % Mostrar "Connected"
                end
                
            else % Si el switch está en "Off"
                app.Lamp.Color = 'red';    % Cambiar el color de la lámpara a rojo
                app.LampLabel.Text = 'Disconnected'; % Mostrar "Disconnected"
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            % Callback function para el Radio Button Group
            switch selectedButton.Text
                case 'Lin'
                    % Cambiar el eje x a escala lineal
                    set(app.UIAxes, 'YScale', 'linear');
                case 'Log'
                    % Cambiar el eje x a escala logarítmica
                    set(app.UIAxes, 'YScale', 'log');
            end
        end

        % Button pushed function: StopSimulationButton
        function StopSimulationButtonPushed(app, event)
            app.StopSimulation = true;
        end

        % Button pushed function: ResetFiguresButton
        function ResetFiguresButtonPushed(app, event)
             % --- Para app.UIAxes ---
            % (Opcional) Guardar el estado de "hold"
            holdState1 = ishold(app.UIAxes);
            
            % Eliminar todos los objetos gráficos del axes (sin resetear sus propiedades)
            delete(app.UIAxes.Children);
            
            % Reestablecer el grid y el minor grid
            grid(app.UIAxes, 'on');
            app.UIAxes.GridLineWidth = 0.5;
            app.UIAxes.GridAlpha = 0.15;
            app.UIAxes.MinorGridAlpha = 0.25;
            app.UIAxes.MinorGridLineWidth = 0.5;
            app.UIAxes.XMinorGrid = 'on';
            app.UIAxes.YMinorGrid = 'on';
            
            % Reestablecer las etiquetas y título
            xlabel(app.UIAxes, 'V_D [V]');
            ylabel(app.UIAxes, 'I_D [A]');
            title(app.UIAxes, 'MOSFET I-V Characteristic');

            % Reestablecer líneas de saturación
            app.SatLinesHandles = []; % Limpiar los handles
    
            % Cambiar el estado
            app.SatLinesVisible = false;
            app.ShowSatLimitsButton.Text = 'Show Sat Limits'; % Cambiar texto del botón
            
            % Si previamente estaba en hold, reactivar hold
            if holdState1
                hold(app.UIAxes, 'on');
            end
            
            % --- Para app.UIAxes2 ---
            holdState2 = ishold(app.UIAxes2);
            
            delete(app.UIAxes2.Children);
            
            grid(app.UIAxes2, 'on');
            app.UIAxes2.GridLineWidth = 0.5;
            app.UIAxes2.GridAlpha = 0.15;
            app.UIAxes2.MinorGridAlpha = 0.25;
            app.UIAxes2.MinorGridLineWidth = 0.5;
            app.UIAxes2.XMinorGrid = 'on';
            app.UIAxes2.YMinorGrid = 'on';
            
            xlabel(app.UIAxes2, 'V_D [V]');
            ylabel(app.UIAxes2, 'V_O [V]');
            title(app.UIAxes2, 'Output Voltage [V]');
            
            if holdState2
                hold(app.UIAxes2, 'on');
            end
        end

        % Menu selected function: HelpMenu_2
        function HelpMenu_2Selected(app, event)
            run('HelpMOSFETApp.mlapp')
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            run('DocumentationMOSFETApp.mlapp')
        end

        % Menu selected function: HelpMenu_3
        function HelpMenu_3Selected(app, event)
            run('HelpMOSFETApp.mlapp')
        end

        % Menu selected function: DocumentationMenu_2
        function DocumentationMenu_2Selected(app, event)
            run('DocumentationMOSFETApp.mlapp')
        end

        % Button pushed function: ShowSatLimitsButton
        function ShowSatLimitsButtonPushed(app, event)
            if ~app.HoldonCheckBox.Value
                uialert(app.UIFigure, ...
                    'Make sure the Hold On checkbox is activated to represent multiple curves.', ...
                    'Warning');
                return; % Salir del callback si la condición no se cumple
            elseif isempty(app.V_D) || ~isprop(app, 'V_D')
                uialert(app.UIFigure, ...
                    'Represent a curve in order to show the saturation limits.', ...
                    'Error');
                return;
            end

            if ~app.SatLinesVisible
                if app.SweepCheckBox.Value
                    if strcmp(app.XAxisDropDown.Value, 'V_DS')
                        V_O_sat_pos = linspace(10, 10, length(app.V_D));
                        V_O_sat_neg = linspace(-10, -10, length(app.V_D));
                        for row = 1:size(app.V_D,1)
                            isat = (10 - app.V_D(row,:)) / app.REditField.Value - app.IOffEditField.Value;
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes2, app.V_D(row,:), V_O_sat_pos, 'k--', 'DisplayName', 'Vsatp');
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes2, app.V_D(row,:), V_O_sat_neg, 'k--', 'DisplayName', 'Vsatn');
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes, app.V_D(row,:), isat, 'k--', 'DisplayName', 'Isatp');
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes, app.V_D(row,:), -isat, 'k--', 'DisplayName', 'Isatn');
                        end
                    elseif strcmp(app.XAxisDropDown.Value, 'V_GS')
                        V_O_sat_pos = linspace(10, 10, length(app.V_G));
                        V_O_sat_neg = linspace(-10, -10, length(app.V_G));
                        % isat = (10 - app.VDminEditField.Value) / app.REditField.Value - app.IOffEditField.Value;
                        % I_D_sat_pos = linspace(isat, isat, length(app.V_G));
                        % I_D_sat_neg = linspace(-isat, -isat, length(app.V_G));
                        for col = 1:size(app.V_G,2)
                            isat = (10 - app.V_D(1,col)) / app.REditField.Value - app.IOffEditField.Value;
                            I_D_sat_pos = repmat(isat,1,length(app.V_G(:,col)));
                            I_D_sat_neg = repmat(-isat,1, length(app.V_G(:,col)));
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes2, app.V_G(:,col), V_O_sat_pos, 'k--', 'DisplayName', 'Vsatp');
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes2, app.V_G(:,col), V_O_sat_neg, 'k--', 'DisplayName', 'Vsatn');
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes, app.V_G(:,col), I_D_sat_pos, 'k--', 'DisplayName', 'Isatp');
                            app.SatLinesHandles(end + 1) = plot(app.UIAxes, app.V_G(:,col), I_D_sat_neg, 'k--', 'DisplayName', 'Isatn');
                        end
                    end
                else
                    if strcmp(app.XAxisDropDown.Value, 'V_DS')
                        V_O_sat_pos = linspace(10, 10, length(app.V_D));
                        V_O_sat_neg = linspace(-10, -10, length(app.V_D));
                        isat = (10 - app.V_D) / app.REditField.Value - app.IOffEditField.Value;
                        app.SatLinesHandles(1) = plot(app.UIAxes2, app.V_D, V_O_sat_pos, 'k--', 'DisplayName', 'Vsatp');
                        app.SatLinesHandles(2) = plot(app.UIAxes2, app.V_D, V_O_sat_neg, 'k--', 'DisplayName', 'Vsatn');
                        app.SatLinesHandles(3) = plot(app.UIAxes, app.V_D, isat, 'k--', 'DisplayName', 'Isatp');
                        app.SatLinesHandles(4) = plot(app.UIAxes, app.V_D, -isat, 'k--', 'DisplayName', 'Isatn');
                    elseif strcmp(app.XAxisDropDown.Value, 'V_GS')
                        V_O_sat_pos = linspace(10, 10, length(app.V_G));
                        V_O_sat_neg = linspace(-10, -10, length(app.V_G));
                        isat = (10 - app.VDminEditField.Value) / app.REditField.Value - app.IOffEditField.Value;
                        I_D_sat_pos = linspace(isat, isat, length(app.V_G));
                        I_D_sat_neg = linspace(-isat, -isat, length(app.V_G));
                        app.SatLinesHandles(1) = plot(app.UIAxes2, app.V_G, V_O_sat_pos, 'k--', 'DisplayName', 'Vsatp');
                        app.SatLinesHandles(2) = plot(app.UIAxes2, app.V_G, V_O_sat_neg, 'k--', 'DisplayName', 'Vsatn');
                        app.SatLinesHandles(3) = plot(app.UIAxes, app.V_G, I_D_sat_pos, 'k--', 'DisplayName', 'Isatp');
                        app.SatLinesHandles(4) = plot(app.UIAxes, app.V_G, I_D_sat_neg, 'k--', 'DisplayName', 'Isatn');
                    end
                end

                % Actualizar leyenda
                legend(app.UIAxes, 'show')
                legend(app.UIAxes2, 'show')

                % Cambiar el estado
                app.SatLinesVisible = true;
                app.ShowSatLimitsButton.Text = 'Hide Sat Limits'; % Cambiar texto del botón
            else
                % Ocultar las líneas de saturación
                delete(app.SatLinesHandles); % Eliminar las líneas de la gráfica
                app.SatLinesHandles = []; % Limpiar los handles
        
                % Cambiar el estado
                app.SatLinesVisible = false;
                app.ShowSatLimitsButton.Text = 'Show Sat Limits'; % Cambiar texto del botón
            end
        end

        % Menu selected function: RenameMenu
        function RenameMenuSelected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to rename.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'single', ...
                                 'PromptString', 'Select a curve to rename:', ...
                                 'Name', 'Rename Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end
            
            % Solicitar el nuevo nombre
            newName = inputdlg('Enter a new name for the curve:', 'Rename Curve', [1 50]);
            if isempty(newName) || isempty(newName{1}) % Si se cancela o no se ingresa un nombre
                return;
            end
            
            % Asignar el nuevo nombre a la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.DisplayName = newName{1};
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes, 'show');
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            % Open a save dialog box
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.fig'}, 'Save Plot As');
            
            % If user cancels, do nothing
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % Build the full file name
            fullFileName = fullfile(path, file);
            
            % Get the file extension
            [~, ~, ext] = fileparts(fullFileName);
            
            % Save the current axes as the selected format
            switch ext
                case '.png'
                    exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
                case '.jpg'
                    exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
                case '.pdf'
                    exportgraphics(app.UIAxes, fullFileName);
                case '.fig'
                    % Save as a MATLAB figure
                    fig = figure('Visible', 'off'); % Create an invisible figure
                    axCopy = copyobj(app.UIAxes, fig); % Copy the axes to the new figure
                    set(axCopy, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]); % Resize
                    savefig(fig, fullFileName); % Save the figure
                    close(fig); % Close the temporary figure
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
            end
        end

        % Menu selected function: ExportDatatoWorkspaceMenu
        function ExportDatatoWorkspaceMenuSelected(app, event)
            % Obtener el objeto gráfico activo
            srcObject = app.UIAxes.Children; % Obtiene todos los objetos del eje
        
            % Iterar sobre los objetos del eje (por si hay varios gráficos)
            for i = 1:numel(srcObject)
                if strcmp(srcObject(i).Type, 'line') % Verificar si es una línea
                    % Extraer datos X e Y de la línea
                    xData = srcObject(i).XData;
                    yData = srcObject(i).YData;
                    
                    % Crear una matriz con dos columnas: X y Y
                    data = [xData(:), yData(:)]; % Asegurarse de que sean columnas
                    
                    % Obtener el nombre descriptivo (DisplayName)
                    graphName = srcObject(i).DisplayName;
                    
                    % Validar el nombre para que sea un nombre de variable válido
                    if isempty(graphName) % Si no hay DisplayName, usar un nombre genérico
                        graphName = sprintf('ExportedData_%d', i);
                    else
                        graphName = matlab.lang.makeValidName(graphName); % Asegurarse de que sea válido
                    end
                    
                    % Exportar al Workspace con el nombre descriptivo
                    assignin('base', graphName, data);
                end
            end
            
            % Notificación al usuario
            uialert(app.UIFigure, 'Data Exported to Workspace', 'Exportation completed');
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            % Abre el diálogo para seleccionar un archivo .mat
            [file, path] = uigetfile('*.mat', 'Select a MAT-file');
            if isequal(file, 0) % Si el usuario cancela
                return;
            end
            fullPath = fullfile(path, file);
            
            % Carga las variables del archivo .mat
            vars = whos('-file', fullPath); % Obtiene información sobre las variables en el archivo
            if isempty(vars)
                uialert(app.UIFigure, 'The selected MAT-file is empty.', 'No Data');
                return;
            end
            
            % Muestra un cuadro de diálogo para seleccionar variables
            varNames = {vars.name};
            [indx, tf] = listdlg('ListString', varNames, ...
                             'SelectionMode', 'multiple', ...
                             'PromptString', 'Select variables to load', ...
                             'Name', 'Load Variables');
            if tf == 0 % Si el usuario cancela
                return;
            end
            
            % Cargar las variables seleccionadas al workspace
            selectedVars = varNames(indx);
            loadedData = load(fullPath, selectedVars{:});
            
            % Guardar las variables cargadas en una propiedad del app (opcional)
            app.ILoadedData = loadedData; % Crear una propiedad `LoadedData` en el app

            % Extraer datos de la estructura cargada
            data = app.ILoadedData; % Supongamos que contiene las variables seleccionadas
            fieldNames = fieldnames(data); % Obtener los nombres de las variables cargadas
            
            matrixData = data.(fieldNames{1}); % Supongamos que es la primera variable
            x = matrixData(:, 1); % Columna 1: Eje X
            y = matrixData(:, 2); % Columna 2: Eje Y

            plot(app.UIAxes, x, y, 'DisplayName', 'ILoaded')
            legend(app.UIAxes, 'show')
        end

        % Menu selected function: HideCurveMenu
        function HideCurveMenuSelected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to hide.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'multiple', ...
                                 'PromptString', 'Select a curve to hide:', ...
                                 'Name', 'Hide Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end

            % Ocultar la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.Visible = 'off';
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes, 'show');
        end

        % Menu selected function: ShowCurveMenu
        function ShowCurveMenuSelected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to show.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'multiple', ...
                                 'PromptString', 'Select a curve to show:', ...
                                 'Name', 'Show Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end

            % Ocultar la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.Visible = 'on';
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes, 'show');
        end

        % Menu selected function: RenameMenu_2
        function RenameMenu_2Selected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes2, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to rename.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'single', ...
                                 'PromptString', 'Select a curve to rename:', ...
                                 'Name', 'Rename Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end
            
            % Solicitar el nuevo nombre
            newName = inputdlg('Enter a new name for the curve:', 'Rename Curve', [1 50]);
            if isempty(newName) || isempty(newName{1}) % Si se cancela o no se ingresa un nombre
                return;
            end
            
            % Asignar el nuevo nombre a la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.DisplayName = newName{1};
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes2, 'show');
        end

        % Menu selected function: SaveasMenu_2
        function SaveasMenu_2Selected(app, event)
            % Open a save dialog box
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.fig'}, 'Save Plot As');
            
            % If user cancels, do nothing
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % Build the full file name
            fullFileName = fullfile(path, file);
            
            % Get the file extension
            [~, ~, ext] = fileparts(fullFileName);
            
            % Save the current axes as the selected format
            switch ext
                case '.png'
                    exportgraphics(app.UIAxes2, fullFileName, 'Resolution', 300);
                case '.jpg'
                    exportgraphics(app.UIAxes2, fullFileName, 'Resolution', 300);
                case '.pdf'
                    exportgraphics(app.UIAxes2, fullFileName);
                case '.fig'
                    % Save as a MATLAB figure
                    fig = figure('Visible', 'off'); % Create an invisible figure
                    axCopy = copyobj(app.UIAxes2, fig); % Copy the axes to the new figure
                    set(axCopy, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]); % Resize
                    savefig(fig, fullFileName); % Save the figure
                    close(fig); % Close the temporary figure
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
            end
        end

        % Menu selected function: ExportDatatoWorkspaceMenu_2
        function ExportDatatoWorkspaceMenu_2Selected(app, event)
            % Obtener el objeto gráfico activo
            srcObject = app.UIAxes2.Children; % Obtiene todos los objetos del eje
        
            % Iterar sobre los objetos del eje (por si hay varios gráficos)
            for i = 1:numel(srcObject)
                if strcmp(srcObject(i).Type, 'line') % Verificar si es una línea
                    % Extraer datos X e Y de la línea
                    xData = srcObject(i).XData;
                    yData = srcObject(i).YData;
                    
                    % Crear una matriz con dos columnas: X y Y
                    data = [xData(:), yData(:)]; % Asegurarse de que sean columnas
                    
                    % Obtener el nombre descriptivo (DisplayName)
                    graphName = srcObject(i).DisplayName;
                    
                    % Validar el nombre para que sea un nombre de variable válido
                    if isempty(graphName) % Si no hay DisplayName, usar un nombre genérico
                        graphName = sprintf('ExportedData_%d', i);
                    else
                        graphName = matlab.lang.makeValidName(graphName); % Asegurarse de que sea válido
                    end
                    
                    % Exportar al Workspace con el nombre descriptivo
                    assignin('base', graphName, data);
                end
            end
            
            % Notificación al usuario
            uialert(app.UIFigure, 'Data Exported to Workspace', 'Exportation completed');
        end

        % Menu selected function: LoadDataMenu_2
        function LoadDataMenu_2Selected(app, event)
            % Abre el diálogo para seleccionar un archivo .mat
            [file, path] = uigetfile('*.mat', 'Select a MAT-file');
            if isequal(file, 0) % Si el usuario cancela
                return;
            end
            fullPath = fullfile(path, file);
            
            % Carga las variables del archivo .mat
            vars = whos('-file', fullPath); % Obtiene información sobre las variables en el archivo
            if isempty(vars)
                uialert(app.UIFigure, 'The selected MAT-file is empty.', 'No Data');
                return;
            end
            
            % Muestra un cuadro de diálogo para seleccionar variables
            varNames = {vars.name};
            [indx, tf] = listdlg('ListString', varNames, ...
                             'SelectionMode', 'multiple', ...
                             'PromptString', 'Select variables to load', ...
                             'Name', 'Load Variables');
            if tf == 0 % Si el usuario cancela
                return;
            end
            
            % Cargar las variables seleccionadas al workspace
            selectedVars = varNames(indx);
            loadedData = load(fullPath, selectedVars{:});
            
            % Guardar las variables cargadas en una propiedad del app (opcional)
            app.ILoadedData = loadedData; % Crear una propiedad `LoadedData` en el app

            % Extraer datos de la estructura cargada
            data = app.ILoadedData; % Supongamos que contiene las variables seleccionadas
            fieldNames = fieldnames(data); % Obtener los nombres de las variables cargadas
            
            matrixData = data.(fieldNames{1}); % Supongamos que es la primera variable
            x = matrixData(:, 1); % Columna 1: Eje X
            y = matrixData(:, 2); % Columna 2: Eje Y

            plot(app.UIAxes2, x, y, 'DisplayName', 'ILoaded')
            legend(app.UIAxes2, 'show')
        end

        % Menu selected function: HideCurveMenu_2
        function HideCurveMenu_2Selected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes2, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to hide.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'multiple', ...
                                 'PromptString', 'Select a curve to hide:', ...
                                 'Name', 'Hide Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end

            % Ocultar la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.Visible = 'off';
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes2, 'show');
        end

        % Menu selected function: ShowCurveMenu_2
        function ShowCurveMenu_2Selected(app, event)
            % Obtener las gráficas en el UIAxes
            lines = findobj(app.UIAxes2, 'Type', 'line'); % Encuentra todas las líneas en el eje
            
            % Verificar si hay líneas disponibles para renombrar
            if isempty(lines)
                uialert(app.UIFigure, 'No curves available to show.', 'Error');
                return;
            end
            
            % Crear un cuadro de diálogo para seleccionar la gráfica a renombrar
            graphNames = get(lines, 'DisplayName'); % Obtiene los nombres actuales de las gráficas
            if ischar(graphNames) % Si solo hay una gráfica, convierte el nombre en celda
                graphNames = {graphNames};
            end
            
            [indx, tf] = listdlg('ListString', graphNames, ...
                                 'SelectionMode', 'multiple', ...
                                 'PromptString', 'Select a curve to show:', ...
                                 'Name', 'Show Curve');
            
            if ~tf % Si el usuario cancela
                return;
            end

            % Ocultar la gráfica seleccionada
            selectedLine = lines(indx); % Línea seleccionada
            selectedLine.Visible = 'on';
            
            % Actualizar la leyenda (si hay una activa)
            legend(app.UIAxes2, 'show');
        end

        % Menu selected function: MOSFETIVCharacteristicMenu
        function MOSFETIVCharacteristicMenuSelected(app, event)
            % Open a save dialog box
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.fig'}, 'Save Plot As');
            
            % If user cancels, do nothing
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % Build the full file name
            fullFileName = fullfile(path, file);
            
            % Get the file extension
            [~, ~, ext] = fileparts(fullFileName);
            
            % Save the current axes as the selected format
            switch ext
                case '.png'
                    exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
                case '.jpg'
                    exportgraphics(app.UIAxes, fullFileName, 'Resolution', 300);
                case '.pdf'
                    exportgraphics(app.UIAxes, fullFileName);
                case '.fig'
                    % Save as a MATLAB figure
                    fig = figure('Visible', 'off'); % Create an invisible figure
                    axCopy = copyobj(app.UIAxes, fig); % Copy the axes to the new figure
                    set(axCopy, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]); % Resize
                    savefig(fig, fullFileName); % Save the figure
                    close(fig); % Close the temporary figure
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
            end
        end

        % Menu selected function: OutputVoltageMenu
        function OutputVoltageMenuSelected(app, event)
            % Open a save dialog box
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'; '*.fig'}, 'Save Plot As');
            
            % If user cancels, do nothing
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % Build the full file name
            fullFileName = fullfile(path, file);
            
            % Get the file extension
            [~, ~, ext] = fileparts(fullFileName);
            
            % Save the current axes as the selected format
            switch ext
                case '.png'
                    exportgraphics(app.UIAxes2, fullFileName, 'Resolution', 300);
                case '.jpg'
                    exportgraphics(app.UIAxes2, fullFileName, 'Resolution', 300);
                case '.pdf'
                    exportgraphics(app.UIAxes2, fullFileName);
                case '.fig'
                    % Save as a MATLAB figure
                    fig = figure('Visible', 'off'); % Create an invisible figure
                    axCopy = copyobj(app.UIAxes2, fig); % Copy the axes to the new figure
                    set(axCopy, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]); % Resize
                    savefig(fig, fullFileName); % Save the figure
                    close(fig); % Close the temporary figure
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
            end
        end

        % Menu selected function: MOSFETIVCharacteristicMenu_2
        function MOSFETIVCharacteristicMenu_2Selected(app, event)
            % Obtener el objeto gráfico activo
            srcObject = app.UIAxes.Children; % Obtiene todos los objetos del eje
        
            % Iterar sobre los objetos del eje (por si hay varios gráficos)
            for i = 1:numel(srcObject)
                if strcmp(srcObject(i).Type, 'line') % Verificar si es una línea
                    % Extraer datos X e Y de la línea
                    xData = srcObject(i).XData;
                    yData = srcObject(i).YData;
                    
                    % Crear una matriz con dos columnas: X y Y
                    data = [xData(:), yData(:)]; % Asegurarse de que sean columnas
                    
                    % Obtener el nombre descriptivo (DisplayName)
                    graphName = srcObject(i).DisplayName;
                    
                    % Validar el nombre para que sea un nombre de variable válido
                    if isempty(graphName) % Si no hay DisplayName, usar un nombre genérico
                        graphName = sprintf('ExportedData_%d', i);
                    else
                        graphName = matlab.lang.makeValidName(graphName); % Asegurarse de que sea válido
                    end
                    
                    % Exportar al Workspace con el nombre descriptivo
                    assignin('base', graphName, data);
                end
            end
            
            % Notificación al usuario
            uialert(app.UIFigure, 'Data Exported to Workspace', 'Exportation completed');
        end

        % Menu selected function: OutputVoltageMenu_2
        function OutputVoltageMenu_2Selected(app, event)
            % Obtener el objeto gráfico activo
            srcObject = app.UIAxes2.Children; % Obtiene todos los objetos del eje
        
            % Iterar sobre los objetos del eje (por si hay varios gráficos)
            for i = 1:numel(srcObject)
                if strcmp(srcObject(i).Type, 'line') % Verificar si es una línea
                    % Extraer datos X e Y de la línea
                    xData = srcObject(i).XData;
                    yData = srcObject(i).YData;
                    
                    % Crear una matriz con dos columnas: X y Y
                    data = [xData(:), yData(:)]; % Asegurarse de que sean columnas
                    
                    % Obtener el nombre descriptivo (DisplayName)
                    graphName = srcObject(i).DisplayName;
                    
                    % Validar el nombre para que sea un nombre de variable válido
                    if isempty(graphName) % Si no hay DisplayName, usar un nombre genérico
                        graphName = sprintf('ExportedData_%d', i);
                    else
                        graphName = matlab.lang.makeValidName(graphName); % Asegurarse de que sea válido
                    end
                    
                    % Exportar al Workspace con el nombre descriptivo
                    assignin('base', graphName, data);
                end
            end
            
            % Notificación al usuario
            uialert(app.UIFigure, 'Data Exported to Workspace', 'Exportation completed');
        end

        % Menu selected function: MOSFETIVCharacteristicMenu_3
        function MOSFETIVCharacteristicMenu_3Selected(app, event)
            % Abre el diálogo para seleccionar un archivo .mat
            [file, path] = uigetfile('*.mat', 'Select a MAT-file');
            if isequal(file, 0) % Si el usuario cancela
                return;
            end
            fullPath = fullfile(path, file);
            
            % Carga las variables del archivo .mat
            vars = whos('-file', fullPath); % Obtiene información sobre las variables en el archivo
            if isempty(vars)
                uialert(app.UIFigure, 'The selected MAT-file is empty.', 'No Data');
                return;
            end
            
            % Muestra un cuadro de diálogo para seleccionar variables
            varNames = {vars.name};
            [indx, tf] = listdlg('ListString', varNames, ...
                             'SelectionMode', 'multiple', ...
                             'PromptString', 'Select variables to load', ...
                             'Name', 'Load Variables');
            if tf == 0 % Si el usuario cancela
                return;
            end
            
            % Cargar las variables seleccionadas al workspace
            selectedVars = varNames(indx);
            loadedData = load(fullPath, selectedVars{:});
            
            % Guardar las variables cargadas en una propiedad del app (opcional)
            app.ILoadedData = loadedData; % Crear una propiedad `LoadedData` en el app

            % Extraer datos de la estructura cargada
            data = app.ILoadedData; % Supongamos que contiene las variables seleccionadas
            fieldNames = fieldnames(data); % Obtener los nombres de las variables cargadas
            
            matrixData = data.(fieldNames{1}); % Supongamos que es la primera variable
            x = matrixData(:, 1); % Columna 1: Eje X
            y = matrixData(:, 2); % Columna 2: Eje Y

            plot(app.UIAxes, x, y, 'DisplayName', 'ILoaded')
            legend(app.UIAxes, 'show')
        end

        % Menu selected function: OutputVoltageMenu_3
        function OutputVoltageMenu_3Selected(app, event)
            % Abre el diálogo para seleccionar un archivo .mat
            [file, path] = uigetfile('*.mat', 'Select a MAT-file');
            if isequal(file, 0) % Si el usuario cancela
                return;
            end
            fullPath = fullfile(path, file);
            
            % Carga las variables del archivo .mat
            vars = whos('-file', fullPath); % Obtiene información sobre las variables en el archivo
            if isempty(vars)
                uialert(app.UIFigure, 'The selected MAT-file is empty.', 'No Data');
                return;
            end
            
            % Muestra un cuadro de diálogo para seleccionar variables
            varNames = {vars.name};
            [indx, tf] = listdlg('ListString', varNames, ...
                             'SelectionMode', 'multiple', ...
                             'PromptString', 'Select variables to load', ...
                             'Name', 'Load Variables');
            if tf == 0 % Si el usuario cancela
                return;
            end
            
            % Cargar las variables seleccionadas al workspace
            selectedVars = varNames(indx);
            loadedData = load(fullPath, selectedVars{:});
            
            % Guardar las variables cargadas en una propiedad del app (opcional)
            app.VLoadedData = loadedData; % Crear una propiedad `LoadedData` en el app

            % Extraer datos de la estructura cargada
            data = app.VLoadedData; % Supongamos que contiene las variables seleccionadas
            fieldNames = fieldnames(data); % Obtener los nombres de las variables cargadas
            
            matrixData = data.(fieldNames{1}); % Supongamos que es la primera variable
            x = matrixData(:, 1); % Columna 1: Eje X
            y = matrixData(:, 2); % Columna 2: Eje Y

            plot(app.UIAxes2, x, y, 'DisplayName', 'VLoaded')
            legend(app.UIAxes2, 'show')
        end

        % Menu selected function: ExportConfigMenu
        function ExportConfigMenuSelected(app, event)
            fields = {
                app.VDminEditFieldLabel.Text, app.VDminEditField.Value;
                app.VDmaxEditFieldLabel.Text, app.VDmaxEditField.Value;
                app.VDstepsEditFieldLabel.Text, app.VDstepsEditField.Value;
                app.VGminEditFieldLabel.Text, app.VGminEditField.Value;
                app.VGmaxEditFieldLabel.Text, app.VGmaxEditField.Value;
                app.VGstepsEditFieldLabel.Text, app.VGstepsEditField.Value;
                app.REditFieldLabel.Text, app.REditField.Value;
                app.NAveragingEditFieldLabel.Text, app.NAveragingEditField.Value;
                app.IOffEditFieldLabel.Text, app.IOffEditField.Value;
            };
        
            % Exportar cada campo al workspace
            for i = 1:size(fields, 1)
                fieldName = fields{i, 1};
                fieldValue = fields{i, 2};
                assignin('base', matlab.lang.makeValidName(fieldName), fieldValue);
            end
        
            uialert(app.UIFigure, 'Configuration exported to workspace.', 'Exportation Completed')
        end

        % Button pushed function: CurvefittingButton
        function CurvefittingButtonPushed(app, event)
            if ~app.HoldonCheckBox.Value
                uialert(app.UIFigure, ...
                    'Make sure the Hold On checkbox is activated.', ...
                    'Warning');
                return; % Salir del callback si la condición no se cumple
            elseif isempty(app.I_D_mean) || ~isprop(app, 'I_D_mean')
                uialert(app.UIFigure, ...
                    'Represent a curve in order to be able to fit a curve.', ...
                    'Error');
                return;
            elseif app.SweepCheckBox.Value
                uialert(app.UIFigure, ...
                    'Curve fitting is not available for sweep curves.', ...
                    'Error');
                return;
            end

            app.CurveFitDialog = CurveFittingDialogMOSFET();
            app.CurveFitDialog.MainMOSFETApp = app;  % Asigna la referencia a la MainApp
            app.CurveFitDialog.UIFigure.Visible = 'on';
        end

        % Button pushed function: FullScaleRangeButton
        function FullScaleRangeButtonPushed(app, event)
            vomax = max(app.V_O_mean);
            vomin = min(app.V_O_mean);
            fsr = vomax - vomin;
            fsrpercent = fsr/20*100;

            % Crea el mensaje para mostrar
            mensaje = sprintf('The FSR is: %.2f V, or %.2f%%', fsr, fsrpercent);

            uialert(app.UIFigure, mensaje, 'Full Scale Range (FSR)')
        end

        % Value changed function: FastModeCheckBox
        function FastModeCheckBoxValueChanged(app, event)
            if app.FastModeCheckBox.Value
                uialert(app.UIFigure, 'Fast Mode is not available.', 'Error')
                return
            end
        end

        % Button pushed function: ExtraParametersButton
        function ExtraParametersButtonPushed(app, event)
            if ~app.HoldonCheckBox.Value
                uialert(app.UIFigure, ...
                    'Make sure the Hold On checkbox is activated.', ...
                    'Warning');
                return; % Salir del callback si la condición no se cumple
            elseif isempty(app.I_D_mean) || ~isprop(app, 'I_D_mean')
                uialert(app.UIFigure, ...
                    'Represent a curve in order to be able to obtain parameters.', ...
                    'Error');
                return;
            end

            if strcmp(app.XAxisDropDown.Value, 'V_GS')
                app.StrInvDialog = StrInvMOSFET();
                app.StrInvDialog.MainMOSFETApp = app;  % Asigna la referencia a la MainApp
                app.StrInvDialog.UIFigure.Visible = 'on';
            elseif strcmp(app.XAxisDropDown.Value, 'V_DS')
                uialert(app.UIFigure,...
                'This functionality is in development. The values of the parameters GAMMA, PHI, KAPPA and THETA2 might not be accurate.',...
                'Warning')
                app.StrInv2Dialog = StrInv2MOSFET();
                app.StrInv2Dialog.MainMOSFETApp = app;
                app.StrInv2Dialog.UIFigure.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveasMenu_3 = uimenu(app.FileMenu);
            app.SaveasMenu_3.Text = 'Save as';
            app.MOSFETIVCharacteristicMenu = uimenu(app.SaveasMenu_3);
            app.MOSFETIVCharacteristicMenu.MenuSelectedFcn = createCallbackFcn(app, @MOSFETIVCharacteristicMenuSelected, true);
            app.MOSFETIVCharacteristicMenu.Text = 'MOSFET I-V Characteristic';
            app.OutputVoltageMenu = uimenu(app.SaveasMenu_3);
            app.OutputVoltageMenu.MenuSelectedFcn = createCallbackFcn(app, @OutputVoltageMenuSelected, true);
            app.OutputVoltageMenu.Text = 'Output Voltage';
            app.ExporttoWorkspaceMenu = uimenu(app.FileMenu);
            app.ExporttoWorkspaceMenu.Text = 'Export to Workspace';
            app.MOSFETIVCharacteristicMenu_2 = uimenu(app.ExporttoWorkspaceMenu);
            app.MOSFETIVCharacteristicMenu_2.MenuSelectedFcn = createCallbackFcn(app, @MOSFETIVCharacteristicMenu_2Selected, true);
            app.MOSFETIVCharacteristicMenu_2.Text = 'MOSFET I-V Characteristic';
            app.OutputVoltageMenu_2 = uimenu(app.ExporttoWorkspaceMenu);
            app.OutputVoltageMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OutputVoltageMenu_2Selected, true);
            app.OutputVoltageMenu_2.Text = 'Output Voltage';
            app.LoadDataMenu_3 = uimenu(app.FileMenu);
            app.LoadDataMenu_3.Text = 'Load Data';
            app.MOSFETIVCharacteristicMenu_3 = uimenu(app.LoadDataMenu_3);
            app.MOSFETIVCharacteristicMenu_3.MenuSelectedFcn = createCallbackFcn(app, @MOSFETIVCharacteristicMenu_3Selected, true);
            app.MOSFETIVCharacteristicMenu_3.Text = 'MOSFET I-V Characteristic';
            app.OutputVoltageMenu_3 = uimenu(app.LoadDataMenu_3);
            app.OutputVoltageMenu_3.MenuSelectedFcn = createCallbackFcn(app, @OutputVoltageMenu_3Selected, true);
            app.OutputVoltageMenu_3.Text = 'Output Voltage';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.MenuSelectedFcn = createCallbackFcn(app, @HelpMenu_2Selected, true);
            app.HelpMenu_2.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'MOSFET I-V Characteristic')
            ylabel(app.UIAxes, 'I_D [A]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Output Voltage')
            ylabel(app.UIAxes2, 'V_O [V]')
            zlabel(app.UIAxes2, 'Z')
            app.NImyDAQPortsPanel = uipanel(app.UIFigure);
            app.NImyDAQPortsPanel.Title = 'NI myDAQ Ports';
            app.VDDropDownLabel = uilabel(app.NImyDAQPortsPanel);
            app.VDDropDownLabel.Text = 'VD';
            app.VDDropDown = uidropdown(app.NImyDAQPortsPanel);
            app.VDDropDown.Items = {'''ao0''', '''ao1'''};
            app.VDDropDown.Value = '''ao0''';
            app.VGDropDownLabel = uilabel(app.NImyDAQPortsPanel);
            app.VGDropDownLabel.Text = 'VG';
            app.VGDropDown = uidropdown(app.NImyDAQPortsPanel);
            app.VGDropDown.Items = {'''ao0''', '''ao1'''};
            app.VGDropDown.Value = '''ao1''';
            app.VODropDownLabel = uilabel(app.NImyDAQPortsPanel);
            app.VODropDownLabel.Text = 'VO';
            app.VODropDown = uidropdown(app.NImyDAQPortsPanel);
            app.VODropDown.Items = {'''ai0''', '''ai1'''};
            app.VODropDown.Value = '''ai1''';
            app.MeasurementStatusPanel = uipanel(app.UIFigure);
            app.MeasurementStatusPanel.Title = 'Measurement Status';
            app.RunMeasurementsButton = uibutton(app.MeasurementStatusPanel, 'push');
            app.RunMeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @RunMeasurementsButtonPushed, true);
            app.RunMeasurementsButton.Text = 'Run Measurements';
            app.StopSimulationButton = uibutton(app.MeasurementStatusPanel, 'push');
            app.StopSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimulationButtonPushed, true);
            app.StopSimulationButton.Text = 'Stop Simulation';
            app.MeasurementStatusGaugeLabel = uilabel(app.MeasurementStatusPanel);
            app.MeasurementStatusGaugeLabel.Text = 'Measurement Status (%)';
            app.MeasurementStatusGauge = uigauge(app.MeasurementStatusPanel, 'linear');
            app.InputSignalPropertiesPanel = uipanel(app.UIFigure);
            app.InputSignalPropertiesPanel.Title = 'Input Signal Properties';
            app.VLabel = uilabel(app.InputSignalPropertiesPanel);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.InputSignalPropertiesPanel);
            app.VLabel_2.Text = 'V';
            app.VLabel_3 = uilabel(app.InputSignalPropertiesPanel);
            app.VLabel_3.Text = 'V';
            app.VLabel_4 = uilabel(app.InputSignalPropertiesPanel);
            app.VLabel_4.Text = 'V';
            app.SweepCheckBox = uicheckbox(app.InputSignalPropertiesPanel);
            app.SweepCheckBox.Text = 'Sweep';
            app.VDminEditFieldLabel = uilabel(app.UIFigure);
            app.VDminEditFieldLabel.Text = 'VDmin';
            app.VDminEditField = uieditfield(app.UIFigure, 'numeric');
            app.VDmaxEditFieldLabel = uilabel(app.UIFigure);
            app.VDmaxEditFieldLabel.Text = 'VDmax';
            app.VDmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.VDstepsEditFieldLabel = uilabel(app.UIFigure);
            app.VDstepsEditFieldLabel.Text = 'VDsteps';
            app.VDstepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VGminEditFieldLabel = uilabel(app.UIFigure);
            app.VGminEditFieldLabel.Text = 'VGmin';
            app.VGminEditField = uieditfield(app.UIFigure, 'numeric');
            app.VGmaxEditFieldLabel = uilabel(app.UIFigure);
            app.VGmaxEditFieldLabel.Text = 'VGmax';
            app.VGmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.VGstepsEditFieldLabel = uilabel(app.UIFigure);
            app.VGstepsEditFieldLabel.Text = 'VGsteps';
            app.VGstepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NImyDAQPanel = uipanel(app.UIFigure);
            app.NImyDAQPanel.Title = 'NI myDAQ';
            app.DeviceIDEditFieldLabel = uilabel(app.NImyDAQPanel);
            app.DeviceIDEditFieldLabel.Text = 'Device ID';
            app.DeviceIDEditField = uieditfield(app.NImyDAQPanel, 'text');
            app.NImyDAQSwitch = uiswitch(app.UIFigure, 'slider');
            app.NImyDAQSwitch.ValueChangedFcn = createCallbackFcn(app, @NImyDAQSwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'US-marca-principal.png');
            app.MeasurementOptionsPanel = uipanel(app.UIFigure);
            app.MeasurementOptionsPanel.Title = 'Measurement Options';
            app.FastModeCheckBox = uicheckbox(app.MeasurementOptionsPanel);
            app.FastModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @FastModeCheckBoxValueChanged, true);
            app.FastModeCheckBox.Text = 'Fast Mode';
            app.ShowSatLimitsButton = uibutton(app.MeasurementOptionsPanel, 'push');
            app.ShowSatLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSatLimitsButtonPushed, true);
            app.ShowSatLimitsButton.Text = 'Show Sat Limits';
            app.CurvefittingButton = uibutton(app.MeasurementOptionsPanel, 'push');
            app.CurvefittingButton.ButtonPushedFcn = createCallbackFcn(app, @CurvefittingButtonPushed, true);
            app.CurvefittingButton.Text = 'Curve fitting';
            app.FullScaleRangeButton = uibutton(app.MeasurementOptionsPanel, 'push');
            app.FullScaleRangeButton.ButtonPushedFcn = createCallbackFcn(app, @FullScaleRangeButtonPushed, true);
            app.FullScaleRangeButton.Text = 'Full Scale Range';
            app.ExtraParametersButton = uibutton(app.MeasurementOptionsPanel, 'push');
            app.ExtraParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ExtraParametersButtonPushed, true);
            app.ExtraParametersButton.Text = 'Extra Parameters';
            app.MeasurementPropertiesPanel = uipanel(app.UIFigure);
            app.MeasurementPropertiesPanel.Title = 'Measurement Properties';
            app.OhmLabel = uilabel(app.MeasurementPropertiesPanel);
            app.OhmLabel.Text = 'Ohm';
            app.ALabel = uilabel(app.MeasurementPropertiesPanel);
            app.ALabel.Text = 'A';
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.NAveragingEditFieldLabel = uilabel(app.UIFigure);
            app.NAveragingEditFieldLabel.Text = 'NAveraging';
            app.NAveragingEditField = uieditfield(app.UIFigure, 'numeric');
            app.IOffEditFieldLabel = uilabel(app.UIFigure);
            app.IOffEditFieldLabel.Text = 'IOff';
            app.IOffEditField = uieditfield(app.UIFigure, 'numeric');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.LinButton = uiradiobutton(app.ButtonGroup);
            app.LinButton.Text = 'Lin';
            app.LinButton.Value = true;
            app.LogButton = uiradiobutton(app.ButtonGroup);
            app.LogButton.Text = 'Log';
            app.HoldonCheckBox = uicheckbox(app.UIFigure);
            app.HoldonCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldonCheckBoxValueChanged, true);
            app.HoldonCheckBox.Text = 'Hold on';
            app.ResetFiguresButton = uibutton(app.UIFigure, 'push');
            app.ResetFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFiguresButtonPushed, true);
            app.ResetFiguresButton.Text = 'Reset Figures';
            app.XAxisDropDownLabel = uilabel(app.UIFigure);
            app.XAxisDropDownLabel.Text = 'X Axis';
            app.XAxisDropDown = uidropdown(app.UIFigure);
            app.XAxisDropDown.Items = {'V_DS', 'V_GS'};
            app.XAxisDropDown.Value = 'V_DS';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExportConfigMenu = uimenu(app.ContextMenu);
            app.ExportConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportConfigMenuSelected, true);
            app.ExportConfigMenu.Text = 'Export Config';
            app.HelpMenu_3 = uimenu(app.ContextMenu);
            app.HelpMenu_3.MenuSelectedFcn = createCallbackFcn(app, @HelpMenu_3Selected, true);
            app.HelpMenu_3.Text = 'Help';
            app.DocumentationMenu_2 = uimenu(app.ContextMenu);
            app.DocumentationMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenu_2Selected, true);
            app.DocumentationMenu_2.Text = 'Documentation';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.RenameMenu = uimenu(app.ContextMenu2);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenu.Text = 'Rename';
            app.SaveasMenu = uimenu(app.ContextMenu2);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as';
            app.ExportDatatoWorkspaceMenu = uimenu(app.ContextMenu2);
            app.ExportDatatoWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDatatoWorkspaceMenuSelected, true);
            app.ExportDatatoWorkspaceMenu.Text = 'Export Data to Workspace';
            app.LoadDataMenu = uimenu(app.ContextMenu2);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Text = 'Load Data';
            app.HideCurveMenu = uimenu(app.ContextMenu2);
            app.HideCurveMenu.MenuSelectedFcn = createCallbackFcn(app, @HideCurveMenuSelected, true);
            app.HideCurveMenu.Text = 'Hide Curve';
            app.ShowCurveMenu = uimenu(app.ContextMenu2);
            app.ShowCurveMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowCurveMenuSelected, true);
            app.ShowCurveMenu.Text = 'Show Curve';
            % Assign app.ContextMenu2
            app.UIAxes.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.RenameMenu_2 = uimenu(app.ContextMenu3);
            app.RenameMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RenameMenu_2Selected, true);
            app.RenameMenu_2.Text = 'Rename';
            app.SaveasMenu_2 = uimenu(app.ContextMenu3);
            app.SaveasMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenu_2Selected, true);
            app.SaveasMenu_2.Text = 'Save as';
            app.ExportDatatoWorkspaceMenu_2 = uimenu(app.ContextMenu3);
            app.ExportDatatoWorkspaceMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ExportDatatoWorkspaceMenu_2Selected, true);
            app.ExportDatatoWorkspaceMenu_2.Text = 'Export Data to Workspace';
            app.LoadDataMenu_2 = uimenu(app.ContextMenu3);
            app.LoadDataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenu_2Selected, true);
            app.LoadDataMenu_2.Text = 'Load Data';
            app.HideCurveMenu_2 = uimenu(app.ContextMenu3);
            app.HideCurveMenu_2.MenuSelectedFcn = createCallbackFcn(app, @HideCurveMenu_2Selected, true);
            app.HideCurveMenu_2.Text = 'Hide Curve';
            app.ShowCurveMenu_2 = uimenu(app.ContextMenu3);
            app.ShowCurveMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ShowCurveMenu_2Selected, true);
            app.ShowCurveMenu_2.Text = 'Show Curve';
            % Assign app.ContextMenu3
            app.UIAxes2.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.ExportFitParametersMenu = uimenu(app.ContextMenu4);
            app.ExportFitParametersMenu.Text = 'Export Fit Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MOSFETCharacteristics

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end