classdef StrInv2MOSFET < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VLabel_6                        matlab.ui.control.Label
        V12Label                        matlab.ui.control.Label
        VLabel_5                        matlab.ui.control.Label
        VLabel_4                        matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        VLabel_3                        matlab.ui.control.Label
        UOEditField                     matlab.ui.control.NumericEditField
        UOEditFieldLabel                matlab.ui.control.Label
        THETAEditField                  matlab.ui.control.NumericEditField
        THETAEditFieldLabel             matlab.ui.control.Label
        VTOEditField                    matlab.ui.control.NumericEditField
        VTOEditFieldLabel               matlab.ui.control.Label
        cm2VsLabel                      matlab.ui.control.Label
        VLabel_2                        matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        THETA2EditField                 matlab.ui.control.NumericEditField
        THETA2EditFieldLabel            matlab.ui.control.Label
        VSBEditField                    matlab.ui.control.NumericEditField
        VSBEditFieldLabel               matlab.ui.control.Label
        KAPPAEditField                  matlab.ui.control.NumericEditField
        KAPPAEditFieldLabel             matlab.ui.control.Label
        GAMMAEditField                  matlab.ui.control.NumericEditField
        GAMMAEditFieldLabel             matlab.ui.control.Label
        PHIEditField                    matlab.ui.control.NumericEditField
        PHIEditFieldLabel               matlab.ui.control.Label
        IstheresubstrateeffectLabel     matlab.ui.control.Label
        STRONGINVERSIONPARAMETERSLabel  matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        ExportStrInvParametersMenu      matlab.ui.container.Menu
    end

    
    properties (Access = public)
        MainMOSFETApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            valid_V = app.MainMOSFETApp.V_O_mean < 10;
            xData = app.MainMOSFETApp.V_D(valid_V);
            yData = app.MainMOSFETApp.I_D_mean(valid_V);
            
            par=[0.8 0.7 1 0];
            parmax=[10 10 10 2];
            parmin=[0 0 -10.0 0];

            opts=optimset; % Cargamos las opciones de optimización por defecto
            opts.TolFun=1e-18; % Modificamos TolFun y TolX para mejorar el ajuste
            opts.TolX=1e-18;

            d = [xData, yData];
            VSB = app.VSBEditField.Value;
            UO = app.UOEditField.Value;
            VTO = app.VTOEditField.Value;
            THETA = app.THETA2EditField.Value;

            [p2, ~, residuals, exitflag, output, lambda, J] = lsqnonlin(@(p) app.MainMOSFETApp.fopt2(p, UO, THETA, VTO, d, VSB), par, parmin, parmax, opts);

            app.GAMMAEditField.Value = p2(1);
            app.PHIEditField.Value   = p2(2);
            app.KAPPAEditField.Value = p2(3);
            app.THETA2EditField.Value = p2(4);
        end

        % Menu selected function: ExportStrInvParametersMenu
        function ExportStrInvParametersMenuSelected(app, event)
            fields = {
                app.GAMMAEditFieldLabel.Text, app.GAMMAEditField.Value;
                app.KAPPAEditFieldLabel.Text, app.KAPPAEditField.Value;
                app.PHIEditFieldLabel.Text, app.PHIEditField.Value;
                app.THETA2EditFieldLabel.Text, app.THETA2EditField.Value;
                app.VSBEditFieldLabel.Text, app.VSBEditField.Value;
            };
        
            % Exportar cada campo al workspace
            for i = 1:size(fields, 1)
                fieldName = fields{i, 1};
                fieldValue = fields{i, 2};
                assignin('base', matlab.lang.makeValidName(fieldName), fieldValue);
            end
        
            uialert(app.UIFigure, 'Parameters exported to workspace.', 'Exportation Completed')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.STRONGINVERSIONPARAMETERSLabel = uilabel(app.UIFigure);
            app.STRONGINVERSIONPARAMETERSLabel.Text = 'STRONG INVERSION PARAMETERS';
            app.IstheresubstrateeffectLabel = uilabel(app.UIFigure);
            app.IstheresubstrateeffectLabel.Text = 'Is there substrate effect?';
            app.PHIEditFieldLabel = uilabel(app.UIFigure);
            app.PHIEditFieldLabel.Text = 'PHI';
            app.PHIEditField = uieditfield(app.UIFigure, 'numeric');
            app.GAMMAEditFieldLabel = uilabel(app.UIFigure);
            app.GAMMAEditFieldLabel.Text = 'GAMMA';
            app.GAMMAEditField = uieditfield(app.UIFigure, 'numeric');
            app.KAPPAEditFieldLabel = uilabel(app.UIFigure);
            app.KAPPAEditFieldLabel.Text = 'KAPPA';
            app.KAPPAEditField = uieditfield(app.UIFigure, 'numeric');
            app.VSBEditFieldLabel = uilabel(app.UIFigure);
            app.VSBEditFieldLabel.Text = 'VSB';
            app.VSBEditField = uieditfield(app.UIFigure, 'numeric');
            app.THETA2EditFieldLabel = uilabel(app.UIFigure);
            app.THETA2EditFieldLabel.Text = 'THETA2';
            app.THETA2EditField = uieditfield(app.UIFigure, 'numeric');
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.UIFigure);
            app.VLabel_2.Text = '1/V';
            app.cm2VsLabel = uilabel(app.UIFigure);
            app.cm2VsLabel.Text = 'cm^2/(V·s)';
            app.VTOEditFieldLabel = uilabel(app.UIFigure);
            app.VTOEditFieldLabel.Text = 'VTO';
            app.VTOEditField = uieditfield(app.UIFigure, 'numeric');
            app.THETAEditFieldLabel = uilabel(app.UIFigure);
            app.THETAEditFieldLabel.Text = 'THETA';
            app.THETAEditField = uieditfield(app.UIFigure, 'numeric');
            app.UOEditFieldLabel = uilabel(app.UIFigure);
            app.UOEditFieldLabel.Text = 'UO';
            app.UOEditField = uieditfield(app.UIFigure, 'numeric');
            app.VLabel_3 = uilabel(app.UIFigure);
            app.VLabel_3.Text = 'V';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.VLabel_4 = uilabel(app.UIFigure);
            app.VLabel_4.Text = 'V';
            app.VLabel_5 = uilabel(app.UIFigure);
            app.VLabel_5.Text = '(1/V)';
            app.V12Label = uilabel(app.UIFigure);
            app.V12Label.Text = 'V^(1/2)';
            app.VLabel_6 = uilabel(app.UIFigure);
            app.VLabel_6.Text = '(1/V)';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExportStrInvParametersMenu = uimenu(app.ContextMenu);
            app.ExportStrInvParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportStrInvParametersMenuSelected, true);
            app.ExportStrInvParametersMenu.Text = 'Export Str Inv Parameters';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StrInv2MOSFET

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end