classdef StrInvMOSFET < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image6                       matlab.ui.control.Image
        cm2VsLabel_2                 matlab.ui.control.Label
        cm2VsLabel                   matlab.ui.control.Label
        VLabel_4                     matlab.ui.control.Label
        VLabel_3                     matlab.ui.control.Label
        VLabel_2                     matlab.ui.control.Label
        VLabel                       matlab.ui.control.Label
        OHMICANDSATURATIONPARAMETERSLabel  matlab.ui.control.Label
        ErrorVTOEditField            matlab.ui.control.EditField
        ErrorVTOEditFieldLabel       matlab.ui.control.Label
        ErrorTHETAEditField          matlab.ui.control.EditField
        ErrorTHETAEditFieldLabel     matlab.ui.control.Label
        ErrorUOEditField             matlab.ui.control.EditField
        ErrorUOEditFieldLabel        matlab.ui.control.Label
        R2EditField                  matlab.ui.control.NumericEditField
        R2EditFieldLabel             matlab.ui.control.Label
        UOEditField                  matlab.ui.control.NumericEditField
        UOEditFieldLabel             matlab.ui.control.Label
        THETAEditField               matlab.ui.control.NumericEditField
        THETAEditFieldLabel          matlab.ui.control.Label
        VTOEditField                 matlab.ui.control.NumericEditField
        VTOEditFieldLabel            matlab.ui.control.Label
        CalculateButton              matlab.ui.control.Button
        ContextMenu                  matlab.ui.container.ContextMenu
        ExportDegradationParametersMenu  matlab.ui.container.Menu
        ContextMenu2                 matlab.ui.container.ContextMenu
        SaveasMenu                   matlab.ui.container.Menu
        ExportDatatoWorkspaceMenu    matlab.ui.container.Menu
        ContextMenu3                 matlab.ui.container.ContextMenu
        SaveasMenu_2                 matlab.ui.container.Menu
        ExportDatatoWorkspaceMenu_2  matlab.ui.container.Menu
    end


    properties (Access = public)
        MainMOSFETApp % Description
        Vd;
        Vg;
        V_ajuste;
        Id_ajuste;
        Id_medido;
        y_fit;
        yData;
        V_O_mean
    end

    properties (Access = private)
        valid_V
        V_meas
        xData
        x_fit
        fitresult
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.valid_V = app.MainMOSFETApp.V_O_mean < 10;
            app.xData = app.MainMOSFETApp.V_G(app.valid_V);
            app.Vd = app.MainMOSFETApp.V_D(app.valid_V);
            app.Vd = mean(app.Vd(:));
            app.yData = app.MainMOSFETApp.I_D_mean(app.valid_V);
            app.V_meas = app.MainMOSFETApp.V_O_mean(app.valid_V);
            [app.xData, app.yData] = prepareCurveData(app.xData, app.yData);

            % Set up fittype and options.
            ft = fittype(sprintf('UO./(1+THETA.*(Vg-VTO)).*3.4530e-6.*(Vg-VTO).*(%g)', app.Vd), ...
                        'independent', 'Vg', 'dependent', 'y');

            excludedPoints = app.xData < 1.2;
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Display = 'Off';
            opts.Lower = [0 0 0];
            opts.StartPoint = [0 600 1.5];
            opts.TolFun = 1e-12;
            opts.TolX = 1e-12;
            opts.Upper = [1 Inf 5];
            opts.Exclude = excludedPoints;

            % Fit model to data.
            [app.fitresult, gof] = fit( app.xData, app.yData, ft, opts );

            % Mostrar/guardar
            app.UOEditField.Value    = app.fitresult.UO;
            app.THETAEditField.Value = app.fitresult.THETA;
            app.VTOEditField.Value   = app.fitresult.VTO;

            % Calcular los intervalos de confianza al 95%
            CI = confint(app.fitresult, 0.95);
            % Los errores se pueden aproximar como la mitad del ancho del intervalo:
            error_UO    = (CI(2,2) - CI(1,2))/2;
            error_THETA = (CI(2,1) - CI(1,1))/2;
            error_VTO   = (CI(2,3) - CI(1,3))/2;

            % Extraer R^2 (valor de la bondad del ajuste)
            R2 = gof.rsquare;

            % Mostrar también los errores
            app.ErrorUOEditField.Value    = num2str(error_UO, '%11.4g');
            app.ErrorTHETAEditField.Value = num2str(error_THETA, '%11.4g');
            app.ErrorVTOEditField.Value   = num2str(error_VTO, '%11.4g');
            app.R2EditField.Value = R2;

            % Obtener el mínimo y máximo de xData para ajustar la curva dentro de los límites
            min_x = min(app.xData);  % Mínimo valor de xData
            max_x = max(app.xData);  % Máximo valor de xData
            
            % Generar un rango de valores para el eje X dentro de los límites de los datos
            app.x_fit = linspace(min_x, max_x, 100);  % 100 puntos entre el mínimo y máximo de xData
            
            % Calcular el ajuste solo dentro de ese rango
            app.y_fit = feval(app.fitresult, app.x_fit);  % Evalúa la curva de ajuste en el rango x_fit
            
            % Aplicar la condición para que los valores de y_fit no sean negativos
            app.y_fit(app.y_fit < 0) = 0;  % Asigna 0 a los valores de y_fit que sean menores que 0

            % Para la curva V-V
            app.V_ajuste = app.MainMOSFETApp.REditField.Value .* app.y_fit + app.Vd;
            
            % Ahora, para graficar, usamos solo los valores ajustados
            plot(app.MainMOSFETApp.UIAxes, app.x_fit, app.y_fit, 'DisplayName', 'Degradation fit');
            plot(app.MainMOSFETApp.UIAxes2, app.x_fit, app.V_ajuste, 'DisplayName', 'Degradation fit')
        end

        % Menu selected function: ExportDegradationParametersMenu
        function ExportDegradationParametersMenuSelected(app, event)
            fields = {
                app.UOEditFieldLabel.Text, app.UOEditField.Value;
                app.THETAEditFieldLabel.Text, app.THETAEditField.Value;
                app.VTOEditFieldLabel.Text, app.VTOEditField.Value;
                app.R2EditFieldLabel.Text, app.R2EditField.Value;
                'ErrorUO', app.ErrorUOEditField.Value;
                'ErrorTHETA', app.ErrorTHETAEditField.Value;
                'ErrorVTO', app.ErrorVTOEditField.Value;
            };
        
            % Exportar cada campo al workspace
            for i = 1:size(fields, 1)
                fieldName = fields{i, 1};
                fieldValue = fields{i, 2};
                assignin('base', matlab.lang.makeValidName(fieldName), fieldValue);
            end
        
            uialert(app.UIFigure, 'Parameters exported to workspace.', 'Exportation Completed')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.VTOEditFieldLabel = uilabel(app.UIFigure);
            app.VTOEditFieldLabel.Text = 'VTO';
            app.VTOEditField = uieditfield(app.UIFigure, 'numeric');
            app.THETAEditFieldLabel = uilabel(app.UIFigure);
            app.THETAEditFieldLabel.Text = 'THETA';
            app.THETAEditField = uieditfield(app.UIFigure, 'numeric');
            app.UOEditFieldLabel = uilabel(app.UIFigure);
            app.UOEditFieldLabel.Text = 'UO';
            app.UOEditField = uieditfield(app.UIFigure, 'numeric');
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R^2';
            app.R2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrorUOEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorUOEditFieldLabel.Text = 'Error UO';
            app.ErrorUOEditField = uieditfield(app.UIFigure, 'text');
            app.ErrorTHETAEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorTHETAEditFieldLabel.Text = 'Error THETA';
            app.ErrorTHETAEditField = uieditfield(app.UIFigure, 'text');
            app.ErrorVTOEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorVTOEditFieldLabel.Text = 'Error VTO';
            app.ErrorVTOEditField = uieditfield(app.UIFigure, 'text');
            app.OHMICANDSATURATIONPARAMETERSLabel = uilabel(app.UIFigure);
            app.OHMICANDSATURATIONPARAMETERSLabel.Text = 'OHMIC AND SATURATION PARAMETERS';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.UIFigure);
            app.VLabel_2.Text = 'V';
            app.VLabel_3 = uilabel(app.UIFigure);
            app.VLabel_3.Text = '1/V';
            app.VLabel_4 = uilabel(app.UIFigure);
            app.VLabel_4.Text = '1/V';
            app.cm2VsLabel = uilabel(app.UIFigure);
            app.cm2VsLabel.Text = 'cm^2/(V·s)';
            app.cm2VsLabel_2 = uilabel(app.UIFigure);
            app.cm2VsLabel_2.Text = 'cm^2/(V·s)';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'MOSFETCharacteristicsUOTHETAVTO.svg');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExportDegradationParametersMenu = uimenu(app.ContextMenu);
            app.ExportDegradationParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDegradationParametersMenuSelected, true);
            app.ExportDegradationParametersMenu.Text = 'Export Degradation Parameters';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.SaveasMenu = uimenu(app.ContextMenu2);
            app.SaveasMenu.Text = 'Save as';
            app.ExportDatatoWorkspaceMenu = uimenu(app.ContextMenu2);
            app.ExportDatatoWorkspaceMenu.Text = 'Export Data to Workspace';
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.SaveasMenu_2 = uimenu(app.ContextMenu3);
            app.SaveasMenu_2.Text = 'Save as';
            app.ExportDatatoWorkspaceMenu_2 = uimenu(app.ContextMenu3);
            app.ExportDatatoWorkspaceMenu_2.Text = 'Export Data to Workspace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StrInvMOSFET

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end